classdef SplitViSu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SplitViSuUIFigure               matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CropTab                         matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        Panel_4                         matlab.ui.container.Panel
        GridLayout2_3                   matlab.ui.container.GridLayout
        PermutateXYCheckBox             matlab.ui.control.CheckBox
        YlengthEditField                matlab.ui.control.NumericEditField
        YlengthEditFieldLabel           matlab.ui.control.Label
        InvertYBCheckBox                matlab.ui.control.CheckBox
        XlengthEditField                matlab.ui.control.NumericEditField
        XlengthEditFieldLabel           matlab.ui.control.Label
        InvertXBCheckBox                matlab.ui.control.CheckBox
        SaveROIsButton_2                matlab.ui.control.Button
        LoadROIsButton_2                matlab.ui.control.Button
        InputdataButtonGroup            matlab.ui.container.ButtonGroup
        text116                         matlab.ui.control.Label
        popupmenu21                     matlab.ui.control.DropDown
        edit4_2                         matlab.ui.control.EditField
        pushbutton13_2                  matlab.ui.control.Button
        edit4                           matlab.ui.control.EditField
        pushbutton13                    matlab.ui.control.Button
        InonefileButton                 matlab.ui.control.RadioButton
        InseparatefilesButton           matlab.ui.control.RadioButton
        ImagePanel                      matlab.ui.container.Panel
        MaxCount                        matlab.ui.control.NumericEditField
        MaxcountSlider                  matlab.ui.control.Slider
        MaxcountSliderLabel             matlab.ui.control.Label
        ColormapDropDown                matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        PixelsizenmEditFieldLabel       matlab.ui.control.Label
        PixelsizenmEditField            matlab.ui.control.NumericEditField
        DisplaydatafrominputlistButtonGroup  matlab.ui.container.ButtonGroup
        IILButton                       matlab.ui.control.RadioButton
        ISButton                        matlab.ui.control.RadioButton
        InputpointsEditField            matlab.ui.control.NumericEditField
        InputpointsEditFieldLabel       matlab.ui.control.Label
        UITable_4                       matlab.ui.control.Table
        UIAxes2_3                       matlab.ui.control.UIAxes
        AlignmentTab                    matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        ZshiftEditFieldLabel            matlab.ui.control.Label
        ZshiftEditField                 matlab.ui.control.NumericEditField
        nmLabel_3                       matlab.ui.control.Label
        YshiftEditFieldLabel_2          matlab.ui.control.Label
        AutoButton                      matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        HistogramPanel                  matlab.ui.container.Panel
        MaxshiftnmEditFieldLabel        matlab.ui.control.Label
        MaxshiftnmEditField             matlab.ui.control.NumericEditField
        BinwidthnmEditField             matlab.ui.control.NumericEditField
        BinwidthnmEditFieldLabel        matlab.ui.control.Label
        framesLabel                     matlab.ui.control.Label
        XshiftEditField_2               matlab.ui.control.NumericEditField
        nmLabel_2                       matlab.ui.control.Label
        nmLabel                         matlab.ui.control.Label
        YshiftEditFieldLabel            matlab.ui.control.Label
        YshiftEditField                 matlab.ui.control.NumericEditField
        XshiftEditFieldLabel            matlab.ui.control.Label
        XshiftEditField                 matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        CalculateButton                 matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        FinealignmentTab                matlab.ui.container.Tab
        GridLayout5_3                   matlab.ui.container.GridLayout
        Panel_6                         matlab.ui.container.Panel
        GridLayout_3                    matlab.ui.container.GridLayout
        ZfitDropDownLabel_4             matlab.ui.control.Label
        XshiftEditField_7               matlab.ui.control.NumericEditField
        ZfitDropDownLabel_3             matlab.ui.control.Label
        ZfitDropDownLabel_2             matlab.ui.control.Label
        XshiftEditField_6               matlab.ui.control.NumericEditField
        PolynomialdegreeDropDown_2      matlab.ui.control.DropDown
        PolynomialdegreeDropDown_2Label  matlab.ui.control.Label
        ZfitDropDown                    matlab.ui.control.DropDown
        ZfitDropDownLabel               matlab.ui.control.Label
        SavetformButton                 matlab.ui.control.Button
        PolynomialdegreeDropDown        matlab.ui.control.DropDown
        PolynomialdegreeDropDownLabel   matlab.ui.control.Label
        XYfitDropDown                   matlab.ui.control.DropDown
        XYfitDropDownLabel              matlab.ui.control.Label
        QuiverplotPanel                 matlab.ui.container.Panel
        ArrowscalexLabel                matlab.ui.control.Label
        ArrowscaleEditField             matlab.ui.control.NumericEditField
        StepmEditField                  matlab.ui.control.NumericEditField
        StepmEditFieldLabel             matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        XshiftEditField_5               matlab.ui.control.NumericEditField
        maxshiftnmLabel                 matlab.ui.control.Label
        CalculateButton_2               matlab.ui.control.Button
        XshiftEditField_4               matlab.ui.control.NumericEditField
        XshiftEditField_3               matlab.ui.control.NumericEditField
        framerangeLabel                 matlab.ui.control.Label
        UITable_5                       matlab.ui.control.Table
        UIAxes_5                        matlab.ui.control.UIAxes
        PairingTab                      matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        Panel_2                         matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        AnisotropyCheckBox              matlab.ui.control.CheckBox
        MaxZpairingdistnmEditField_2    matlab.ui.control.NumericEditField
        MergeButton                     matlab.ui.control.Button
        TgroupingXYZnmEditField         matlab.ui.control.NumericEditField
        TgroupingrXYZnmLabel            matlab.ui.control.Label
        MaxZpairingdistnmEditField      matlab.ui.control.NumericEditField
        RatioIBIAEditFieldLabel         matlab.ui.control.Label
        RatioIBIAEditField              matlab.ui.control.NumericEditField
        HistogramDropDown               matlab.ui.control.DropDown
        HistogramDropDownLabel          matlab.ui.control.Label
        UnivariatehistogramPanel        matlab.ui.container.Panel
        rDropDown                       matlab.ui.control.DropDown
        rDropDownLabel                  matlab.ui.control.Label
        countscaleDropDown              matlab.ui.control.DropDown
        countscaleDropDownLabel         matlab.ui.control.Label
        rscaleDropDown                  matlab.ui.control.DropDown
        rscaleDropDownLabel             matlab.ui.control.Label
        rlimEditFieldLabel              matlab.ui.control.Label
        rlimEditField                   matlab.ui.control.NumericEditField
        rbinsizeEditFieldLabel          matlab.ui.control.Label
        rbinsizeEditField               matlab.ui.control.NumericEditField
        XmaxEditField_7                 matlab.ui.control.NumericEditField
        BivariatehistogramPanel_2       matlab.ui.container.Panel
        LUTDropDown                     matlab.ui.control.DropDown
        LUTDropDownLabel                matlab.ui.control.Label
        LUTscaleDropDown                matlab.ui.control.DropDown
        LUTscaleDropDownLabel           matlab.ui.control.Label
        DatascaleDropDown               matlab.ui.control.DropDown
        DatascaleDropDownLabel          matlab.ui.control.Label
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        binsizephotonsEditFieldLabel    matlab.ui.control.Label
        binsizephotonsEditField         matlab.ui.control.NumericEditField
        LUTscaleDropDownLabel_2         matlab.ui.control.Label
        MaxCount_3                      matlab.ui.control.NumericEditField
        MaxCount_2                      matlab.ui.control.NumericEditField
        XmaxEditField_4                 matlab.ui.control.NumericEditField
        XmaxEditField_3                 matlab.ui.control.NumericEditField
        PairedpointsEditField_2         matlab.ui.control.NumericEditField
        PairedpointsEditField_2Label    matlab.ui.control.Label
        MaxpairingdistancenmLabel       matlab.ui.control.Label
        XYZpairingdistnmEditField       matlab.ui.control.NumericEditField
        FindpairsButton                 matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        DemixingTab                     matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        Panel_3                         matlab.ui.container.Panel
        GridLayout2_2                   matlab.ui.container.GridLayout
        OutputpointsDropDown            matlab.ui.control.DropDown
        OutputpointsDropDownLabel       matlab.ui.control.Label
        OutputchannelsDropDown          matlab.ui.control.DropDown
        OutputchannelsDropDownLabel     matlab.ui.control.Label
        BivariatehistogramPanel_3       matlab.ui.container.Panel
        LUTscaleDropDownLabel_3         matlab.ui.control.Label
        MaxCount_5                      matlab.ui.control.NumericEditField
        MaxCount_4                      matlab.ui.control.NumericEditField
        LUTscaleDropDown_2              matlab.ui.control.DropDown
        LUTscaleDropDown_2Label         matlab.ui.control.Label
        DatascaleDropDown_2             matlab.ui.control.DropDown
        DatascaleDropDown_2Label        matlab.ui.control.Label
        XmaxEditField_6                 matlab.ui.control.NumericEditField
        XmaxEditField_5                 matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        YEditField_2                    matlab.ui.control.NumericEditField
        XEditField_2Label               matlab.ui.control.Label
        XEditField_2                    matlab.ui.control.NumericEditField
        binsizephotonsEditField_2Label  matlab.ui.control.Label
        binsizephotonsEditField_2       matlab.ui.control.NumericEditField
        LoadROIsButton                  matlab.ui.control.Button
        SaveROIsButton                  matlab.ui.control.Button
        DemixedlocalizationsEditField   matlab.ui.control.NumericEditField
        DemixedlocalizationsEditFieldLabel  matlab.ui.control.Label
        RatioIntAIntBEditFieldLabel     matlab.ui.control.Label
        RatioIntAIntBEditField          matlab.ui.control.NumericEditField
        UITable_3                       matlab.ui.control.Table
        UIAxes2_2                       matlab.ui.control.UIAxes
        ChromaticaberrationsTab         matlab.ui.container.Tab
        GridLayout5_2                   matlab.ui.container.GridLayout
        Panel_5                         matlab.ui.container.Panel
        GridLayout_2                    matlab.ui.container.GridLayout
        QuiverplotPanel_2               matlab.ui.container.Panel
        ArrowscaleEditField_2Label      matlab.ui.control.Label
        ArrowscaleEditField_2           matlab.ui.control.NumericEditField
        StepmEditField_2                matlab.ui.control.NumericEditField
        StepmEditField_2Label           matlab.ui.control.Label
        SaveresultsPanel                matlab.ui.container.Panel
        pushbutton42_2                  matlab.ui.control.Button
        pushbutton43                    matlab.ui.control.Button
        pushbutton42                    matlab.ui.control.Button
        DpolynomialfitButtonGroup       matlab.ui.container.ButtonGroup
        PolynomialdegreeDropDown_3      matlab.ui.control.DropDown
        PolynomialdegreeDropDown_3Label  matlab.ui.control.Label
        OutputchannelDropDown           matlab.ui.control.DropDown
        OutputchannelDropDownLabel      matlab.ui.control.Label
        AftercorrectionButton           matlab.ui.control.RadioButton
        BeforecorrectionButton          matlab.ui.control.RadioButton
        UIAxes_3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        AB0 % original localization tables
        AB % localization tables after crop
        AB1 % shifted loc tables
        shiftx % Description
        shifty % Description
        shiftz
        ABpaired % Description
        Int % Description
        roi
        tf
        ABout 
        h % SR image histogram
        roiIA % Roi for the crop, channel A
        roiIB % Roi for the crop, channel B
        tfIA % Description
        tfIB
        roiP % Description
        GMT_wysiwygcont = [0.250980000000000,0,0.276078000000000;0.250980000000000,0,0.326275000000000;0.250980000000000,0,0.376471000000000;0.250980000000000,0,0.426667000000000;0.250980000000000,0,0.476863000000000;0.250980000000000,0,0.527059000000000;0.250980000000000,0,0.577255000000000;0.250980000000000,0,0.627451000000000;0.250980000000000,0,0.677647000000000;0.250980000000000,0,0.727843000000000];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

        coordw % Description
        coordm
        Ameansort % Description
        Awmeansort
        delta % Description
        p %chroma fit
        mu %chroma fit
        folder % Description
        shiftxx
        shiftyy
        mu0
        p0
        AB1t % temporary AB1
        posIA % Description
        posIB
        hist2 % 
        hist22 % Description
        finetform % Description
        finetformIm
        AB1tofit % Description
        finetformZ
    end
    
    properties (Access = private)
        CallingApp % Main app object
    end
    
    methods (Access = private)
        
        function updateTable(app)
            app.UITable.Data = [size(app.AB{1},1), size(app.AB{2},1); app.AB{1}(end,2) - app.AB{1}(1,2) + 1, app.AB{2}(end,2) - app.AB{2}(1,2) + 1];
            app.UITable_5.Data = [size(app.AB{1},1), size(app.AB{2},1); app.AB{1}(end,2) - app.AB{1}(1,2) + 1, app.AB{2}(end,2) - app.AB{2}(1,2) + 1];
        end
                      
        function shift(app)
            if isempty(app.AB1)
                app.AB1 = app.AB;
                app.AB1{2}(:,4) = app.AB{2}(:,4) + app.XshiftEditField.Value;
                app.AB1{2}(:,5) = app.AB{2}(:,5) + app.YshiftEditField.Value;
                app.AB1{2}(:,6) = app.AB{2}(:,6) + app.ZshiftEditField.Value;
            end
        end
        
        function CheckShift(app)
            %shift(app);
            if strcmp(app.DropDown.Value, 'for first')
                [app.shiftx, app.shifty, app.shiftz] = shiftXYlin(app.AB1{1}, app.AB1{2}, app.XshiftEditField_2.Value);
            elseif strcmp(app.DropDown.Value, 'for last')
                [app.shiftx, app.shifty, app.shiftz] = shiftXYlinlast(app.AB1{1}, app.AB1{2}, app.XshiftEditField_2.Value);
            end
            updateHist(app)
        end
        
        function updateHist(app)
            if app.BinwidthnmEditField.Value > 0
                histogram(app.UIAxes, app.shiftx, 'BinLimits', [-32766 32766], 'BinEdges', -32765.5:app.BinwidthnmEditField.Value:32765.5);
                histogram(app.UIAxes_2, app.shifty, 'BinLimits', [-32766 32766], 'BinEdges', -32765.5:app.BinwidthnmEditField.Value:32765.5);
                histogram(app.UIAxes_6, app.shiftz, 'BinLimits', [-32766 32766], 'BinEdges', -32765.5:app.BinwidthnmEditField.Value:32765.5);
            else
                histogram(app.UIAxes, app.shiftx, 'BinLimits', [-32766 32766],  'BinMethod','integers');
                histogram(app.UIAxes_2, app.shifty, 'BinLimits', [-32766 32766], 'BinMethod','integers');
                histogram(app.UIAxes_6, app.shiftz, 'BinLimits', [-32766 32766], 'BinMethod','integers');
            end
            xlim(app.UIAxes, [-app.MaxshiftnmEditField.Value, app.MaxshiftnmEditField.Value]); xlim(app.UIAxes_2, [-app.MaxshiftnmEditField.Value, app.MaxshiftnmEditField.Value]);
            xlim(app.UIAxes_6, [-app.MaxshiftnmEditField.Value, app.MaxshiftnmEditField.Value]);
            ylim(app.UIAxes, [0, Inf]); ylim(app.UIAxes_2, [0, Inf]); ylim(app.UIAxes_6, [0, Inf]); 
            xlabel(app.UIAxes, 'Nearest neighbor distance, nm'); xlabel(app.UIAxes_2, 'Nearest neighbor distance, nm'); xlabel(app.UIAxes_6, 'Nearest neighbor distance, nm'); 
            ylabel(app.UIAxes, 'Count'); ylabel(app.UIAxes_2, 'Count'); ylabel(app.UIAxes_6, 'Count');
        end
        

        function updateIntHist(app)


            if   strcmp(app.HistogramDropDown.Value, 'Bivariate')

                if strcmp(app.LUTDropDown.Value, 'jet')
                    app.UIAxes2.Colormap = jet;
                elseif strcmp(app.LUTDropDown.Value, 'turbo')
                    app.UIAxes2.Colormap = turbo;
                elseif strcmp(app.LUTDropDown.Value, 'parula')
                    app.UIAxes2.Colormap = parula;
                elseif strcmp(app.LUTDropDown.Value, 'fire')
                    app.UIAxes2.Colormap = fire;
                elseif strcmp(app.LUTDropDown.Value, 'inferno')
                    app.UIAxes2.Colormap = inferno;
                elseif strcmp(app.LUTDropDown.Value, 'hot')
                    app.UIAxes2.Colormap = hot;
                elseif strcmp(app.LUTDropDown.Value, 'hsv')
                    app.UIAxes2.Colormap = hsv;
                elseif strcmp(app.LUTDropDown.Value, 'gray')
                    app.UIAxes2.Colormap = gray;
                elseif strcmp(app.LUTDropDown.Value, 'pink')
                    app.UIAxes2.Colormap = pink;
                elseif strcmp(app.LUTDropDown.Value, 'copper')
                    app.UIAxes2.Colormap = copper;
                elseif strcmp(app.LUTDropDown.Value, 'bone')
                    app.UIAxes2.Colormap = bone;
                elseif strcmp(app.LUTDropDown.Value, 'winter')
                    app.UIAxes2.Colormap = winter;
                elseif strcmp(app.LUTDropDown.Value, 'autumn')
                    app.UIAxes2.Colormap = autumn;
                elseif strcmp(app.LUTDropDown.Value, 'summer')
                    app.UIAxes2.Colormap = summer;
                elseif strcmp(app.LUTDropDown.Value, 'spring')
                    app.UIAxes2.Colormap = spring;
                elseif strcmp(app.LUTDropDown.Value, 'cool')
                    app.UIAxes2.Colormap = cool;
                elseif strcmp(app.LUTDropDown.Value, 'lines')
                    app.UIAxes2.Colormap = lines;
                elseif strcmp(app.LUTDropDown.Value, 'colorcube')
                    app.UIAxes2.Colormap = colorcube;
                elseif strcmp(app.LUTDropDown.Value, 'prism')
                    app.UIAxes2.Colormap = prism;
                elseif strcmp(app.LUTDropDown.Value, 'flag')
                    app.UIAxes2.Colormap = flag;
                end
                
                app.hist2 = histogram2(app.UIAxes2, app.Int(:,1), app.Int(:,2), app.XmaxEditField_3.Value:app.binsizephotonsEditField.Value:app.XEditField.Value, app.XmaxEditField_4.Value:app.binsizephotonsEditField.Value:app.YEditField.Value, 'XBinLimitsMode', 'manual',  'YBinLimitsMode', 'manual', 'DisplayStyle','tile', 'facecolor','flat');
                
                xlim(app.UIAxes2, [app.XmaxEditField_3.Value, app.XEditField.Value]);
                ylim(app.UIAxes2, [app.XmaxEditField_4.Value, app.YEditField.Value]);
                xlabel(app.UIAxes2, 'I_A, photons');
                ylabel(app.UIAxes2, 'I_B, photons');

                UIAxes2CLimits = caxis(app.UIAxes2);
                app.MaxCount_2.Value = UIAxes2CLimits(1);
                app.MaxCount_3.Value = UIAxes2CLimits(2);

                if strcmp(app.LUTscaleDropDown.Value, 'Linear')
                    app.UIAxes2.ColorScale = 'linear';

                elseif strcmp(app.LUTscaleDropDown.Value, 'Logarithmic')
                    app.UIAxes2.ColorScale = 'log';

                end

                c = colorbar(app.UIAxes2);
                c.Label.String = 'Count';

                hold (app.UIAxes2, 'on');
                plot(app.UIAxes2, 0:app.RatioIBIAEditField.Value:20000, 'Color', '#D95319', 'LineStyle',"--");
                hold (app.UIAxes2, 'off');

                if strcmp(app.DatascaleDropDown.Value, 'Linear')
                    app.UIAxes2.XScale = 'linear';
                    app.UIAxes2.YScale = 'linear';

                elseif strcmp(app.DatascaleDropDown.Value, 'Logarithmic')
                    app.UIAxes2.XScale = 'log';
                    app.UIAxes2.YScale = 'log';

                end
                app.UIAxes2.Title.String = 'Photon counts';
                app.UIAxes2.XTickMode = 'auto';


            elseif strcmp(app.HistogramDropDown.Value, 'Univariate')


                if strcmp(app.rscaleDropDown.Value, 'Linear')
                    Binedges = (app.XmaxEditField_7.Value:app.rbinsizeEditField.Value:app.rlimEditField.Value);
                elseif strcmp(app.rscaleDropDown.Value, 'Logarithmic')
                    Binedges = 10.^(log10(app.XmaxEditField_7.Value):app.rbinsizeEditField.Value:log10(app.rlimEditField.Value));
                end   

                if strcmp(app.rDropDown.Value, 'IB/IA')
                    app.hist2 = histogram(app.UIAxes2, app.Int(:,2) ./ app.Int(:,1), 'BinEdges', Binedges, 'BinLimitsMode', 'manual');
                    xlabel(app.UIAxes2, 'r = I_B/I_A');
                    xline(app.UIAxes2, app.RatioIBIAEditField.Value, 'Color', '#D95319', 'LineStyle', "--")

                elseif strcmp(app.rDropDown.Value, 'IA/IB')
                    app.hist2 = histogram(app.UIAxes2, app.Int(:,1) ./ app.Int(:,2), 'BinEdges', Binedges, 'BinLimitsMode', 'manual');
                    xlabel(app.UIAxes2, 'r = I_A/I_B');
                    xline(app.UIAxes2, 1/app.RatioIBIAEditField.Value, 'Color', '#D95319', 'LineStyle', "--")
                end

                    xlim(app.UIAxes2, [app.XmaxEditField_7.Value, app.rlimEditField.Value]);
                    ylim(app.UIAxes2, 'auto');
                    ylabel(app.UIAxes2, 'count');

                if strcmp(app.rscaleDropDown.Value, 'Linear')
                    app.UIAxes2.XScale = 'linear';
                    app.UIAxes2.XTickMode = 'auto';
                elseif strcmp(app.rscaleDropDown.Value, 'Logarithmic')
                    app.UIAxes2.XScale = 'log';
                    app.UIAxes2.XTick = [0.1 0.14 0.2 0.3 0.5 0.7 1 1.4 2 3 4 5 6 8 10];
                end    
              
                if strcmp(app.countscaleDropDown.Value, 'Linear')
                    app.UIAxes2.YScale = 'linear';
                elseif strcmp(app.countscaleDropDown.Value, 'Logarithmic')
                    app.UIAxes2.YScale = 'log';
                end
                
                app.UIAxes2.Title.String = 'Ratio of photon counts';
                colorbar(app.UIAxes2, 'off');

            end
        end
        
        function updateTable2(app)
            app.PairedpointsEditField_2.Value = size(app.ABpaired{1},1);
        end
        
                
        function Unmix(app)
            app.tf = cell(size(app.roi));
            for i = 1:size(app.tf,2)
                app.tf{i} = inROI(app.roi{i}, app.Int(:,1), app.Int(:,2));
            end
            UpdateTable3(app);
            Output(app);
        end
        
        
        function updateIntHistUnmixint(app)
            map = turbo(1024);
            colormap (app.UIAxes2_2, map);
            app.hist22 = histogram2(app.UIAxes2_2, app.Int(:,1), app.Int(:,2), app.XmaxEditField_5.Value:app.binsizephotonsEditField_2.Value:app.XEditField_2.Value, app.XmaxEditField_6.Value:app.binsizephotonsEditField_2.Value:app.YEditField_2.Value, 'XBinLimitsMode', 'manual',  'YBinLimitsMode', 'manual', 'DisplayStyle','tile', 'facecolor','flat');
            xlim(app.UIAxes2_2, [app.XmaxEditField_5.Value, app.XEditField_2.Value]);
            ylim(app.UIAxes2_2, [app.XmaxEditField_6.Value, app.YEditField_2.Value]);
            xlabel(app.UIAxes2_2, 'I_A, photons');
            ylabel(app.UIAxes2_2, 'I_B, photons');
            
            UIAxes22CLimits = caxis(app.UIAxes2_2);
            app.MaxCount_4.Value = UIAxes22CLimits(1);
            app.MaxCount_5.Value = UIAxes22CLimits(2);

            if strcmp(app.LUTscaleDropDown_2.Value, 'Linear')
                app.UIAxes2_2.ColorScale = 'linear';
                
            elseif strcmp(app.LUTscaleDropDown_2.Value, 'Logarithmic')
                app.UIAxes2_2.ColorScale = 'log';
                
            end
            
            c = colorbar(app.UIAxes2_2);
            c.Label.String = 'Count';
            hold (app.UIAxes2_2, 'on');
            plot(app.UIAxes2_2, 0:1/app.RatioIntAIntBEditField.Value:20000, 'Color', '#D95319', 'LineStyle',"--");
            hold (app.UIAxes2_2, 'off'); 
            if isempty(app.roiP)
                if app.OutputchannelsDropDown.Value == '1'
                    roiP0{1} = [70 250; 250 70; app.XEditField_2.Value 70; app.XEditField_2.Value app.YEditField_2.Value; 70 app.YEditField_2.Value];
                elseif app.OutputchannelsDropDown.Value == '2'
                    roiP0{2} = [250 70; 1000 650; app.XEditField_2.Value app.YEditField_2.Value/app.RatioIntAIntBEditField.Value-app.YEditField_2.Value/3; app.XEditField_2.Value 70];
                    roiP0{1} = [70 250; 550 500; app.XEditField_2.Value*app.RatioIntAIntBEditField.Value-app.YEditField_2.Value/2.5 app.YEditField_2.Value; 70 app.YEditField_2.Value];
                elseif app.OutputchannelsDropDown.Value == '3'
                    roiP0{3} = [70 250; 250 70; app.XEditField_2.Value app.YEditField_2.Value/app.RatioIntAIntBEditField.Value-app.YEditField_2.Value/3; app.XEditField_2.Value*app.RatioIntAIntBEditField.Value-app.YEditField_2.Value/2.5 app.YEditField_2.Value];         
                    roiP0{2} = [250 70; 1000 650; app.XEditField_2.Value app.YEditField_2.Value/app.RatioIntAIntBEditField.Value-app.YEditField_2.Value/3; app.XEditField_2.Value 70];
                    roiP0{1} = [70 250; 550 500; app.XEditField_2.Value*app.RatioIntAIntBEditField.Value-app.YEditField_2.Value/2.5 app.YEditField_2.Value; 70 app.YEditField_2.Value];
                end
            elseif str2double(app.OutputchannelsDropDown.Value) ~= size(app.roiP,2)
                if app.OutputchannelsDropDown.Value == '1' && size(app.roiP,2) == 2
                    roiP0{1} = app.roiP{1};
                    app.roiP(2) = [];
                elseif app.OutputchannelsDropDown.Value == '1' && size(app.roiP,2) == 3
                    roiP0{1} = app.roiP{1};
                    app.roiP(2) = [];
                    app.roiP(3) = [];
                elseif app.OutputchannelsDropDown.Value == '2' && size(app.roiP,2) == 1
                    roiP0{1} = app.roiP{1};
                    roiP0{2} = [250 70; 1000 650; app.XEditField_2.Value app.YEditField_2.Value/app.RatioIntAIntBEditField.Value-app.YEditField_2.Value/3; app.XEditField_2.Value 70];
                elseif app.OutputchannelsDropDown.Value == '2' && size(app.roiP,2) == 3
                    roiP0{1} = app.roiP{1};
                    roiP0{2} = app.roiP{2};
                    app.roiP(3) = [];
                elseif app.OutputchannelsDropDown.Value == '3' && size(app.roiP,2) == 1
                    roiP0{1} = app.roiP{1};
                    roiP0{2} = [250 70; 1000 650; app.XEditField_2.Value app.YEditField_2.Value/app.RatioIntAIntBEditField.Value-app.YEditField_2.Value/3; app.XEditField_2.Value 70];
                    roiP0{3} = [70 250; 250 70; app.XEditField_2.Value app.YEditField_2.Value/app.RatioIntAIntBEditField.Value-app.YEditField_2.Value/3; app.XEditField_2.Value*app.RatioIntAIntBEditField.Value-app.YEditField_2.Value/2.5 app.YEditField_2.Value];
                elseif app.OutputchannelsDropDown.Value == '3' && size(app.roiP,2) == 2
                    roiP0{1} = app.roiP{1};
                    roiP0{2} = app.roiP{2};
                    roiP0{3} = [70 250; 250 70; app.XEditField_2.Value app.YEditField_2.Value/app.RatioIntAIntBEditField.Value-app.YEditField_2.Value/3; app.XEditField_2.Value*app.RatioIntAIntBEditField.Value-app.YEditField_2.Value/2.5 app.YEditField_2.Value];
                end
            else
                roiP0 = app.roiP;
            end
            app.roi = cell(size(roiP0));
            color = {'r','g','b'};
            %color = {'g','m','b'};
            app.UIAxes2_2.FontSize = 16;
            for i = 1:size(app.roi,2)
                app.roi{i} = images.roi.Polygon(app.UIAxes2_2, 'Position', roiP0{i}, 'Color', color{i}, 'MarkerSize', 4);
                addlistener(app.roi{i},'ROIMoved',@listener);
            end
            
            function listener(~,~)
                Unmix(app);
                for j = 1:size(app.roi,2)
                    app.roiP{j} = app.roi{j}.Position;
                end
            end
            
            if strcmp(app.DatascaleDropDown_2.Value, 'Linear')
                app.UIAxes2_2.XScale = 'linear';
                app.UIAxes2_2.YScale = 'linear';
                
            elseif strcmp(app.DatascaleDropDown_2.Value, 'Logarithmic')
                app.UIAxes2_2.XScale = 'log';
                app.UIAxes2_2.YScale = 'log';
            end
            bringToFront(app.roi{end});
        end

        function UpdateTable3(app)
            
            app.UITable_3.RowName = {'Points', '% of paired', '% of input', 'Cross-talk, %'};
            
            if size(app.tf,2) == 1
                
                app.UITable_3.ColumnName = {'A demixed'};
                app.UITable_3.Data = [sum(app.tf{1});           % Points
                100 * sum(app.tf{1}) / size(app.ABpaired{1},1); % % of paired 
                100 * sum(app.tf{1}) / min([size(app.AB1{1},1), size(app.AB1{2},1)]);      % % of input
                0];                                             % Cross-talk, %
                
            elseif size(app.tf,2) == 2
                
                app.UITable_3.ColumnName = {'A demixed', 'B demixed'};
                app.UITable_3.Data = [sum(app.tf{1}), sum(app.tf{2});                                           % Points
                100 * sum(app.tf{1}) / size(app.ABpaired{1},1), 100 * sum(app.tf{2}) / size(app.ABpaired{1},1); % % of paired 
                100 * sum(app.tf{1}) / min([size(app.AB1{1},1), size(app.AB1{2},1)]), 100 * sum(app.tf{2}) / min([size(app.AB1{1},1), size(app.AB1{2},1)]); % % of input
                100 * sum(app.tf{1}) / max([sum(app.tf{1}), sum(app.tf{2})]), 100 * sum(app.tf{2}) / max([sum(app.tf{1}), sum(app.tf{2})])];           % Cross-talk, %

            elseif size(app.tf,2) == 3
                
                app.UITable_3.ColumnName = {'A demixed', 'B demixed', 'C demixed'};
                app.UITable_3.Data = [sum(app.tf{1}), sum(app.tf{2}), sum(app.tf{3});                           % Points
                100 * sum(app.tf{1}) / size(app.ABpaired{1},1), 100 * sum(app.tf{2}) / size(app.ABpaired{1},1), 100 * sum(app.tf{3}) / size(app.ABpaired{1},1);  % % of paired 
                100 * sum(app.tf{1}) / min([size(app.AB1{1},1), size(app.AB1{2},1)]), 100 * sum(app.tf{2}) / min([size(app.AB1{1},1), size(app.AB1{2},1)]), 100 * sum(app.tf{3}) / min([size(app.AB1{1},1), size(app.AB1{2},1)]); % % of input
                100 * sum(app.tf{1}) / max([sum(app.tf{1}), sum(app.tf{2}), sum(app.tf{3})]), 100 * sum(app.tf{2}) / max([sum(app.tf{1}), sum(app.tf{2}), sum(app.tf{3})]), 100 * sum(app.tf{3}) / max([sum(app.tf{1}), sum(app.tf{2}), sum(app.tf{3})])];  % Cross-talk, %
                
            end
            
        app.DemixedlocalizationsEditField.Value = sum(sum([app.tf{:}]));
        end
        
        function Output(app)
            app.ABout = cell(1,3);
            %if app.AllfrominputAButton.Value
            if strcmpi(app.OutputpointsDropDown.Value, 'All from input A')
                for i = 1:size(app.tf,2)
                    app.ABout{i} = app.ABpaired{1}(app.tf{i},:);
                end
            elseif strcmpi(app.OutputpointsDropDown.Value, 'From brightest input')

                for i = 1:size(app.tf,2)
                    [~,brightestChannel] = max(mean([app.ABpaired{1}(app.tf{i},7), app.ABpaired{2}(app.tf{i},7)]),[],2); %determine the brightest channel
                    app.ABout{i} = app.ABpaired{brightestChannel}(app.tf{i},:);
                end
                
            elseif strcmpi(app.OutputpointsDropDown.Value, 'All from input B')
                for i = 1:size(app.tf,2)
                    app.ABout{i} = app.ABpaired{2}(app.tf{i},:);
                end
            elseif strcmpi(app.OutputpointsDropDown.Value, 'Weighted mean')

                for i = 1:size(app.tf,2)

                    ABout1{i} = app.ABpaired{1}(app.tf{i},:);
                    ABout2{i} = app.ABpaired{2}(app.tf{i},:);  
                    Int{i} = ABout1{i}(:,7) + ABout2{i}(:,7);
                    w1{i} = ABout1{i}(:,7)./Int{i};
                    w2{i} = ABout2{i}(:,7)./Int{i};
                    coord{i} = ABout1{i}(:, 4:6).*w1{i} + ABout2{i}(:, 4:6).*w2{i};
                    sigm{i} = ABout1{i}(:, 8:9).*w1{i} + ABout2{i}(:, 8:9).*w2{i};
                    app.ABout{i} = [ABout1{i}(:,1:3), coord{i}, Int{i}, sigm{i}];
                    
                end
            elseif strcmpi(app.OutputpointsDropDown.Value, 'Simple mean')

                for i = 1:size(app.tf,2)

                    ABout1{i} = app.ABpaired{1}(app.tf{i},:);
                    ABout2{i} = app.ABpaired{2}(app.tf{i},:);  
                    Int{i} = ABout1{i}(:,7) + ABout2{i}(:,7);
                    w1{i} = 0.5;
                    w2{i} = 0.5;
                    coord{i} = ABout1{i}(:, 4:6).*w1{i} + ABout2{i}(:, 4:6).*w2{i};
                    sigm{i} = ABout1{i}(:, 8:9).*w1{i} + ABout2{i}(:, 8:9).*w2{i};
                    app.ABout{i} = [ABout1{i}(:,1:3), coord{i}, Int{i}, sigm{i}];
                    
                end

            elseif strcmpi(app.OutputpointsDropDown.Value, 'Wmean - chroma')
                    
                Chroma(app);

            end
            if ~isempty (app.CallingApp)
                updateAB(app.CallingApp, app.ABout);
            end
        end
        
        function SRimage(app)
            i = activeInputList(app);

            if ~isempty(app.AB0{i})
                app.h = histogram2(app.UIAxes2_3, app.AB0{i}(:,4), app.AB0{i}(:,5), 'DisplayStyle', 'tile', 'facecolor','flat', 'EdgeAlpha', 0); 
                if strcmp(app.ColormapDropDown.Value, 'red hot')
                    map = hot;
                elseif strcmp(app.ColormapDropDown.Value, 'inferno')
                    map = inferno;
                elseif strcmp(app.ColormapDropDown.Value, 'gray')
                    map = gray;
                elseif strcmp(app.ColormapDropDown.Value, 'fire')
                    map = fire;
                end
                colormap (app.UIAxes2_3, map);
                axis(app.UIAxes2_3, 'equal') ; 
                app.UIAxes2_3.YDir = 'reverse';
                app.UIAxes2_3.Color = 'k';
                app.UIAxes2_3.PositionConstraint = 'innerposition';
                caxis(app.UIAxes2_3, [0 app.MaxCount.Value]);
                app.h.XBinEdges = min(app.AB0{i}(:,4)) : app.PixelsizenmEditField.Value : max(app.AB0{i}(:,4));
                app.h.YBinEdges = min(app.AB0{i}(:,5)) : app.PixelsizenmEditField.Value : max(app.AB0{i}(:,5));


                if app.InonefileButton.Value


                    if isempty(app.posIA)
                        app.posIA = [450 0; 25100 0; 25100 31000; 450 31000];
                    end
                    if isempty(app.posIB)
                        app.posIB = [26000 0; 50580 0; 50580 31000; 26000 31000];
                    end
                    app.roiIA = images.roi.Polygon(app.UIAxes2_3, 'Position', app.posIA, 'Color', 'Green', 'FaceAlpha', 0);
                    app.roiIB = images.roi.Polygon(app.UIAxes2_3, 'Position', app.posIB, 'Color', 'Red', 'FaceAlpha', 0);
                    Crop(app);
                    addlistener(app.roiIA,'ROIMoved',@listenerI);
                    addlistener(app.roiIB,'ROIMoved',@listenerI);
                    app.XshiftEditField.Value = -25643;
                else
                    app.UITable_4.Data = [size(app.AB0{1}, 1), size(app.AB0{2}, 1); 100, 100];
                    app.XshiftEditField.Value = 0;
                end
            end
                function listenerI(~,~)
                    Crop(app);
                end
        end
        
        function Crop(app)
            i = activeInputList(app);
            app.tfIA = inROI(app.roiIA, app.AB0{i}(:,4), app.AB0{i}(:,5));
            app.tfIB = inROI(app.roiIB, app.AB0{i}(:,4), app.AB0{i}(:,5));
            app.UITable_4.Data = [sum(app.tfIA), sum(app.tfIB); 100 * sum(app.tfIA)/size(app.AB0{i},1), 100 * sum(app.tfIB)/size(app.AB0{i},1)];
        end
        
        function i = activeInputList(app)
            if app.ISButton.Value
                i = 1;
            elseif app.IILButton.Value
                i = 2;
            end
        end
        
        
        function Chroma(app) 

            app.ABout = cell(1,3);

            for i = 1:size(app.tf,2)

                ABout1{i} = app.ABpaired{1}(app.tf{i},:);
                ABout2{i} = app.ABpaired{2}(app.tf{i},:); 
                Int{i} = ABout1{i}(:,7) + ABout2{i}(:,7);  
                w1{i} = ABout1{i}(:,7)./Int{i};
                w2{i} = ABout2{i}(:,7)./Int{i};
                app.coordw{i} = ABout1{i}(:, 4:6).*w1{i} + ABout2{i}(:, 4:6).*w2{i};
                app.coordm{i} = ABout1{i}(:, 4:6).*0.5 + ABout2{i}(:, 4:6).*0.5;
                coord{i} = app.coordw{i};
 
                %fitting


                app.p{i} = fitgeotrans(app.coordm{i}(:,1:2), app.coordw{i}(:,1:2), "polynomial", str2double(app.PolynomialdegreeDropDown_3.Value));
                [coord{i}(:,1), coord{i}(:,2)] = transformPointsInverse(app.p{i}, app.coordw{i}(:,1), app.coordw{i}(:,2));


                sigm{i} = ABout1{i}(:, 8:9).*w1{i} + ABout2{i}(:, 8:9).*w2{i};
                app.ABout{i} = [ABout1{i}(:,1:3), coord{i}, Int{i}, sigm{i}];
                    
             end

            if ~isempty (app.CallingApp)
                updateAB(app.CallingApp, app.ABout);
            end
            Chromaplot(app);
        end
        
        function Chromaplot(app)

                i = min([str2double(app.OutputchannelDropDown.Value), size(app.tf,2)]); %channel to show

            if app.BeforecorrectionButton.Value
            
                [X,Y] = meshgrid(min(app.ABout{i}(:,4)) : app.StepmEditField_2.Value * 1000 : max(app.ABout{i}(:,4)), min(app.ABout{i}(:,5)) : app.StepmEditField_2.Value * 1000 : max(app.ABout{i}(:,5)));

                [Xn, Yn] = transformPointsInverse(app.p{i}, X, Y);
                U = (Xn - X) * app.ArrowscaleEditField_2.Value;
                V = (Yn - Y) * app.ArrowscaleEditField_2.Value;
                quiver(app.UIAxes_3, X, Y, U, V, "off");
                app.UIAxes_3.XLim = [min(app.ABout{i}(:,4)), max(app.ABout{i}(:,4))];
                app.UIAxes_3.YLim = [min(app.ABout{i}(:,5)), max(app.ABout{i}(:,5))];


            elseif app.AftercorrectionButton.Value


                p1{i} = fitgeotrans(app.ABout{i}(:,4:5), app.coordm{i}(:,1:2), "polynomial", str2double(app.PolynomialdegreeDropDown_3.Value));

                [X,Y] = meshgrid(min(app.ABout{i}(:,4)) : app.StepmEditField_2.Value * 1000 : max(app.ABout{i}(:,4)), min(app.ABout{i}(:,5)) : app.StepmEditField_2.Value * 1000 : max(app.ABout{i}(:,5)));

                [Xn, Yn] = transformPointsInverse(p1{i}, X, Y);
                U = (Xn - X) * app.ArrowscaleEditField_2.Value;
                V = (Yn - Y) * app.ArrowscaleEditField_2.Value;
                quiver(app.UIAxes_3, X, Y, U, V, "off");
                app.UIAxes_3.XLim = [min(app.ABout{i}(:,4)), max(app.ABout{i}(:,4))];
                app.UIAxes_3.YLim = [min(app.ABout{i}(:,5)), max(app.ABout{i}(:,5))];

            end

                app.UIAxes_3.XLabel.String = 'X position, nm';
                app.UIAxes_3.YLabel.String = 'Y position, nm';
        end
        

        function PosShiftFit(app)
           if ~isempty(app.AB1tofit)
                if strcmp(app.XYfitDropDown.Value, "polynomial")
                    app.finetform = fitgeotrans(app.AB1tofit{1}(:,4:5), app.AB1tofit{2}(:,4:5), "polynomial", str2double(app.PolynomialdegreeDropDown.Value));
                    app.finetformIm = fitgeotrans(app.AB1tofit{1}(:,4:5)/app.XshiftEditField_7.Value, app.AB1tofit{2}(:,4:5)/app.XshiftEditField_7.Value, "polynomial", str2double(app.PolynomialdegreeDropDown.Value));
                elseif strcmp(app.XYfitDropDown.Value, "affine")
                    app.finetform = fitgeotrans(app.AB1tofit{1}(:,4:5), app.AB1tofit{2}(:,4:5), "affine");
                elseif strcmp(app.XYfitDropDown.Value, "nonreflectivesimilarity")
                    app.finetform = fitgeotrans(app.AB1tofit{1}(:,4:5), app.AB1tofit{2}(:,4:5), "nonreflectivesimilarity");
                elseif strcmp(app.XYfitDropDown.Value, "projective")
                    app.finetform = fitgeotrans(app.AB1tofit{1}(:,4:5), app.AB1tofit{2}(:,4:5), "projective");
                elseif strcmp(app.XYfitDropDown.Value, "piecewise linear")
                    app.finetform = fitgeotrans(app.AB1tofit{1}(:,4:5), app.AB1tofit{2}(:,4:5), "pwl");
                elseif strcmp(app.XYfitDropDown.Value, "local weighted mean")
                    app.finetform = fitgeotrans(app.AB1tofit{1}(:,4:5), app.AB1tofit{2}(:,4:5), "lwm", 15);
                end

                if strcmp(app.ZfitDropDown.Value, "polynomial")
                    app.finetformZ = fit(app.AB1tofit{1}(:,4:5),app.AB1tofit{1}(:,6) - app.AB1tofit{2}(:,6), ['poly' app.PolynomialdegreeDropDown.Value app.PolynomialdegreeDropDown.Value]);
                end

                PosShiftPlot(app);
           end
        end

        function PosShiftPlot(app)

        if ~strcmp(app.XYfitDropDown.Value, "no fit")

            if strcmp(app.ZfitDropDown.Value, "polynomial")
                [X,Y] = meshgrid(min(app.AB1{2}(:,4)) : app.StepmEditField.Value * 1000 : max(app.AB1{2}(:,4)), min(app.AB1{2}(:,5)) : app.StepmEditField.Value * 1000 : max(app.AB1{2}(:,5)));
                Z = zeros(size(X));
                [Xn, Yn] = transformPointsInverse(app.finetform, X, Y);
                U = (Xn - X) * app.ArrowscaleEditField.Value;
                V = (Yn - Y) * app.ArrowscaleEditField.Value;
                W = reshape(feval(app.finetformZ, [X(:), Y(:)]),size(X)) * app.ArrowscaleEditField.Value;
                quiver3(app.UIAxes_5, X, Y, Z, U, V, W, "off");
                app.UIAxes_5.XLim = [min(app.AB1{2}(:,4)), max(app.AB1{2}(:,4))];
                app.UIAxes_5.YLim = [min(app.AB1{2}(:,5)), max(app.AB1{2}(:,5))];
                if min(W, [], 'all') ~= max(W, [], 'all')
                    app.UIAxes_5.ZLim = [min(W, [], 'all'), max(W, [], 'all')];
                else
                    app.UIAxes_5.ZLim = [-1, 1];
                    view(app.UIAxes_5, 2);
                end

            else
                [X,Y] = meshgrid(min(app.AB1{2}(:,4)) : app.StepmEditField.Value * 1000 : max(app.AB1{2}(:,4)), min(app.AB1{2}(:,5)) : app.StepmEditField.Value * 1000 : max(app.AB1{2}(:,5)));
                [Xn, Yn] = transformPointsInverse(app.finetform, X, Y);
                U = (Xn - X) * app.ArrowscaleEditField.Value;
                V = (Yn - Y) * app.ArrowscaleEditField.Value;
                quiver(app.UIAxes_5, X, Y, U, V, "off");
                app.UIAxes_5.XLim = [min(app.AB1{2}(:,4)), max(app.AB1{2}(:,4))];
                app.UIAxes_5.YLim = [min(app.AB1{2}(:,5)), max(app.AB1{2}(:,5))];
                view(app.UIAxes_5, 2);
            end

        else
            [X,Y] = meshgrid(min(app.AB1tofit{2}(:,4)) + app.StepmEditField.Value * 500 : app.StepmEditField.Value * 1000 : max(app.AB1tofit{2}(:,4)) - app.StepmEditField.Value * 500, ...
                min(app.AB1tofit{2}(:,5)) + app.StepmEditField.Value * 500 : app.StepmEditField.Value * 1000 : max(app.AB1tofit{2}(:,5)) - app.StepmEditField.Value * 500);
            [Xedges,Yedges] = meshgrid(min(app.AB1tofit{2}(:,4)) : app.StepmEditField.Value * 1000 : max(app.AB1tofit{2}(:,4)), ...
                min(app.AB1tofit{2}(:,5)) : app.StepmEditField.Value * 1000 : max(app.AB1tofit{2}(:,5)));
            distances = app.AB1tofit{1}(:,4:6) - app.AB1tofit{2}(:,4:6);
            shiftX = zeros(size(X));
            shiftY = zeros(size(Y));
            shiftZ = zeros(size(Y));
            for i = 1:size(Xedges,1) - 1
                for j = 1:size(Yedges,2) - 1
                    IndAB1 = app.AB1tofit{1}(:, 4) >= Xedges(i,j) & app.AB1tofit{1}(:, 4) < Xedges(i+1,j+1) & app.AB1tofit{1}(:, 5) >= Yedges(i,j) & app.AB1tofit{1}(:, 5) < Yedges(i+1,j+1);
                    shiftX(i,j) = mean(distances(IndAB1, 1));
                    shiftY(i,j) = mean(distances(IndAB1, 2));
                    shiftZ(i,j) = mean(distances(IndAB1, 3));
                end
            end

            U = shiftX * app.ArrowscaleEditField.Value;
            V = shiftY * app.ArrowscaleEditField.Value;
            W = shiftZ * app.ArrowscaleEditField.Value;
            Z = zeros(size(X));
            quiver3(app.UIAxes_5, X, Y, Z, U, V, W, "off")
            app.UIAxes_5.XLim = [min(app.AB1{2}(:,4)), max(app.AB1{2}(:,4))];
            app.UIAxes_5.YLim = [min(app.AB1{2}(:,5)), max(app.AB1{2}(:,5))];
            %app.UIAxes_5.ZLim = [min(app.AB1{2}(:,6)), max(app.AB1{2}(:,6))];
            if min(W, [], 'all') ~= max(W, [], 'all')
                app.UIAxes_5.ZLim = [min(W, [], 'all'), max(W, [], 'all')];
            else
                app.UIAxes_5.ZLim = [-1, 1];
                view(app.UIAxes_5, 2);
            end
        end
            app.UIAxes_5.YDir = "reverse";

        end
        
        function PosShiftCorrect(app)
        if ~isempty(app.finetform)

        if ~strcmp(app.XYfitDropDown.Value, "no fit")
                [app.AB1{2}(:,4), app.AB1{2}(:,5)] = transformPointsInverse(app.finetform,app.AB1t{2}(:,4),app.AB1t{2}(:,5));

                if strcmp(app.ZfitDropDown.Value, "polynomial")
                    app.AB1{2}(:,6) = app.AB1t{2}(:,6) + feval(app.finetformZ, [app.AB1t{2}(:,4), app.AB1t{2}(:,5)]);
                else
                    app.AB1{2}(:,6) = app.AB1t{2}(:,6);
                end
        else
            app.AB1{2}(:,4) = app.AB1t{2}(:,4);
            app.AB1{2}(:,5) = app.AB1t{2}(:,5);
        end
        end
        end
    end
    
    methods (Access = public)

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, AB0)
try
    if exist('mainapp', 'var')
                app.CallingApp = mainapp; % add mainapp to the input arguments
                app.AB0 = AB0;
                app.folder = app.CallingApp.folder;

            if isempty (app.AB0{1}) || isempty (app.AB0{2})
                app.InonefileButton.Value = true;
                app.DisplaydatafrominputlistButtonGroup.Enable = 'off';
                app.XshiftEditField.Value = -25643;
                if isempty(app.AB0{1})
                    app.IILButton.Value = true;
                elseif isempty(app.AB0{2})
                    app.ISButton.Value = true;
                end
            else
                app.InseparatefilesButton.Value = true;
                app.DisplaydatafrominputlistButtonGroup.Enable = 'on';
                app.XshiftEditField.Value = 0;
            end
            
            SRimage(app);
            i = activeInputList(app);
            app.InputpointsEditField.Value = size(app.AB0{i},1);
    end
            
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
try
                
            CheckShift(app);
                        
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: BinwidthnmEditField
        function BinwidthnmEditFieldValueChanged(app, event)
try
   
            updateHist(app);            

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: MaxshiftnmEditField
        function MaxshiftnmEditFieldValueChanged(app, event)
try
    
            updateHist(app);            

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: YshiftEditField
        function YshiftEditFieldValueChanged(app, event)
try

    app.AB1 = [];
            shift(app);            

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: XshiftEditField
        function XshiftEditFieldValueChanged(app, event)
try
    
    app.AB1 = [];
            shift(app);            
 
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Button pushed function: FindpairsButton
        function FindpairsButtonPushed(app, event)
try
            [app.ABpaired{1}, app.ABpaired{2}] = pairinglin(app.AB1{1}, app.AB1{2}, app.XYZpairingdistnmEditField.Value, app.MaxZpairingdistnmEditField.Value);
            app.Int = [app.ABpaired{1}(:,7),app.ABpaired{2}(:,7)];
            updateTable2(app);
            updateIntHist(app);
            updateIntHistUnmixint(app);
            Unmix(app);
            
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: binsizephotonsEditField
        function binsizephotonsEditFieldValueChanged(app, event)
try
    
            app.hist2.BinWidth = [app.binsizephotonsEditField.Value, app.binsizephotonsEditField.Value];

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
try
    
            %updateIntHist(app);

            app.hist2.XBinEdges = app.XmaxEditField_3.Value:app.binsizephotonsEditField.Value:app.XEditField.Value;
            xlim(app.UIAxes2, [app.XmaxEditField_3.Value, app.XEditField.Value]);
    
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end       
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
try
    
            %updateIntHist(app);  
            app.hist2.YBinEdges = app.XmaxEditField_4.Value:app.binsizephotonsEditField.Value:app.YEditField.Value;
            ylim(app.UIAxes2, [app.XmaxEditField_4.Value, app.YEditField.Value]);

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end    
        end

        % Callback function
        function binsEditField_2ValueChanged(app, event)
try
    
            updateIntHistUnmixint(app);            

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Callback function
        function XmaxEditField_2ValueChanged(app, event)
try
    
            updateIntHistUnmixint(app); 
    
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Callback function
        function YmaxEditField_2ValueChanged(app, event)
try
    
            updateIntHistUnmixint(app);            

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Callback function
        function UnmixButtonPushed(app, event)
try
    
            Unmix(app);            

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Callback function
        function OutputlistsButtonGroupSelectionChanged(app, event)
try
    
            Output(app);            

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Close request function: SplitViSuUIFigure
        function SplitViSuUIFigureCloseRequest(app, event)
try
    
            delete(app)            
    
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Selection changed function: DisplaydatafrominputlistButtonGroup
        function DisplaydatafrominputlistButtonGroupSelectionChanged(app, event)
try
    
            i = activeInputList(app);
            app.InputpointsEditField.Value = size(app.AB0{i},1);
            
            if ~isempty(app.AB0{i})
                app.h.Data = [app.AB0{i}(:,4), app.AB0{i}(:,5)];
            end
            %SRimage(app);                      

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end  
        end

        % Value changed function: MaxCount
        function MaxCountValueChanged(app, event)
try
    
            if app.MaxCount.Value <= app.MaxcountSlider.Limits(2) 
                app.MaxcountSlider.Value = app.MaxCount.Value;
            end
            caxis(app.UIAxes2_3, [0 app.MaxCount.Value]);             
 
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: PixelsizenmEditField
        function PixelsizenmEditFieldValueChanged(app, event)
try

            app.h.XBinEdges = min(app.AB0{1}(:,4)) : app.PixelsizenmEditField.Value : max(app.AB0{1}(:,4));
            app.h.YBinEdges = min(app.AB0{1}(:,5)) : app.PixelsizenmEditField.Value : max(app.AB0{1}(:,5));            
 
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changing function: MaxcountSlider
        function MaxcountSliderValueChanging(app, event)
try

            app.MaxCount.Value = double(uint16(event.Value));            
    
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: MaxcountSlider
        function MaxcountSliderValueChanged(app, event)
try
    
            app.MaxCount.Value = double(uint16(event.Value));  
            caxis(app.UIAxes2_3, [0 app.MaxCount.Value]);            
 
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
try
    
            if app.TabGroup.SelectedTab == app.AlignmentTab
                if app.InseparatefilesButton.Value
                    app.AB = app.AB0;                    
                elseif app.InonefileButton.Value
                    i = activeInputList(app);
                    app.AB{1} = app.AB0{i}(app.tfIA,:);
                    app.AB{2} = app.AB0{i}(app.tfIB,:); 
                end
                if app.InvertXBCheckBox.Value
                    app.AB{2}(:, 4) = app.XlengthEditField.Value - app.AB{2}(:, 4);
                end
                if app.InvertYBCheckBox.Value
                    app.AB{2}(:, 5) = app.YlengthEditField.Value - app.AB{2}(:, 5);
                end
                if app.PermutateXYCheckBox.Value
                    app.AB{2}(:, 4:5) = app.AB{2}(:, 5:-1:4);
                end
                    updateTable(app);
                    shift(app);

            end            
    
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Selection changed function: InputdataButtonGroup
        function InputdataButtonGroupSelectionChanged(app, event)
try
    if app.InseparatefilesButton.Value
        app.pushbutton13_2.Enable = true;
        app.edit4_2.Enable = true;
        app.DisplaydatafrominputlistButtonGroup.Enable = "on";
    elseif app.InonefileButton.Value
        app.pushbutton13_2.Enable = false;
        app.edit4_2.Enable = false;
        %app.AB0{2} = [];
        app.ISButton.Value = true;
        app.DisplaydatafrominputlistButtonGroup.Enable = "off";
    end
    if ~isempty(app.AB0)
        SRimage(app);         
    end          

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Button pushed function: SaveROIsButton
        function SaveROIsButtonPushed(app, event)
try
    
            for i = 1:size(app.roi,2)
            roiP{i} = app.roi{i}.Position; %#ok<ADPROPLC> %do not put app.roiP!
            end
            uisave({'roiP'}, [app.folder '/roi']);


            figure(app.SplitViSuUIFigure); %maximize the window;

            app.roiP = roiP;            %#ok<ADPROPLC> 

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Button pushed function: LoadROIsButton
        function LoadROIsButtonPushed(app, event)
try
    
            if ~isempty (app.folder)
                uiopen([app.folder '/roi.mat']);
            end

            figure(app.SplitViSuUIFigure); %maximize the window;


            if exist ('roiP','var')
                app.OutputchannelsDropDown.Value = num2str(size(roiP,2)); %#ok<ADPROPLC> %do not put app.roiP!
                app.roiP = roiP; %#ok<ADPROPLC> 
                updateIntHistUnmixint(app);
                UpdateTable3(app);
                Unmix(app);
                
            elseif exist ('roiAP','var')
                app.roiP{1} = roiAP;
                    app.OutputchannelsDropDown.Value = '1';
                if exist ('roiBP','var')
                    app.roiP{2} = roiBP;
                    app.OutputchannelsDropDown.Value = '2';
                end
                updateIntHistUnmixint(app);
                UpdateTable3(app);
                Unmix(app);
            end

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: XmaxEditField_3
        function XmaxEditField_3ValueChanged(app, event)
try
    
            %updateIntHist(app); 
            app.hist2.XBinEdges = app.XmaxEditField_3.Value:app.binsizephotonsEditField.Value:app.XEditField.Value;
            xlim(app.UIAxes2, [app.XmaxEditField_3.Value, app.XEditField.Value]);
    
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end        
        end

        % Value changed function: XmaxEditField_4
        function XmaxEditField_4ValueChanged(app, event)
try

            %updateIntHist(app);
            app.hist2.YBinEdges = app.XmaxEditField_4.Value:app.binsizephotonsEditField.Value:app.YEditField.Value;
            ylim(app.UIAxes2, [app.XmaxEditField_4.Value, app.YEditField.Value]);
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end           
        end

        % Value changed function: DatascaleDropDown
        function DatascaleDropDownValueChanged(app, event)
try
    
            %updateIntHist(app);
            if strcmp(app.DatascaleDropDown.Value, 'Linear')
                app.UIAxes2.XScale = 'linear';
                app.UIAxes2.YScale = 'linear';
                
            elseif strcmp(app.DatascaleDropDown.Value, 'Logarithmic')
                app.UIAxes2.XScale = 'log';
                app.UIAxes2.YScale = 'log';
                
            end

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end         
        end

        % Value changed function: binsizephotonsEditField_2
        function binsizephotonsEditField_2ValueChanged2(app, event)
try
    
            app.hist22.BinWidth = [app.binsizephotonsEditField_2.Value, app.binsizephotonsEditField_2.Value];       
            bringToFront(app.roi{end});
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: XEditField_2
        function XEditField_2ValueChanged(app, event)
try
            app.hist22.XBinEdges = app.XmaxEditField_5.Value:app.binsizephotonsEditField_2.Value:app.XEditField_2.Value;
            xlim(app.UIAxes2_2, [app.XmaxEditField_5.Value, app.XEditField_2.Value]);
            bringToFront(app.roi{end});

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: YEditField_2
        function YEditField_2ValueChanged(app, event)
try
            app.hist22.YBinEdges = app.XmaxEditField_6.Value:app.binsizephotonsEditField_2.Value:app.YEditField_2.Value;
            ylim(app.UIAxes2_2, [app.XmaxEditField_6.Value, app.YEditField_2.Value]);   
            bringToFront(app.roi{end});       

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: DatascaleDropDown_2
        function DatascaleDropDown_2ValueChanged(app, event)
try
    
            if strcmp(app.DatascaleDropDown_2.Value, 'Linear')
                app.UIAxes2_2.XScale = 'linear';
                app.UIAxes2_2.YScale = 'linear';
                
            elseif strcmp(app.DatascaleDropDown_2.Value, 'Logarithmic')
                app.UIAxes2_2.XScale = 'log';
                app.UIAxes2_2.YScale = 'log';
                
            end

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: XmaxEditField_5
        function XmaxEditField_5ValueChanged(app, event)
try
            app.hist22.XBinEdges = app.XmaxEditField_5.Value:app.binsizephotonsEditField_2.Value:app.XEditField_2.Value;
            xlim(app.UIAxes2_2, [app.XmaxEditField_5.Value, app.XEditField_2.Value]);    
            bringToFront(app.roi{end}); 
    
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: XmaxEditField_6
        function XmaxEditField_6ValueChanged(app, event)
try
    app.hist22.YBinEdges = app.XmaxEditField_6.Value:app.binsizephotonsEditField_2.Value:app.YEditField_2.Value;
    ylim(app.UIAxes2_2, [app.XmaxEditField_6.Value, app.YEditField_2.Value]);    
    bringToFront(app.roi{end});   

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: RatioIntAIntBEditField
        function RatioIntAIntBEditFieldValueChanged(app, event)
try

            updateIntHistUnmixint(app);            

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: LUTscaleDropDown_2
        function LUTscaleDropDown_2ValueChanged(app, event)
try
    
            if strcmp(app.LUTscaleDropDown_2.Value, 'Linear')
                app.UIAxes2_2.ColorScale = 'linear';
                
            elseif strcmp(app.LUTscaleDropDown_2.Value, 'Logarithmic')
                app.UIAxes2_2.ColorScale = 'log';
                
            end         

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: LUTscaleDropDown
        function LUTscaleDropDownValueChanged(app, event)
try
    
            %updateIntHist(app);
            if strcmp(app.LUTscaleDropDown.Value, 'Linear')
                app.UIAxes2.ColorScale = 'linear';
                
            elseif strcmp(app.LUTscaleDropDown.Value, 'Logarithmic')
                app.UIAxes2.ColorScale = 'log';
                
            end           
    
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Button pushed function: LoadROIsButton_2
        function LoadROIsButton_2Pushed(app, event)
try
    
    if ~isempty (app.folder)
            uiopen([app.folder '/roiCrop.mat']);
    end        

            
    figure(app.SplitViSuUIFigure); %maximize the window;


    if exist ('roiIA','var')
            app.posIA = roiIA;
    else
            app.posIA = [];
    end
                
    if exist ('roiIB','var')
            app.posIB = roiIB;
    else
            app.posIB = [];
                
    end

            SRimage(app);            
    
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Button pushed function: SaveROIsButton_2
        function SaveROIsButton_2Pushed(app, event)
try
    
            roiIA = app.roiIA.Position;
            roiIB = app.roiIB.Position;
            uisave({'roiIA', 'roiIB'}, [app.folder '/roiCrop']);      

            
            figure(app.SplitViSuUIFigure); %maximize the window;


catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: OutputchannelsDropDown
        function OutputchannelsDropDownValueChanged(app, event)
try
    
            updateIntHistUnmixint(app);
            Unmix(app);            
    
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
try
    f = waitbar(0,'Please wait...');

    app.AB1 = [];
    I = cell(2,1);
    %fov = max(FOV(app.AB{1}),FOV(app.AB{2}));
    for i = 1:2
        I{i} = drnew(app.AB{i}, 20, 52000);
        I{i}(I{i} > 10) = 10;
        I{i} = imgaussfilt(I{i}, 1);
    end
    waitbar(.25, f, 'Please wait...');
    output = dftregistration(fft2(I{1}), fft2(I{2}), 10);
    waitbar(1, f, 'Please wait...');
    app.XshiftEditField.Value = output(4) .* 20;
    app.YshiftEditField.Value = output(3) .* 20;
    

    edgesX = 0:20:max(app.AB{1}(:,4));
    edgesY = 0:20:max(app.AB{1}(:,5));
    edgesZ = -1200:20:1200;

    Ixz = cell(2,1);
    Iyz = cell(2,1);

    for i = 1:2
        Ixz{i} = histcounts2(app.AB{i}(:,6), app.AB{i}(:,4), edgesZ, edgesX);
        Iyz{i} = histcounts2(app.AB{i}(:,6), app.AB{i}(:,5), edgesZ, edgesY);
    end
    outputxz = dftregistration(fft2(Ixz{1}), fft2(Ixz{2}), 10);
    outputyz = dftregistration(fft2(Iyz{1}), fft2(Iyz{2}), 10);

    app.ZshiftEditField.Value = mean([outputxz(3) outputyz(3)]) .* 20;

    shift(app);
    
    close(f)
catch ME
errordlg(getReport(ME,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: OutputpointsDropDown
        function OutputpointsDropDownValueChanged(app, event)
try
    
            Output(app);            

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: OutputchannelDropDown
        function OutputchannelDropDownValueChanged(app, event)
   try

        Chromaplot(app);

   catch errorObj
       errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
   end
        end

        % Button pushed function: pushbutton13
        function pushbutton13ButtonPushed(app, event)
    try
            format = app.popupmenu21.Value;
                extension = {'*.*'};
            if strcmp(format,'Leica LAS') % set eventlist extension depending on format
                formn = 3;
                extension = {'*.ascii;*.bin'; '*.*'};            
            elseif strcmp(format,'QuickPALM') % set eventlist extension depending on format
                formn = 4;            
            elseif strcmp(format,'RapidSTORM') % set eventlist extension depending on format
                formn = 5;
            elseif strcmp(format,'µManager') % set eventlist extension depending on format
                formn = 6;
                extension = {'*.txt'; '*.*'};            
            elseif strcmp(format,'ThunderStorm') % set eventlist extension depending on format
                formn = 2;
                extension = {'*.csv'; '*.xls'; '*.txt'; '*.*'};             
            elseif strcmp(format,'ViSP') % set eventlist extension depending on format
                formn = 7;
            elseif strcmp(format,'SharpViSu') % set eventlist extension depending on format
                formn = 0;
                extension = {'*.ascii;*.mat'; '*.*'};
            elseif strcmp(format,'Zeiss ZEN') % set eventlist extension depending on format
                formn = 8;
                extension = {'*.txt'; '*.*'};   
            end
            if ~isempty(app.folder)
                pathname = app.folder;
                [filenameA, pathnameA] = uigetfile(extension, 'input eventlist', pathname);
            else
            [filenameA, pathnameA] = uigetfile(extension, 'input eventlist');
            end
            
            figure(app.SplitViSuUIFigure); %maximize the window;

            i = 1;
            if ~iscell (app.AB0)
                app.AB0 = cell(2,1);
            end

            if pathnameA ~= 0

                
            if all(filenameA(end-3:end) == '.bin')
                A = openLASbin (pathnameA, filenameA);
            elseif all(filenameA(end-3:end) == '.mat')
                load([pathnameA filenameA], 'A');
            elseif formn == 6  % µManager
                A = readtable([pathnameA filenameA],'DecimalSeparator',',');
                A = table2array(A);
            elseif formn == 2 %ThunderStorm
                A = readtable([pathnameA filenameA], 'FileType', 'delimitedtext');
                if ~ismember('z_nm_', A.Properties.VariableNames)
                    A = [zeros(size(A,1),1) A.frame-1 zeros(size(A,1),1) A.x_nm_ A.y_nm_ zeros(size(A,1),1) A.intensity_photon_ A.sigma_nm_ A.sigma_nm_];
                else
                    A = [zeros(size(A,1),1) A.frame-1 zeros(size(A,1),1) A.x_nm_ A.y_nm_ A.z_nm_ A.intensity_photon_ A.sigma1_nm_ A.sigma2_nm_];
                end
            else
                A = importdata([pathnameA filenameA]);
            end
            pfname = [pathnameA filenameA];

            app.edit4.Value = pfname;
            
            if isstruct(A)
                A = A.data;
            end
            
            if ~strcmp(format,'SharpViSu')
                A = adjustformat(A, formn);    % adjust the format to the standard
            end
            
            app.folder = pathnameA;

            app.AB0{i} = A;

            SRimage(app);
            app.InputpointsEditField.Value = size(app.AB0{i},1);

            else
                pfname = '';
                app.edit4.Value = pfname;
                app.AB0{i} = [];
            end
            
            %clear old data

            
    catch errorObj
        errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
    end
        end

        % Button pushed function: pushbutton13_2
        function pushbutton13_2ButtonPushed(app, event)
    try
            
            format = app.popupmenu21.Value;
                extension = {'*.*'};
            if strcmp(format,'Leica LAS') % set eventlist extension depending on format
                formn = 3;
                extension = {'*.ascii;*.bin'; '*.*'};            
            elseif strcmp(format,'QuickPALM') % set eventlist extension depending on format
                formn = 4;            
            elseif strcmp(format,'RapidSTORM') % set eventlist extension depending on format
                formn = 5;
            elseif strcmp(format,'µManager') % set eventlist extension depending on format
                formn = 6;
                extension = {'*.txt'; '*.*'};            
            elseif strcmp(format,'ThunderStorm') % set eventlist extension depending on format
                formn = 2;
                extension = {'*.csv'; '*.xls'; '*.txt'; '*.*'};            
            elseif strcmp(format,'ViSP') % set eventlist extension depending on format
                formn = 7;
            elseif strcmp(format,'SharpViSu') % set eventlist extension depending on format
                formn = 0;
                extension = {'*.ascii;*.mat'; '*.*'};
            elseif strcmp(format,'Zeiss ZEN') % set eventlist extension depending on format
                formn = 8;
                extension = {'*.txt'; '*.*'};  
            end
            if ~isempty(app.folder)
                pathname = app.folder;
                [filenameA, pathnameA] = uigetfile(extension, 'input eventlist', pathname);
            else
            [filenameA, pathnameA] = uigetfile(extension, 'input eventlist');
            end

            figure(app.SplitViSuUIFigure); %maximize the window;

            i = 2;
            if ~iscell (app.AB0)
                app.AB0 = cell(2,1);
            end

            if pathnameA ~= 0

                
            if all(filenameA(end-3:end) == '.bin')
                A = openLASbin (pathnameA, filenameA);
            elseif all(filenameA(end-3:end) == '.mat')
                load([pathnameA filenameA], 'A');
            elseif formn == 6  % µManager
                A = readtable([pathnameA filenameA],'DecimalSeparator',',');
                A = table2array(A);
            elseif formn == 2 %ThunderStorm
                A = readtable([pathnameA filenameA], 'FileType', 'delimitedtext');
                if ~ismember('z_nm_', A.Properties.VariableNames)
                    A = [zeros(size(A,1),1) A.frame-1 zeros(size(A,1),1) A.x_nm_ A.y_nm_ zeros(size(A,1),1) A.intensity_photon_ A.sigma_nm_ A.sigma_nm_];
                else
                    A = [zeros(size(A,1),1) A.frame-1 zeros(size(A,1),1) A.x_nm_ A.y_nm_ A.z_nm_ A.intensity_photon_ A.sigma1_nm_ A.sigma2_nm_];
                end
            else
                A = importdata([pathnameA filenameA]);
            end
            pfname = [pathnameA filenameA];

            app.edit4_2.Value = pfname;
            
            if isstruct(A)
                A = A.data;
            end
            
            if ~strcmp(format,'SharpViSu')
                A = adjustformat(A, formn);    % adjust the format to the standard
            end
            
            
            app.folder = pathnameA;

            app.AB0{i} = A;

            SRimage(app);
            app.InputpointsEditField.Value = size(app.AB0{i},1);

            else
                pfname = '';
                app.edit4_2.Value = pfname;
                app.AB0{i} = [];
            end
            
            %clear old data

            
    catch errorObj
        errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
    end
        end

        % Selection changed function: DpolynomialfitButtonGroup
        function DpolynomialfitButtonGroupSelectionChanged(app, event)
   try

        Chromaplot(app);

   catch errorObj
       errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
   end
        end

        % Button pushed function: pushbutton43
        function pushbutton43ButtonPushed(app, event)
            try
            if ~isempty(app.ABout{1})
                A = app.ABout{1};
                pathname = app.folder;
                [FileName,PathName,FilterIndex] = uiputfile( ...
                {'*.mat', 'SharpViSu (*.mat)';...
                '*.ascii', 'SharpViSu (*.ascii)';...
                 '*.2d', 'ViSP 2D File (*.2d)';...
                 '*.2dlp', 'ViSP 2D Localization Precision File (*.2dlp)';...
                 '*.3d', 'ViSP 3D File (*.3d)';...
                 '*.3dlp', 'ViSP 3D Localization Precision File (*.3dlp)'},...
                 'demixed eventlist #1', pathname);
                if FilterIndex ~= 0

                A = round(A,1);
                    FPName=[PathName FileName];
                    if FilterIndex == 1
                        save(FPName, 'A');
                    elseif FilterIndex == 2
                        dlmwrite(FPName, A, '\t');
                    else
                        SaveViSP( A, FilterIndex - 1, FPName );
                    end

            figure(app.SplitViSuUIFigure); %maximize the window;

                end
            end
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton42
        function pushbutton42ButtonPushed(app, event)
            try
            if ~isempty(app.ABout{2})
                A = app.ABout{2};
                pathname = app.folder;
                [FileName,PathName,FilterIndex] = uiputfile( ...
                {'*.mat', 'SharpViSu (*.mat)';...
                '*.ascii', 'SharpViSu (*.ascii)';...
                 '*.2d', 'ViSP 2D File (*.2d)';...
                 '*.2dlp', 'ViSP 2D Localization Precision File (*.2dlp)';...
                 '*.3d', 'ViSP 3D File (*.3d)';...
                 '*.3dlp', 'ViSP 3D Localization Precision File (*.3dlp)'},...
                 'demixed eventlist #1', pathname);
                if FilterIndex ~= 0
                A = round(A,1);
                    FPName=[PathName FileName];
                    if FilterIndex == 1
                        save(FPName, 'A');
                    elseif FilterIndex == 2
                        dlmwrite(FPName, A, '\t');
                    else
                        SaveViSP( A, FilterIndex - 1, FPName );
                    end

            figure(app.SplitViSuUIFigure); %maximize the window;

                end
            end
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton42_2
        function pushbutton42_2ButtonPushed(app, event)
            try
            if ~isempty(app.ABout{3})
                A = app.ABout{3};
                pathname = app.folder;
                [FileName,PathName,FilterIndex] = uiputfile( ...
                {'*.mat', 'SharpViSu (*.mat)';...
                '*.ascii', 'SharpViSu (*.ascii)';...
                 '*.2d', 'ViSP 2D File (*.2d)';...
                 '*.2dlp', 'ViSP 2D Localization Precision File (*.2dlp)';...
                 '*.3d', 'ViSP 3D File (*.3d)';...
                 '*.3dlp', 'ViSP 3D Localization Precision File (*.3dlp)'},...
                 'demixed eventlist #1', pathname);
                if FilterIndex ~= 0
                A = round(A,1);
                    FPName=[PathName FileName];
                    if FilterIndex == 1
                        save(FPName, 'A');
                    elseif FilterIndex == 2
                        dlmwrite(FPName, A, '\t');
                    else
                        SaveViSP( A, FilterIndex - 1, FPName );
                    end

            figure(app.SplitViSuUIFigure); %maximize the window;

                end
            end
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            try
                if app.XshiftEditField_3.Value < min(max(app.AB1{1}(:,2)), max(app.AB1{2}(:,2))) && app.XshiftEditField_3.Value < app.XshiftEditField_4.Value   

                    app.AB1t = app.AB1;
                    [app.AB1tofit{1}, app.AB1tofit{2}] = shiftXYtform(app.AB1{2}, app.AB1{1}, app.XshiftEditField_3.Value, app.XshiftEditField_4.Value, app.XshiftEditField_5.Value, app.XshiftEditField_6.Value);
                    PosShiftFit(app);

                    PosShiftCorrect(app);
                else
                    errordlg('No frames in this range!');
                end


            catch ME
                errordlg(getReport(ME,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: MaxCount_3
        function MaxCount_3ValueChanged(app, event)
try
    
            caxis(app.UIAxes2, [app.MaxCount_2.Value app.MaxCount_3.Value]);             
 
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: MaxCount_2
        function MaxCount_2ValueChanged(app, event)
try
    
            caxis(app.UIAxes2, [app.MaxCount_2.Value app.MaxCount_3.Value]);             
 
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: MaxCount_4
        function MaxCount_4ValueChanged(app, event)
try
    
            caxis(app.UIAxes2_2, [app.MaxCount_4.Value app.MaxCount_5.Value]);             
 
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: MaxCount_5
        function MaxCount_5ValueChanged(app, event)
try
    
            caxis(app.UIAxes2_2, [app.MaxCount_4.Value app.MaxCount_5.Value]);             
 
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: HistogramDropDown
        function HistogramDropDownValueChanged(app, event)
            try

                if strcmp (app.HistogramDropDown.Value, 'Univariate')
                    app.UnivariatehistogramPanel.Enable = 'on';
                    app.BivariatehistogramPanel_2.Enable = 'off';
                elseif strcmp (app.HistogramDropDown.Value, 'Bivariate')
                    app.UnivariatehistogramPanel.Enable = 'off';
                    app.BivariatehistogramPanel_2.Enable = 'on';
                end
                updateIntHist(app);
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: countscaleDropDown
        function countscaleDropDownValueChanged(app, event)
try
                if strcmp(app.countscaleDropDown.Value, 'Linear')
                    app.UIAxes2.YScale = 'linear';
                elseif strcmp(app.countscaleDropDown.Value, 'Logarithmic')
                    app.UIAxes2.YScale = 'log';
                end

                    ylim(app.UIAxes2, 'auto');
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: rDropDown
        function rDropDownValueChanged(app, event)
            try
                updateIntHist(app);
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: rbinsizeEditField
        function rbinsizeEditFieldValueChanged(app, event)
            try
                if strcmp(app.rscaleDropDown.Value, 'Linear')
                    Binedges = (app.XmaxEditField_7.Value:app.rbinsizeEditField.Value:app.rlimEditField.Value);
                elseif strcmp(app.rscaleDropDown.Value, 'Logarithmic')
                    Binedges = 10.^(log10(app.XmaxEditField_7.Value+eps):app.rbinsizeEditField.Value:log10(app.rlimEditField.Value));
                end
                app.hist2.BinEdges = Binedges;

                ylim(app.UIAxes2, 'auto');
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: rscaleDropDown
        function rscaleDropDownValueChanged(app, event)
            try

                if strcmp(app.rscaleDropDown.Value, 'Linear')
                    Binedges = (app.XmaxEditField_7.Value:app.rbinsizeEditField.Value:app.rlimEditField.Value);
                elseif strcmp(app.rscaleDropDown.Value, 'Logarithmic')
                    Binedges = 10.^(log10(app.XmaxEditField_7.Value):app.rbinsizeEditField.Value:log10(app.rlimEditField.Value));
                end
                app.hist2.BinEdges = Binedges;

                if strcmp(app.rscaleDropDown.Value, 'Linear')
                    app.UIAxes2.XScale = 'linear';
                app.UIAxes2.XTickMode = 'auto';
                elseif strcmp(app.rscaleDropDown.Value, 'Logarithmic')
                    app.UIAxes2.XScale = 'log';
                    app.UIAxes2.XTick = [0.1 0.14 0.2 0.3 0.5 0.7 1 1.4 2 3 4 5 6 8 10];
                end

                ylim(app.UIAxes2, 'auto');
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: XmaxEditField_7
        function XmaxEditField_7ValueChanged(app, event)
            try
                if strcmp(app.rscaleDropDown.Value, 'Linear')
                    Binedges = (app.XmaxEditField_7.Value:app.rbinsizeEditField.Value:app.rlimEditField.Value);
                elseif strcmp(app.rscaleDropDown.Value, 'Logarithmic')
                    Binedges = 10.^(log10(app.XmaxEditField_7.Value+eps):app.rbinsizeEditField.Value:log10(app.rlimEditField.Value));
                end

                app.hist2.BinEdges = Binedges;
                xlim(app.UIAxes2, [app.XmaxEditField_7.Value, app.rlimEditField.Value]);

                    ylim(app.UIAxes2, 'auto');
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: rlimEditField
        function rlimEditFieldValueChanged(app, event)
            try
                if strcmp(app.rscaleDropDown.Value, 'Linear')
                    Binedges = (app.XmaxEditField_7.Value:app.rbinsizeEditField.Value:app.rlimEditField.Value);
                elseif strcmp(app.rscaleDropDown.Value, 'Logarithmic')
                    Binedges = 10.^(log10(app.XmaxEditField_7.Value+eps):app.rbinsizeEditField.Value:log10(app.rlimEditField.Value));
                end
                
                app.hist2.BinEdges = Binedges;
                xlim(app.UIAxes2, [app.XmaxEditField_7.Value, app.rlimEditField.Value]);

                    ylim(app.UIAxes2, 'auto');
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            if strcmp(app.ColormapDropDown.Value, 'red hot')
                map = hot;
            elseif strcmp(app.ColormapDropDown.Value, 'inferno')
                map = inferno;
            elseif strcmp(app.ColormapDropDown.Value, 'gray')
                map = gray;
            elseif strcmp(app.ColormapDropDown.Value, 'fire')
                map = fire;
            end
            app.UIAxes2_3.Colormap = map;
        end

        % Value changed function: LUTDropDown
        function LUTDropDownValueChanged(app, event)
            if strcmp(app.LUTDropDown.Value, 'jet')
                app.UIAxes2.Colormap = jet;
            elseif strcmp(app.LUTDropDown.Value, 'turbo')
                app.UIAxes2.Colormap = turbo;
            elseif strcmp(app.LUTDropDown.Value, 'parula')
                app.UIAxes2.Colormap = parula;
            elseif strcmp(app.LUTDropDown.Value, 'fire')
                app.UIAxes2.Colormap = fire;
            elseif strcmp(app.LUTDropDown.Value, 'inferno')
                app.UIAxes2.Colormap = inferno;
            elseif strcmp(app.LUTDropDown.Value, 'hot')
                app.UIAxes2.Colormap = hot;
            elseif strcmp(app.LUTDropDown.Value, 'hsv')
                app.UIAxes2.Colormap = hsv;
            elseif strcmp(app.LUTDropDown.Value, 'gray')
                app.UIAxes2.Colormap = gray;
            elseif strcmp(app.LUTDropDown.Value, 'pink')
                app.UIAxes2.Colormap = pink;
            elseif strcmp(app.LUTDropDown.Value, 'copper')
                app.UIAxes2.Colormap = copper;
            elseif strcmp(app.LUTDropDown.Value, 'bone')
                app.UIAxes2.Colormap = bone;
            elseif strcmp(app.LUTDropDown.Value, 'winter')
                app.UIAxes2.Colormap = winter;
            elseif strcmp(app.LUTDropDown.Value, 'autumn')
                app.UIAxes2.Colormap = autumn;
            elseif strcmp(app.LUTDropDown.Value, 'summer')
                app.UIAxes2.Colormap = summer;
            elseif strcmp(app.LUTDropDown.Value, 'spring')
                app.UIAxes2.Colormap = spring;
            elseif strcmp(app.LUTDropDown.Value, 'cool')
                app.UIAxes2.Colormap = cool;
            elseif strcmp(app.LUTDropDown.Value, 'lines')
                app.UIAxes2.Colormap = lines;
            elseif strcmp(app.LUTDropDown.Value, 'colorcube')
                app.UIAxes2.Colormap = colorcube;
            elseif strcmp(app.LUTDropDown.Value, 'prism')
                app.UIAxes2.Colormap = prism;
            elseif strcmp(app.LUTDropDown.Value, 'flag')
                app.UIAxes2.Colormap = flag;
            end
        end

        % Value changed function: StepmEditField
        function StepmEditFieldValueChanged(app, event)
            PosShiftPlot(app);
        end

        % Value changed function: ArrowscaleEditField
        function ArrowscaleEditFieldValueChanged(app, event)
            PosShiftPlot(app);
        end

        % Value changed function: XYfitDropDown
        function XYfitDropDownValueChanged(app, event)
            PosShiftFit(app);
            PosShiftCorrect(app);
        end

        % Value changed function: PolynomialdegreeDropDown
        function PolynomialdegreeDropDownValueChanged(app, event)
            PosShiftFit(app);
            PosShiftCorrect(app);
        end

        % Value changed function: RatioIBIAEditField
        function RatioIBIAEditFieldValueChanged(app, event)
            try
                updateIntHist(app);
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: popupmenu21
        function popupmenu21ValueChanged(app, event)
            try
                format = app.popupmenu21.Value;
                if strcmp(format,'Leica LAS') % set eventlist extension depending on format
                    formn = 3;
                elseif strcmp(format,'QuickPALM') % set eventlist extension depending on format
                    formn = 4;
                elseif strcmp(format,'RapidSTORM') % set eventlist extension depending on format
                    formn = 5;
                elseif strcmp(format,'µManager') % set eventlist extension depending on format
                    formn = 6;
                elseif strcmp(format,'ThunderStorm') % set eventlist extension depending on format
                    formn = 2;
                elseif strcmp(format,'ViSP') % set eventlist extension depending on format
                    formn = 7;
                elseif strcmp(format,'SharpViSu') % set eventlist extension depending on format
                    formn = 0;
                elseif strcmp(format,'Zeiss ZEN') % set eventlist extension depending on format
                    formn = 8;
                end
                if app.edit4.Value ~= ""
                    [pathnameA, filenameA, ext] = fileparts(app.edit4.Value);
                    filenameA = [filenameA ext];
                    pathnameA = [pathnameA '\'];

                i = 1;


                if pathnameA ~= ""


                    if all(filenameA(end-3:end) == '.bin')
                        A = openLASbin (pathnameA, filenameA);
                    elseif all(filenameA(end-3:end) == '.mat')
                        load([pathnameA filenameA], 'A');
                    elseif formn == 6  % µManager
                        A = readtable([pathnameA filenameA],'DecimalSeparator',',');
                        A = table2array(A);
                    elseif formn == 2 %ThunderStorm
                        A = readtable([pathnameA filenameA], 'FileType', 'delimitedtext');
                        if ~ismember('z_nm_', A.Properties.VariableNames)
                            A = [zeros(size(A,1),1) A.frame-1 zeros(size(A,1),1) A.x_nm_ A.y_nm_ zeros(size(A,1),1) A.intensity_photon_ A.sigma_nm_ A.sigma_nm_];
                        else
                            A = [zeros(size(A,1),1) A.frame-1 zeros(size(A,1),1) A.x_nm_ A.y_nm_ A.z_nm_ A.intensity_photon_ A.sigma1_nm_ A.sigma2_nm_];
                        end
                    else
                        A = importdata([pathnameA filenameA]);
                    end


                    if isstruct(A)
                        A = A.data;
                    end

                    if ~strcmp(format,'SharpViSu')
                        A = adjustformat(A, formn);    % adjust the format to the standard
                    end

                    app.AB0{i} = A;

                    SRimage(app);
                    app.InputpointsEditField.Value = size(app.AB0{i},1);

                end

                end

                if app.InseparatefilesButton.Value

                    i = 2;
                    if app.edit4_2.Value ~= ""
                        [pathnameA, filenameA, ext] = fileparts(app.edit4_2.Value);
                        filenameA = [filenameA ext];
                        pathnameA = [pathnameA '\'];

                    if pathnameA ~= ""


                        if all(filenameA(end-3:end) == '.bin')
                            A = openLASbin (pathnameA, filenameA);
                        elseif all(filenameA(end-3:end) == '.mat')
                            load([pathnameA filenameA], 'A');
                        elseif formn == 6  % µManager
                            A = readtable([pathnameA filenameA],'DecimalSeparator',',');
                            A = table2array(A);
                        elseif formn == 2 %ThunderStorm
                            A = readtable([pathnameA filenameA], 'FileType', 'delimitedtext');
                            if ~ismember('z_nm_', A.Properties.VariableNames)
                                A = [zeros(size(A,1),1) A.frame-1 zeros(size(A,1),1) A.x_nm_ A.y_nm_ zeros(size(A,1),1) A.intensity_photon_ A.sigma_nm_ A.sigma_nm_];
                            else
                                A = [zeros(size(A,1),1) A.frame-1 zeros(size(A,1),1) A.x_nm_ A.y_nm_ A.z_nm_ A.intensity_photon_ A.sigma1_nm_ A.sigma2_nm_];
                            end
                        else
                            A = importdata([pathnameA filenameA]);
                        end


                        if isstruct(A)
                            A = A.data;
                        end

                        if ~strcmp(format,'SharpViSu')
                            A = adjustformat(A, formn);    % adjust the format to the standard
                        end

                        app.AB0{i} = A;

                        SRimage(app);
                        app.InputpointsEditField.Value = size(app.AB0{i},1);


                    end
                    end
                end

            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: SavetformButton
        function SavetformButtonPushed(app, event)
            try
                finetformIm = app.finetformIm;
                finetform = app.finetform;
                uisave({'finetform','finetformIm'}, [app.folder '/tform']);

            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: ZshiftEditField
        function ZshiftEditFieldValueChanged(app, event)
try

    app.AB1 = [];
            shift(app);            

catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: ZfitDropDown
        function ZfitDropDownValueChanged(app, event)
            PosShiftFit(app);
            PosShiftCorrect(app);
        end

        % Value changed function: PolynomialdegreeDropDown_2
        function PolynomialdegreeDropDown_2ValueChanged(app, event)
            PosShiftFit(app);
            PosShiftCorrect(app);
        end

        % Button pushed function: MergeButton
        function MergeButtonPushed(app, event)
try            
            [Anew, Bnew] = filtercons3Ddemix(app.ABpaired{1}, app.ABpaired{2}, [app.TgroupingXYZnmEditField.Value, app.TgroupingXYZnmEditField.Value, app.MaxZpairingdistnmEditField_2.Value], 0, app.AnisotropyCheckBox.Value);
            app.Int = [Anew(:,7), Bnew(:,7)];
            updateTable2(app);
            updateIntHist(app);
            updateIntHistUnmixint(app);
            Unmix(app);
            
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Value changed function: PolynomialdegreeDropDown_3
        function PolynomialdegreeDropDown_3ValueChanged(app, event)
try

             Chroma(app);       
catch errorObj
errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end    
        end

        % Value changed function: StepmEditField_2
        function StepmEditField_2ValueChanged(app, event)
   try

        Chromaplot(app);

   catch errorObj
       errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
   end
        end

        % Value changed function: ArrowscaleEditField_2
        function ArrowscaleEditField_2ValueChanged(app, event)
   try

        Chromaplot(app);

   catch errorObj
       errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
   end
        end

        % Value changed function: InvertXBCheckBox
        function InvertXBCheckBoxValueChanged(app, event)
            if app.InvertXBCheckBox.Value
                app.XlengthEditField.Enable = true;
            else
                app.XlengthEditField.Enable = false;
            end
        end

        % Value changed function: InvertYBCheckBox
        function InvertYBCheckBoxValueChanged(app, event)
            if app.InvertYBCheckBox.Value
                app.YlengthEditField.Enable = true;
            else
                app.YlengthEditField.Enable = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SplitViSuUIFigure = uifigure('Visible', 'off');
            app.SplitViSuUIFigure.Name = 'SplitViSu';
            app.SplitViSuUIFigure.CloseRequestFcn = createCallbackFcn(app, @SplitViSuUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.SplitViSuUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CropTab = uitab(app.TabGroup);
            app.CropTab.Title = 'Crop';
            app.GridLayout4 = uigridlayout(app.CropTab);
            app.UIAxes2_3 = uiaxes(app.GridLayout4);
            title(app.UIAxes2_3, 'SR image')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.Panel_4 = uipanel(app.GridLayout4);
            app.GridLayout2_3 = uigridlayout(app.Panel_4);
            app.UITable_4 = uitable(app.GridLayout2_3);
            app.UITable_4.ColumnName = {'A (left/green)'; 'B (right/red)'};
            app.UITable_4.RowName = {'Points'; '% of total'};
            app.InputpointsEditFieldLabel = uilabel(app.GridLayout2_3);
            app.InputpointsEditFieldLabel.Text = 'Input points';
            app.InputpointsEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.InputpointsEditField.Editable = 'off';
            app.DisplaydatafrominputlistButtonGroup = uibuttongroup(app.GridLayout2_3);
            app.DisplaydatafrominputlistButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplaydatafrominputlistButtonGroupSelectionChanged, true);
            app.DisplaydatafrominputlistButtonGroup.Title = 'Display data from input list';
            app.ISButton = uiradiobutton(app.DisplaydatafrominputlistButtonGroup);
            app.ISButton.Text = 'I "λS"';
            app.ISButton.Value = true;
            app.IILButton = uiradiobutton(app.DisplaydatafrominputlistButtonGroup);
            app.IILButton.Text = 'II "λL"';
            app.ImagePanel = uipanel(app.GridLayout2_3);
            app.ImagePanel.Title = 'Image';
            app.PixelsizenmEditField = uieditfield(app.ImagePanel, 'numeric');
            app.PixelsizenmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelsizenmEditFieldValueChanged, true);
            app.PixelsizenmEditField.Value = 20;
            app.PixelsizenmEditFieldLabel = uilabel(app.ImagePanel);
            app.PixelsizenmEditFieldLabel.Text = 'Pixel size, nm';
            app.ColormapDropDownLabel = uilabel(app.ImagePanel);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.ImagePanel);
            app.ColormapDropDown.Items = {'fire', 'red hot', 'inferno', 'gray'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'red hot';
            app.MaxcountSliderLabel = uilabel(app.ImagePanel);
            app.MaxcountSliderLabel.Text = 'Max. count';
            app.MaxcountSlider = uislider(app.ImagePanel);
            app.MaxcountSlider.Limits = [1 100];
            app.MaxcountSlider.ValueChangedFcn = createCallbackFcn(app, @MaxcountSliderValueChanged, true);
            app.MaxcountSlider.ValueChangingFcn = createCallbackFcn(app, @MaxcountSliderValueChanging, true);
            app.MaxcountSlider.Value = 30;
            app.MaxCount = uieditfield(app.ImagePanel, 'numeric');
            app.MaxCount.ValueChangedFcn = createCallbackFcn(app, @MaxCountValueChanged, true);
            app.MaxCount.Value = 30;
            app.InputdataButtonGroup = uibuttongroup(app.GridLayout2_3);
            app.InputdataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputdataButtonGroupSelectionChanged, true);
            app.InputdataButtonGroup.Title = 'Input data';
            app.InseparatefilesButton = uiradiobutton(app.InputdataButtonGroup);
            app.InseparatefilesButton.Text = 'In separate files';
            app.InonefileButton = uiradiobutton(app.InputdataButtonGroup);
            app.InonefileButton.Text = 'In one file';
            app.InonefileButton.Value = true;
            app.pushbutton13 = uibutton(app.InputdataButtonGroup, 'push');
            app.pushbutton13.ButtonPushedFcn = createCallbackFcn(app, @pushbutton13ButtonPushed, true);
            app.pushbutton13.Tag = 'pushbutton13';
            app.pushbutton13.Text = 'Browse...';
            app.edit4 = uieditfield(app.InputdataButtonGroup, 'text');
            app.edit4.Tag = 'edit4';
            app.pushbutton13_2 = uibutton(app.InputdataButtonGroup, 'push');
            app.pushbutton13_2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton13_2ButtonPushed, true);
            app.pushbutton13_2.Tag = 'pushbutton13';
            app.pushbutton13_2.Enable = 'off';
            app.pushbutton13_2.Text = 'Browse...';
            app.edit4_2 = uieditfield(app.InputdataButtonGroup, 'text');
            app.edit4_2.Tag = 'edit4';
            app.edit4_2.Enable = 'off';
            app.popupmenu21 = uidropdown(app.InputdataButtonGroup);
            app.popupmenu21.Items = {'SharpViSu', 'ThunderStorm', 'Leica LAS', 'QuickPALM', 'RapidSTORM', 'µManager', 'ViSP', 'Zeiss ZEN'};
            app.popupmenu21.ValueChangedFcn = createCallbackFcn(app, @popupmenu21ValueChanged, true);
            app.popupmenu21.Tag = 'popupmenu21';
            app.popupmenu21.Value = 'SharpViSu';
            app.text116 = uilabel(app.InputdataButtonGroup);
            app.text116.Tag = 'text116';
            app.text116.Text = 'Format:';
            app.LoadROIsButton_2 = uibutton(app.GridLayout2_3, 'push');
            app.LoadROIsButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadROIsButton_2Pushed, true);
            app.LoadROIsButton_2.Text = 'Load ROIs...';
            app.SaveROIsButton_2 = uibutton(app.GridLayout2_3, 'push');
            app.SaveROIsButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveROIsButton_2Pushed, true);
            app.SaveROIsButton_2.Text = 'Save ROIs...';
            app.InvertXBCheckBox = uicheckbox(app.GridLayout2_3);
            app.InvertXBCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertXBCheckBoxValueChanged, true);
            app.InvertXBCheckBox.Text = 'Invert X (B)';
            app.InvertXBCheckBox.Value = true;
            app.XlengthEditFieldLabel = uilabel(app.GridLayout2_3);
            app.XlengthEditFieldLabel.Text = 'X length';
            app.XlengthEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.XlengthEditField.Value = 29590;
            app.InvertYBCheckBox = uicheckbox(app.GridLayout2_3);
            app.InvertYBCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertYBCheckBoxValueChanged, true);
            app.InvertYBCheckBox.Text = 'Invert Y (B)';
            app.YlengthEditFieldLabel = uilabel(app.GridLayout2_3);
            app.YlengthEditFieldLabel.Text = 'Y length';
            app.YlengthEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.YlengthEditField.Enable = 'off';
            app.YlengthEditField.Value = 60313.6;
            app.PermutateXYCheckBox = uicheckbox(app.GridLayout2_3);
            app.PermutateXYCheckBox.Text = 'Permutate X-Y';
            app.AlignmentTab = uitab(app.TabGroup);
            app.AlignmentTab.Title = 'Alignment';
            app.GridLayout5 = uigridlayout(app.AlignmentTab);
            app.UIAxes = uiaxes(app.GridLayout5);
            title(app.UIAxes, 'X shift')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_6 = uiaxes(app.GridLayout5);
            title(app.UIAxes_6, 'Z shift')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout5);
            title(app.UIAxes_2, 'Y shift')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel = uipanel(app.GridLayout5);
            app.GridLayout = uigridlayout(app.Panel);
            app.CalculateButton = uibutton(app.GridLayout, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'A'; 'B'};
            app.UITable.RowName = {'Points'; 'Frames'};
            app.XshiftEditField = uieditfield(app.GridLayout, 'numeric');
            app.XshiftEditField.ValueChangedFcn = createCallbackFcn(app, @XshiftEditFieldValueChanged, true);
            app.XshiftEditFieldLabel = uilabel(app.GridLayout);
            app.XshiftEditFieldLabel.Text = 'X shift';
            app.YshiftEditField = uieditfield(app.GridLayout, 'numeric');
            app.YshiftEditField.ValueChangedFcn = createCallbackFcn(app, @YshiftEditFieldValueChanged, true);
            app.YshiftEditFieldLabel = uilabel(app.GridLayout);
            app.YshiftEditFieldLabel.Text = 'Y shift';
            app.nmLabel = uilabel(app.GridLayout);
            app.nmLabel.Text = 'nm';
            app.nmLabel_2 = uilabel(app.GridLayout);
            app.nmLabel_2.Text = 'nm';
            app.XshiftEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.XshiftEditField_2.Value = 10000;
            app.framesLabel = uilabel(app.GridLayout);
            app.framesLabel.Text = 'frames';
            app.HistogramPanel = uipanel(app.GridLayout);
            app.HistogramPanel.Title = 'Histogram';
            app.BinwidthnmEditFieldLabel = uilabel(app.HistogramPanel);
            app.BinwidthnmEditFieldLabel.Text = 'Bin width, nm';
            app.BinwidthnmEditField = uieditfield(app.HistogramPanel, 'numeric');
            app.BinwidthnmEditField.ValueChangedFcn = createCallbackFcn(app, @BinwidthnmEditFieldValueChanged, true);
            app.BinwidthnmEditField.Value = 2;
            app.MaxshiftnmEditField = uieditfield(app.HistogramPanel, 'numeric');
            app.MaxshiftnmEditField.ValueChangedFcn = createCallbackFcn(app, @MaxshiftnmEditFieldValueChanged, true);
            app.MaxshiftnmEditField.Value = 100;
            app.MaxshiftnmEditFieldLabel = uilabel(app.HistogramPanel);
            app.MaxshiftnmEditFieldLabel.Text = 'Max shift, nm';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'for first', 'for last'};
            app.DropDown.Value = 'for last';
            app.AutoButton = uibutton(app.GridLayout, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.YshiftEditFieldLabel_2 = uilabel(app.GridLayout);
            app.YshiftEditFieldLabel_2.Text = 'Check shift:';
            app.nmLabel_3 = uilabel(app.GridLayout);
            app.nmLabel_3.Text = 'nm';
            app.ZshiftEditField = uieditfield(app.GridLayout, 'numeric');
            app.ZshiftEditField.ValueChangedFcn = createCallbackFcn(app, @ZshiftEditFieldValueChanged, true);
            app.ZshiftEditFieldLabel = uilabel(app.GridLayout);
            app.ZshiftEditFieldLabel.Text = 'Z shift';
            app.FinealignmentTab = uitab(app.TabGroup);
            app.FinealignmentTab.Title = 'Fine alignment';
            app.GridLayout5_3 = uigridlayout(app.FinealignmentTab);
            app.UIAxes_5 = uiaxes(app.GridLayout5_3);
            title(app.UIAxes_5, 'X-Y shift')
            xlabel(app.UIAxes_5, 'X, nm')
            ylabel(app.UIAxes_5, 'Y, nm')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.YDir = 'reverse';
            app.Panel_6 = uipanel(app.GridLayout5_3);
            app.GridLayout_3 = uigridlayout(app.Panel_6);
            app.UITable_5 = uitable(app.GridLayout_3);
            app.UITable_5.ColumnName = {'A'; 'B'};
            app.UITable_5.RowName = {'Points'; 'Frames'};
            app.framerangeLabel = uilabel(app.GridLayout_3);
            app.framerangeLabel.Text = 'frame range:';
            app.XshiftEditField_3 = uieditfield(app.GridLayout_3, 'numeric');
            app.XshiftEditField_3.Value = 10000;
            app.XshiftEditField_4 = uieditfield(app.GridLayout_3, 'numeric');
            app.XshiftEditField_4.Value = 30000;
            app.CalculateButton_2 = uibutton(app.GridLayout_3, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.maxshiftnmLabel = uilabel(app.GridLayout_3);
            app.maxshiftnmLabel.Text = 'max shift, nm:';
            app.XshiftEditField_5 = uieditfield(app.GridLayout_3, 'numeric');
            app.XshiftEditField_5.Value = 300;
            app.Label = uilabel(app.GridLayout_3);
            app.Label.Text = '—';
            app.QuiverplotPanel = uipanel(app.GridLayout_3);
            app.QuiverplotPanel.Title = 'Quiver plot';
            app.StepmEditFieldLabel = uilabel(app.QuiverplotPanel);
            app.StepmEditFieldLabel.Text = 'Step, µm';
            app.StepmEditField = uieditfield(app.QuiverplotPanel, 'numeric');
            app.StepmEditField.ValueChangedFcn = createCallbackFcn(app, @StepmEditFieldValueChanged, true);
            app.StepmEditField.Value = 2;
            app.ArrowscaleEditField = uieditfield(app.QuiverplotPanel, 'numeric');
            app.ArrowscaleEditField.ValueChangedFcn = createCallbackFcn(app, @ArrowscaleEditFieldValueChanged, true);
            app.ArrowscaleEditField.Value = 10;
            app.ArrowscalexLabel = uilabel(app.QuiverplotPanel);
            app.ArrowscalexLabel.Text = 'Arrow scale, ×';
            app.XYfitDropDownLabel = uilabel(app.GridLayout_3);
            app.XYfitDropDownLabel.Text = 'XY fit';
            app.XYfitDropDown = uidropdown(app.GridLayout_3);
            app.XYfitDropDown.Items = {'polynomial', 'affine', 'nonreflectivesimilarity', 'projective', 'no fit'};
            app.XYfitDropDown.ValueChangedFcn = createCallbackFcn(app, @XYfitDropDownValueChanged, true);
            app.XYfitDropDown.Value = 'polynomial';
            app.PolynomialdegreeDropDownLabel = uilabel(app.GridLayout_3);
            app.PolynomialdegreeDropDownLabel.Text = 'Polynomial degree';
            app.PolynomialdegreeDropDown = uidropdown(app.GridLayout_3);
            app.PolynomialdegreeDropDown.Items = {'2', '3', '4'};
            app.PolynomialdegreeDropDown.ValueChangedFcn = createCallbackFcn(app, @PolynomialdegreeDropDownValueChanged, true);
            app.PolynomialdegreeDropDown.Value = '3';
            app.SavetformButton = uibutton(app.GridLayout_3, 'push');
            app.SavetformButton.ButtonPushedFcn = createCallbackFcn(app, @SavetformButtonPushed, true);
            app.SavetformButton.Text = 'Save tform';
            app.ZfitDropDownLabel = uilabel(app.GridLayout_3);
            app.ZfitDropDownLabel.Text = 'Z fit';
            app.ZfitDropDown = uidropdown(app.GridLayout_3);
            app.ZfitDropDown.Items = {'polynomial', 'no fit'};
            app.ZfitDropDown.ValueChangedFcn = createCallbackFcn(app, @ZfitDropDownValueChanged, true);
            app.ZfitDropDown.Value = 'polynomial';
            app.PolynomialdegreeDropDown_2Label = uilabel(app.GridLayout_3);
            app.PolynomialdegreeDropDown_2Label.Text = 'Polynomial degree';
            app.PolynomialdegreeDropDown_2 = uidropdown(app.GridLayout_3);
            app.PolynomialdegreeDropDown_2.Items = {'1', '2', '3', '4'};
            app.PolynomialdegreeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PolynomialdegreeDropDown_2ValueChanged, true);
            app.PolynomialdegreeDropDown_2.Value = '3';
            app.XshiftEditField_6 = uieditfield(app.GridLayout_3, 'numeric');
            app.XshiftEditField_6.Value = 300;
            app.ZfitDropDownLabel_2 = uilabel(app.GridLayout_3);
            app.ZfitDropDownLabel_2.Text = 'Z:';
            app.ZfitDropDownLabel_3 = uilabel(app.GridLayout_3);
            app.ZfitDropDownLabel_3.Text = 'XY:';
            app.XshiftEditField_7 = uieditfield(app.GridLayout_3, 'numeric');
            app.XshiftEditField_7.Value = 160;
            app.ZfitDropDownLabel_4 = uilabel(app.GridLayout_3);
            app.ZfitDropDownLabel_4.Text = 'pixel size, nm';
            app.PairingTab = uitab(app.TabGroup);
            app.PairingTab.Title = 'Pairing';
            app.GridLayout6 = uigridlayout(app.PairingTab);
            app.UIAxes2 = uiaxes(app.GridLayout6);
            title(app.UIAxes2, 'Photon counts')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Panel_2 = uipanel(app.GridLayout6);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.FindpairsButton = uibutton(app.GridLayout2, 'push');
            app.FindpairsButton.ButtonPushedFcn = createCallbackFcn(app, @FindpairsButtonPushed, true);
            app.FindpairsButton.Text = 'Find pairs';
            app.XYZpairingdistnmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.XYZpairingdistnmEditField.Tooltip = {'XY'};
            app.XYZpairingdistnmEditField.Value = 150;
            app.MaxpairingdistancenmLabel = uilabel(app.GridLayout2);
            app.MaxpairingdistancenmLabel.Text = 'XY/Z pairing dist., nm';
            app.PairedpointsEditField_2Label = uilabel(app.GridLayout2);
            app.PairedpointsEditField_2Label.Text = 'Paired points';
            app.PairedpointsEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.PairedpointsEditField_2.Editable = 'off';
            app.BivariatehistogramPanel_2 = uipanel(app.GridLayout2);
            app.BivariatehistogramPanel_2.Enable = 'off';
            app.BivariatehistogramPanel_2.Title = 'Bivariate histogram';
            app.XmaxEditField_3 = uieditfield(app.BivariatehistogramPanel_2, 'numeric');
            app.XmaxEditField_3.ValueChangedFcn = createCallbackFcn(app, @XmaxEditField_3ValueChanged, true);
            app.XmaxEditField_3.Tooltip = {'Xmin'};
            app.XmaxEditField_3.Value = 30;
            app.XmaxEditField_4 = uieditfield(app.BivariatehistogramPanel_2, 'numeric');
            app.XmaxEditField_4.ValueChangedFcn = createCallbackFcn(app, @XmaxEditField_4ValueChanged, true);
            app.XmaxEditField_4.Tooltip = {'Ymin'};
            app.XmaxEditField_4.Value = 30;
            app.MaxCount_2 = uieditfield(app.BivariatehistogramPanel_2, 'numeric');
            app.MaxCount_2.ValueChangedFcn = createCallbackFcn(app, @MaxCount_2ValueChanged, true);
            app.MaxCount_3 = uieditfield(app.BivariatehistogramPanel_2, 'numeric');
            app.MaxCount_3.ValueChangedFcn = createCallbackFcn(app, @MaxCount_3ValueChanged, true);
            app.LUTscaleDropDownLabel_2 = uilabel(app.BivariatehistogramPanel_2);
            app.LUTscaleDropDownLabel_2.Text = 'LUT lim';
            app.binsizephotonsEditField = uieditfield(app.BivariatehistogramPanel_2, 'numeric');
            app.binsizephotonsEditField.ValueChangedFcn = createCallbackFcn(app, @binsizephotonsEditFieldValueChanged, true);
            app.binsizephotonsEditField.Value = 20;
            app.binsizephotonsEditFieldLabel = uilabel(app.BivariatehistogramPanel_2);
            app.binsizephotonsEditFieldLabel.Text = 'bin size, photons';
            app.XEditField = uieditfield(app.BivariatehistogramPanel_2, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField.Tooltip = {'Xmax'};
            app.XEditField.Value = 20000;
            app.XEditFieldLabel = uilabel(app.BivariatehistogramPanel_2);
            app.XEditFieldLabel.Text = 'X';
            app.YEditField = uieditfield(app.BivariatehistogramPanel_2, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.YEditField.Tooltip = {'Ymax'};
            app.YEditField.Value = 20000;
            app.YEditFieldLabel = uilabel(app.BivariatehistogramPanel_2);
            app.YEditFieldLabel.Text = 'Y';
            app.DatascaleDropDownLabel = uilabel(app.BivariatehistogramPanel_2);
            app.DatascaleDropDownLabel.Text = 'Data scale';
            app.DatascaleDropDown = uidropdown(app.BivariatehistogramPanel_2);
            app.DatascaleDropDown.Items = {'Linear', 'Logarithmic'};
            app.DatascaleDropDown.ValueChangedFcn = createCallbackFcn(app, @DatascaleDropDownValueChanged, true);
            app.DatascaleDropDown.Value = 'Linear';
            app.LUTscaleDropDownLabel = uilabel(app.BivariatehistogramPanel_2);
            app.LUTscaleDropDownLabel.Text = 'LUT scale';
            app.LUTscaleDropDown = uidropdown(app.BivariatehistogramPanel_2);
            app.LUTscaleDropDown.Items = {'Linear', 'Logarithmic'};
            app.LUTscaleDropDown.ValueChangedFcn = createCallbackFcn(app, @LUTscaleDropDownValueChanged, true);
            app.LUTscaleDropDown.Value = 'Linear';
            app.LUTDropDownLabel = uilabel(app.BivariatehistogramPanel_2);
            app.LUTDropDownLabel.Text = 'LUT';
            app.LUTDropDown = uidropdown(app.BivariatehistogramPanel_2);
            app.LUTDropDown.Items = {'turbo', 'fire', 'hot', 'jet', 'inferno', 'hsv', 'gray', 'bone', 'pink', 'parula', 'copper', 'winter', 'autumn', 'summer', 'spring', 'cool', 'lines', 'colorcube', 'prism', 'flag'};
            app.LUTDropDown.ValueChangedFcn = createCallbackFcn(app, @LUTDropDownValueChanged, true);
            app.LUTDropDown.Value = 'turbo';
            app.UnivariatehistogramPanel = uipanel(app.GridLayout2);
            app.UnivariatehistogramPanel.Title = 'Univariate histogram';
            app.XmaxEditField_7 = uieditfield(app.UnivariatehistogramPanel, 'numeric');
            app.XmaxEditField_7.ValueChangedFcn = createCallbackFcn(app, @XmaxEditField_7ValueChanged, true);
            app.XmaxEditField_7.Tooltip = {'Xmin'};
            app.XmaxEditField_7.Value = 0.2;
            app.rbinsizeEditField = uieditfield(app.UnivariatehistogramPanel, 'numeric');
            app.rbinsizeEditField.ValueChangedFcn = createCallbackFcn(app, @rbinsizeEditFieldValueChanged, true);
            app.rbinsizeEditField.Value = 0.01;
            app.rbinsizeEditFieldLabel = uilabel(app.UnivariatehistogramPanel);
            app.rbinsizeEditFieldLabel.Text = 'r bin size';
            app.rlimEditField = uieditfield(app.UnivariatehistogramPanel, 'numeric');
            app.rlimEditField.ValueChangedFcn = createCallbackFcn(app, @rlimEditFieldValueChanged, true);
            app.rlimEditField.Tooltip = {'Xmax'};
            app.rlimEditField.Value = 6;
            app.rlimEditFieldLabel = uilabel(app.UnivariatehistogramPanel);
            app.rlimEditFieldLabel.Text = 'r lim';
            app.rscaleDropDownLabel = uilabel(app.UnivariatehistogramPanel);
            app.rscaleDropDownLabel.Text = 'r scale';
            app.rscaleDropDown = uidropdown(app.UnivariatehistogramPanel);
            app.rscaleDropDown.Items = {'Linear', 'Logarithmic'};
            app.rscaleDropDown.ValueChangedFcn = createCallbackFcn(app, @rscaleDropDownValueChanged, true);
            app.rscaleDropDown.Value = 'Logarithmic';
            app.countscaleDropDownLabel = uilabel(app.UnivariatehistogramPanel);
            app.countscaleDropDownLabel.Text = 'count scale';
            app.countscaleDropDown = uidropdown(app.UnivariatehistogramPanel);
            app.countscaleDropDown.Items = {'Linear', 'Logarithmic'};
            app.countscaleDropDown.ValueChangedFcn = createCallbackFcn(app, @countscaleDropDownValueChanged, true);
            app.countscaleDropDown.Value = 'Linear';
            app.rDropDownLabel = uilabel(app.UnivariatehistogramPanel);
            app.rDropDownLabel.Text = 'r = ';
            app.rDropDown = uidropdown(app.UnivariatehistogramPanel);
            app.rDropDown.Items = {'IB/IA', 'IA/IB'};
            app.rDropDown.ValueChangedFcn = createCallbackFcn(app, @rDropDownValueChanged, true);
            app.rDropDown.Value = 'IB/IA';
            app.HistogramDropDownLabel = uilabel(app.GridLayout2);
            app.HistogramDropDownLabel.Text = 'Histogram ';
            app.HistogramDropDown = uidropdown(app.GridLayout2);
            app.HistogramDropDown.Items = {'Univariate', 'Bivariate'};
            app.HistogramDropDown.ValueChangedFcn = createCallbackFcn(app, @HistogramDropDownValueChanged, true);
            app.HistogramDropDown.Value = 'Univariate';
            app.RatioIBIAEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RatioIBIAEditField.ValueChangedFcn = createCallbackFcn(app, @RatioIBIAEditFieldValueChanged, true);
            app.RatioIBIAEditField.Value = 1;
            app.RatioIBIAEditFieldLabel = uilabel(app.GridLayout2);
            app.RatioIBIAEditFieldLabel.Text = 'Ratio IB/IA';
            app.MaxZpairingdistnmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaxZpairingdistnmEditField.Tooltip = {'Z'};
            app.MaxZpairingdistnmEditField.Value = 400;
            app.TgroupingrXYZnmLabel = uilabel(app.GridLayout2);
            app.TgroupingrXYZnmLabel.Text = 'T grouping XY/Z, nm';
            app.TgroupingXYZnmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TgroupingXYZnmEditField.Tooltip = {'XY max dist'};
            app.TgroupingXYZnmEditField.Value = 50;
            app.MergeButton = uibutton(app.GridLayout2, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Text = 'Merge';
            app.MaxZpairingdistnmEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.MaxZpairingdistnmEditField_2.Tooltip = {'Z'};
            app.MaxZpairingdistnmEditField_2.Value = 400;
            app.AnisotropyCheckBox = uicheckbox(app.GridLayout2);
            app.AnisotropyCheckBox.Text = 'Anisotropy';
            app.AnisotropyCheckBox.Value = true;
            app.DemixingTab = uitab(app.TabGroup);
            app.DemixingTab.Title = 'Demixing';
            app.GridLayout7 = uigridlayout(app.DemixingTab);
            app.UIAxes2_2 = uiaxes(app.GridLayout7);
            title(app.UIAxes2_2, 'Photon counts')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.Panel_3 = uipanel(app.GridLayout7);
            app.GridLayout2_2 = uigridlayout(app.Panel_3);
            app.UITable_3 = uitable(app.GridLayout2_2);
            app.UITable_3.ColumnName = {'A demixed'; 'B demixed'};
            app.UITable_3.RowName = {'Points'; '% of paired'; '% of input'; 'Cross-talk, %'};
            app.RatioIntAIntBEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.RatioIntAIntBEditField.ValueChangedFcn = createCallbackFcn(app, @RatioIntAIntBEditFieldValueChanged, true);
            app.RatioIntAIntBEditField.Value = 1;
            app.RatioIntAIntBEditFieldLabel = uilabel(app.GridLayout2_2);
            app.RatioIntAIntBEditFieldLabel.Text = 'Ratio Int A/Int B';
            app.DemixedlocalizationsEditFieldLabel = uilabel(app.GridLayout2_2);
            app.DemixedlocalizationsEditFieldLabel.Text = 'Demixed localizations';
            app.DemixedlocalizationsEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.DemixedlocalizationsEditField.Editable = 'off';
            app.SaveROIsButton = uibutton(app.GridLayout2_2, 'push');
            app.SaveROIsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveROIsButtonPushed, true);
            app.SaveROIsButton.Text = 'Save ROIs...';
            app.LoadROIsButton = uibutton(app.GridLayout2_2, 'push');
            app.LoadROIsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadROIsButtonPushed, true);
            app.LoadROIsButton.Text = 'Load ROIs...';
            app.BivariatehistogramPanel_3 = uipanel(app.GridLayout2_2);
            app.BivariatehistogramPanel_3.Title = 'Bivariate histogram';
            app.binsizephotonsEditField_2 = uieditfield(app.BivariatehistogramPanel_3, 'numeric');
            app.binsizephotonsEditField_2.ValueChangedFcn = createCallbackFcn(app, @binsizephotonsEditField_2ValueChanged2, true);
            app.binsizephotonsEditField_2.Value = 20;
            app.binsizephotonsEditField_2Label = uilabel(app.BivariatehistogramPanel_3);
            app.binsizephotonsEditField_2Label.Text = 'bin size, photons';
            app.XEditField_2 = uieditfield(app.BivariatehistogramPanel_3, 'numeric');
            app.XEditField_2.ValueChangedFcn = createCallbackFcn(app, @XEditField_2ValueChanged, true);
            app.XEditField_2.Tooltip = {'Xmax'};
            app.XEditField_2.Value = 20000;
            app.XEditField_2Label = uilabel(app.BivariatehistogramPanel_3);
            app.XEditField_2Label.Text = 'X';
            app.YEditField_2 = uieditfield(app.BivariatehistogramPanel_3, 'numeric');
            app.YEditField_2.ValueChangedFcn = createCallbackFcn(app, @YEditField_2ValueChanged, true);
            app.YEditField_2.Tooltip = {'Ymax'};
            app.YEditField_2.Value = 20000;
            app.YEditField_2Label = uilabel(app.BivariatehistogramPanel_3);
            app.YEditField_2Label.Text = 'Y';
            app.XmaxEditField_5 = uieditfield(app.BivariatehistogramPanel_3, 'numeric');
            app.XmaxEditField_5.ValueChangedFcn = createCallbackFcn(app, @XmaxEditField_5ValueChanged, true);
            app.XmaxEditField_5.Tooltip = {'Xmin'};
            app.XmaxEditField_5.Value = 30;
            app.XmaxEditField_6 = uieditfield(app.BivariatehistogramPanel_3, 'numeric');
            app.XmaxEditField_6.ValueChangedFcn = createCallbackFcn(app, @XmaxEditField_6ValueChanged, true);
            app.XmaxEditField_6.Tooltip = {'Ymin'};
            app.XmaxEditField_6.Value = 30;
            app.DatascaleDropDown_2Label = uilabel(app.BivariatehistogramPanel_3);
            app.DatascaleDropDown_2Label.Text = 'Data scale';
            app.DatascaleDropDown_2 = uidropdown(app.BivariatehistogramPanel_3);
            app.DatascaleDropDown_2.Items = {'Linear', 'Logarithmic'};
            app.DatascaleDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DatascaleDropDown_2ValueChanged, true);
            app.DatascaleDropDown_2.Value = 'Linear';
            app.LUTscaleDropDown_2Label = uilabel(app.BivariatehistogramPanel_3);
            app.LUTscaleDropDown_2Label.Text = 'LUT scale';
            app.LUTscaleDropDown_2 = uidropdown(app.BivariatehistogramPanel_3);
            app.LUTscaleDropDown_2.Items = {'Linear', 'Logarithmic'};
            app.LUTscaleDropDown_2.ValueChangedFcn = createCallbackFcn(app, @LUTscaleDropDown_2ValueChanged, true);
            app.LUTscaleDropDown_2.Value = 'Linear';
            app.MaxCount_4 = uieditfield(app.BivariatehistogramPanel_3, 'numeric');
            app.MaxCount_4.ValueChangedFcn = createCallbackFcn(app, @MaxCount_4ValueChanged, true);
            app.MaxCount_5 = uieditfield(app.BivariatehistogramPanel_3, 'numeric');
            app.MaxCount_5.ValueChangedFcn = createCallbackFcn(app, @MaxCount_5ValueChanged, true);
            app.LUTscaleDropDownLabel_3 = uilabel(app.BivariatehistogramPanel_3);
            app.LUTscaleDropDownLabel_3.Text = 'LUT lim';
            app.OutputchannelsDropDownLabel = uilabel(app.GridLayout2_2);
            app.OutputchannelsDropDownLabel.Text = 'Output channels';
            app.OutputchannelsDropDown = uidropdown(app.GridLayout2_2);
            app.OutputchannelsDropDown.Items = {'1', '2', '3'};
            app.OutputchannelsDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputchannelsDropDownValueChanged, true);
            app.OutputchannelsDropDown.Value = '2';
            app.OutputpointsDropDownLabel = uilabel(app.GridLayout2_2);
            app.OutputpointsDropDownLabel.Text = 'Output points';
            app.OutputpointsDropDown = uidropdown(app.GridLayout2_2);
            app.OutputpointsDropDown.Items = {'All from input A', 'From brightest input', 'All from input B', 'Simple mean', 'Weighted mean', 'Wmean - chroma'};
            app.OutputpointsDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputpointsDropDownValueChanged, true);
            app.OutputpointsDropDown.Value = 'Wmean - chroma';
            app.ChromaticaberrationsTab = uitab(app.TabGroup);
            app.ChromaticaberrationsTab.Title = 'Chromatic aberrations';
            app.GridLayout5_2 = uigridlayout(app.ChromaticaberrationsTab);
            app.UIAxes_3 = uiaxes(app.GridLayout5_2);
            title(app.UIAxes_3, 'XY wmean - mean')
            xlabel(app.UIAxes_3, 'X, nm')
            ylabel(app.UIAxes_3, 'Y, nm')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.YDir = 'reverse';
            app.Panel_5 = uipanel(app.GridLayout5_2);
            app.GridLayout_2 = uigridlayout(app.Panel_5);
            app.DpolynomialfitButtonGroup = uibuttongroup(app.GridLayout_2);
            app.DpolynomialfitButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DpolynomialfitButtonGroupSelectionChanged, true);
            app.DpolynomialfitButtonGroup.Title = '2D polynomial fit';
            app.BeforecorrectionButton = uiradiobutton(app.DpolynomialfitButtonGroup);
            app.BeforecorrectionButton.Text = 'Before correction';
            app.BeforecorrectionButton.Value = true;
            app.AftercorrectionButton = uiradiobutton(app.DpolynomialfitButtonGroup);
            app.AftercorrectionButton.Text = 'After correction';
            app.OutputchannelDropDownLabel = uilabel(app.DpolynomialfitButtonGroup);
            app.OutputchannelDropDownLabel.Text = 'Output channel';
            app.OutputchannelDropDown = uidropdown(app.DpolynomialfitButtonGroup);
            app.OutputchannelDropDown.Items = {'1', '2', '3'};
            app.OutputchannelDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputchannelDropDownValueChanged, true);
            app.OutputchannelDropDown.Value = '1';
            app.PolynomialdegreeDropDown_3Label = uilabel(app.DpolynomialfitButtonGroup);
            app.PolynomialdegreeDropDown_3Label.Text = 'Polynomial degree';
            app.PolynomialdegreeDropDown_3 = uidropdown(app.DpolynomialfitButtonGroup);
            app.PolynomialdegreeDropDown_3.Items = {'2', '3', '4'};
            app.PolynomialdegreeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @PolynomialdegreeDropDown_3ValueChanged, true);
            app.PolynomialdegreeDropDown_3.Value = '3';
            app.SaveresultsPanel = uipanel(app.GridLayout_2);
            app.SaveresultsPanel.Title = 'Save results';
            app.pushbutton42 = uibutton(app.SaveresultsPanel, 'push');
            app.pushbutton42.ButtonPushedFcn = createCallbackFcn(app, @pushbutton42ButtonPushed, true);
            app.pushbutton42.Tag = 'pushbutton42';
            app.pushbutton42.Text = 'Flurophore II...';
            app.pushbutton43 = uibutton(app.SaveresultsPanel, 'push');
            app.pushbutton43.ButtonPushedFcn = createCallbackFcn(app, @pushbutton43ButtonPushed, true);
            app.pushbutton43.Tag = 'pushbutton43';
            app.pushbutton43.Text = 'Flurophore I...';
            app.pushbutton42_2 = uibutton(app.SaveresultsPanel, 'push');
            app.pushbutton42_2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton42_2ButtonPushed, true);
            app.pushbutton42_2.Tag = 'pushbutton42';
            app.pushbutton42_2.Text = 'Flurophore III...';
            app.QuiverplotPanel_2 = uipanel(app.GridLayout_2);
            app.QuiverplotPanel_2.Title = 'Quiver plot';
            app.StepmEditField_2Label = uilabel(app.QuiverplotPanel_2);
            app.StepmEditField_2Label.Text = 'Step, µm';
            app.StepmEditField_2 = uieditfield(app.QuiverplotPanel_2, 'numeric');
            app.StepmEditField_2.ValueChangedFcn = createCallbackFcn(app, @StepmEditField_2ValueChanged, true);
            app.StepmEditField_2.Value = 2;
            app.ArrowscaleEditField_2 = uieditfield(app.QuiverplotPanel_2, 'numeric');
            app.ArrowscaleEditField_2.ValueChangedFcn = createCallbackFcn(app, @ArrowscaleEditField_2ValueChanged, true);
            app.ArrowscaleEditField_2.Value = 100;
            app.ArrowscaleEditField_2Label = uilabel(app.QuiverplotPanel_2);
            app.ArrowscaleEditField_2Label.Text = 'Arrow scale, ×';
            % Show the figure after all components are created
            app.SplitViSuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SplitViSu(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SplitViSuUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SplitViSuUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SplitViSuUIFigure)
        end
    end
end