classdef contrastenh_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure         matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        Panel          matlab.ui.container.Panel
        GridLayout2    matlab.ui.container.GridLayout
        DstackPanel    matlab.ui.container.Panel
        text8_2        matlab.ui.control.Label
        edit5_2        matlab.ui.control.EditField
        pushbutton7_2  matlab.ui.control.Button
        EditField_3    matlab.ui.control.NumericEditField
        EditField_2    matlab.ui.control.NumericEditField
        EditField      matlab.ui.control.NumericEditField
        bitCheckBox    matlab.ui.control.CheckBox
        popupmenu3_2   matlab.ui.control.DropDown
        text6_2        matlab.ui.control.Label
        uipanel1       matlab.ui.container.ButtonGroup
        radiobutton3   matlab.ui.control.RadioButton
        radiobutton2   matlab.ui.control.RadioButton
        uipanel4       matlab.ui.container.Panel
        checkbox12     matlab.ui.control.CheckBox
        edit7          matlab.ui.control.EditField
        text13         matlab.ui.control.Label
        edit5          matlab.ui.control.EditField
        text9          matlab.ui.control.Label
        text8          matlab.ui.control.Label
        text12         matlab.ui.control.Label
        text11         matlab.ui.control.Label
        edit6          matlab.ui.control.EditField
        text10         matlab.ui.control.Label
        popupmenu5     matlab.ui.control.DropDown
        text7          matlab.ui.control.Label
        popupmenu4     matlab.ui.control.DropDown
        checkbox9      matlab.ui.control.CheckBox
        checkbox11     matlab.ui.control.CheckBox
        text5          matlab.ui.control.Label
        text3          matlab.ui.control.Label
        text6          matlab.ui.control.Label
        popupmenu2     matlab.ui.control.DropDown
        text4          matlab.ui.control.Label
        popupmenu3     matlab.ui.control.DropDown
        pushbutton7    matlab.ui.control.Button
        uipanel3       matlab.ui.container.Panel
        checkbox3      matlab.ui.control.CheckBox
        checkbox2      matlab.ui.control.CheckBox
        axes1          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        im % image object
    end
    
    methods (Access = private)
        function Reconstruct(app, hObject, handles)
            try
            pixsize = str2double(app.edit5.Value);
            mode = get(handles.popupmenu5, 'Value');
            capacity = str2double(get(handles.edit7, 'String'));
            % gaussmode = get(handles.radiobutton5, 'Value');
            % histmode = get(handles.radiobutton4, 'Value');
            draworig = get(handles.radiobutton3, 'Value');
            drawcorr = get(handles.radiobutton2, 'Value');
            sigma = str2double(get(handles.edit6, 'String'));
            sigm = handles.sigm;
            
            AB = handles.AB;
            ABcorr = handles.ABcorr;
            p0 = handles.pixsize0;
            p1 = handles.pixsize1;
            Voronoi = handles.Voronoi;
            Voronoi0 = handles.Voronoi0;
            
            fov = FOV(AB);
            zer = zeros(floor(fov/pixsize));
            Ic = cell(1,3);
            I0 = cell(1,3);
            if drawcorr
                for i = 1:3
                    if ~isempty(ABcorr{i})
                        if mode == 1 %hist
                            if p1(1) ~= pixsize
                            Ic{i} = drnew(ABcorr{i}, pixsize, fov);
                            end
                        elseif mode == 2 %gauss
                            if p1(2) ~= pixsize || sigm(1) ~= sigma
                                Ic{i} = gaussdraw(ABcorr{i}, pixsize, sigma, fov);
                            end
                        elseif mode == 3 %dens
                            if p1(3) ~= pixsize
                                if isempty(Voronoi{i,1})
                                     Voronoi(i,:) = VorArea(ABcorr{i});
                                end
                                Ic{i} = drawVor1(Voronoi{i,1}, Voronoi{i,4}, Voronoi{i,5}, pixsize, fov);
                            end
                        elseif mode == 4 %Quad-tree
                            if p1(4) ~= capacity
                                Ic{i} = QuadTree(ABcorr{i}, capacity);
                            end
                        elseif mode == 5 %Voronoi decomposition
                            if isempty(Voronoi{i,1})
                                 Voronoi(i,:) = VorArea(ABcorr{i});
                            end
                                 Ic{i} = Voronoi{i,1};
                        end
                    else
                        if any(mode == 1:3)
                            Ic{i} = zer;
                        else
                            Ic{i} = [];
                        end
                    end
                end
            elseif draworig
                for i = 1:3
                    if ~isempty(AB{i})
                        if mode == 1
                            if p0(1) ~= pixsize
                            I0{i} = drnew(AB{i}, pixsize, fov);
                            end
                        elseif mode == 2
                            if p0(2) ~= pixsize || sigm(2) ~= sigma
                                I0{i} = gaussdraw(AB{i}, pixsize, sigma, fov);
                            end
                        elseif mode == 3
                            if p0(3) ~= pixsize
                            if isempty(Voronoi0{i,1})
                                Voronoi0(i,:) = VorArea(AB{i});
                            end
                                I0{i} = drawVor1(Voronoi0{i,1}, Voronoi0{i,4}, Voronoi0{i,5}, pixsize, fov);
                            end
                        elseif mode == 4 %Quad-tree
                            if p0(4) ~= capacity
                                cla(handles.axes1);
                                I0{i} = QuadTree(AB{i}, capacity);
                            end
                        elseif mode == 5 %Voronoi decomposition
                            if isempty(Voronoi0{i,1})
                                 Voronoi0(i,:) = VorArea(AB{i});
                            end
                                 I0{i} = Voronoi0{i,1};
                        end
                    else
                        if any(mode == 1:3)
                            I0{i} = zer;
                        else
                            I0{i} = [];
                        end
                    end
                end
            end
            if mode == 2 && drawcorr
                if p1(2) ~= pixsize
                    handles.I1(:,2) = Ic;
                end
                    handles.pixsize1(2) = pixsize;
                    handles.sigm(1) = sigma;
            elseif mode == 1 && drawcorr
                if p1(1) ~= pixsize
                    handles.I1(:,1) = Ic;
                end
                    handles.pixsize1(1) = pixsize;
            elseif mode == 3 && drawcorr
                if p1(3) ~= pixsize
                    handles.I1(:,3) = Ic;
                end
                    handles.pixsize1(3) = pixsize;
            elseif mode == 4 && drawcorr
                if p1(4) ~= capacity
                    handles.I1(:,4) = Ic;
                end
                    handles.pixsize1(4) = capacity;
            elseif mode == 5 && drawcorr
                    handles.I1(:,5) = Ic;
            end
            if mode == 2 && draworig
                if p0(2) ~= pixsize
                    handles.I0(:,2) = I0;
                end
                    handles.pixsize0(2) = pixsize;
                    handles.sigm(2) = sigma;
            elseif mode == 1 && draworig
                if p0(1) ~= pixsize
                    handles.I0(:,1) = I0;
                end
                    handles.pixsize0(1) = pixsize;
            elseif mode == 3 && draworig
                if p0(3) ~= pixsize
                    handles.I0(:,3) = I0;
                end
                    handles.pixsize0(3) = pixsize;
            elseif mode == 4 && draworig
                if p0(4) ~= capacity
                    handles.I0(:,4) = I0;
                end
                    handles.pixsize0(4) = capacity;
            elseif mode == 5 && draworig
            %     if p0(4) ~= capacity
                    handles.I0(:,5) = I0;
            %     end
            %         handles.pixsize0(4) = capacity;
            end
            handles.Voronoi = Voronoi;
            handles.Voronoi0 = Voronoi0;
            guidata(hObject, handles);
            UpdateImage(app, hObject, handles);
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end
        
        function UpdateImage(app, hObject, handles)
            try
                if app.bitCheckBox.Value %16bit
                    bits = 65536;
                else %8bit
                    bits = 256;
                end
                
            monochr = get(handles.checkbox11, 'Value');
            if monochr
                app.popupmenu2.Enable = false;
                app.popupmenu3.Enable = false;
                app.popupmenu3_2.Enable = false;
            else
                app.popupmenu2.Enable = true;
                app.popupmenu3.Enable = true;
                app.popupmenu3_2.Enable = true;
            end
            
            norm = get(handles.checkbox9, 'Value');
            mult0(1) = app.EditField.Value;
            mult0(2) = app.EditField_2.Value;
            mult0(3) = app.EditField_3.Value;
            list = get(handles.radiobutton2, 'Value'); %1-corrected, 0-original;
            mode = get(handles.popupmenu5, 'Value'); %1-histogram, 2-gaussian, 3-density, 4-QuadTree;
            if list
                I1 = handles.I1(:,mode);
                Voronoi = handles.Voronoi;
            elseif ~list
                I1 = handles.I0(:,mode);
                Voronoi = handles.Voronoi0;
            end
            cla(handles.axes1);
            if ~isempty (I1{1}) || ~isempty (I1{2}) || ~isempty (I1{3})
            p = str2double(app.edit5.Value);
            show = get(handles.popupmenu4, 'Value');
            value = get(handles.popupmenu2, 'Value');
            stringlist = get(handles.popupmenu2, 'String');
            mapstr{1} = stringlist{value}; %colormap for red eventlist
            value = get(handles.popupmenu3, 'Value');
            stringlist = get(handles.popupmenu3, 'String');
            mapstr{2} = stringlist{value}; %colormap for green eventlist

            mapstr{3} = app.popupmenu3_2.Value; %colormap for blue eventlist
            
            mult = mult0;
            if norm
                for k = 1:3
                if ~isempty (I1{k}) && max(max(I1{k})) > 0
                    mult(k) = mult0(k) * (bits-1) / max(max(I1{k}));
                end
                end
            end
            
            I = cell(3,1);
            RGB0 = cell(3,1);
            RGB0sh = cell(3,1);
            map = cell(3,1);
            for i = 1 : 3
                I{i} = I1{i} * mult(i);
                I{i} = round(I{i});
              if ~monochr
                if strcmp(mapstr{i}, 'red')
                    map{i} = [(0:(bits-1))', zeros(bits,2)]/(bits-1);
                elseif strcmp(mapstr{i}, 'green-yellow-white')
                    map{i} = hot(bits);
                    map{i} = [map{i}(:,2), map{i}(:,1), map{i}(:,3)];
                elseif strcmp(mapstr{i}, 'blue-cyan-white')
                    map{i} = hot(bits);
                    map{i} = [map{i}(:,3), map{i}(:,2), map{i}(:,1)];
                elseif strcmp(mapstr{i}, 'magenta-white')
                    map{i} = [[0:2/(bits-1):1 ones(bits/2,1)']' [zeros(bits/2-1,1)' 0:2/bits:1]' [0:2/(bits-1):1 ones(bits/2,1)']'];
                elseif strcmp(mapstr{i}, 'yellow-white')
                    map{i} = [[0:2/(bits-1):1 ones(bits/2,1)']' [0:2/(bits-1):1 ones(bits/2,1)']' [zeros(bits/2-1,1)' 0:2/bits:1]'];
                elseif strcmp(mapstr{i}, 'cyan-white')
                    map{i} = [[zeros(bits/2-1,1)' 0:2/bits:1]' [0:2/(bits-1):1 ones(bits/2,1)']' [0:2/(bits-1):1 ones(bits/2,1)']'];
                elseif strcmp(mapstr{i}, 'green')
                    map{i} = [zeros(bits,1), (0:(bits-1))', zeros(bits,1)]/(bits-1);
                elseif strcmp(mapstr{i}, 'blue')
                    map{i} = [zeros(bits,2), (0:(bits-1))']/(bits-1);
                elseif strcmp(mapstr{i}, 'cyan')
                    map{i} = [zeros(bits,1), (0:(bits-1))', (0:(bits-1))']/(bits-1);
                elseif strcmp(mapstr{i}, 'magenta')
                    map{i} = [(0:(bits-1))', zeros(bits,1), (0:(bits-1))']/(bits-1);
                elseif strcmp(mapstr{i}, 'gray')
                    map{i} = gray(bits);
                elseif strcmp(mapstr{i}, 'red-yellow-white')
                    map{i} = hot(bits);
                elseif strcmp(mapstr{i}, 'hsv')
                    map{i} = hsv(bits);
                elseif strcmp(mapstr{i}, 'jet')
                    map{i} = jet(bits);
                elseif strcmp(mapstr{i}, 'parula')
                    map{i} = parula(bits);
                elseif strcmp(mapstr{i}, 'cool')
                    map{i} = cool(bits);
                elseif strcmp(mapstr{i}, 'spring')
                    map{i} = spring(bits);
                elseif strcmp(mapstr{i}, 'autumn')
                    map{i} = autumn(bits);
                elseif strcmp(mapstr{i}, 'summer')
                    map{i} = summer(bits);
                elseif strcmp(mapstr{i}, 'winter')
                    map{i} = winter(bits);
                elseif strcmp(mapstr{i}, 'bone')
                    map{i} = bone(bits);
                elseif strcmp(mapstr{i}, 'copper')
                    map{i} = copper(bits);
                elseif strcmp(mapstr{i}, 'pink')
                    map{i} = pink(bits);
                elseif strcmp(mapstr{i}, 'turbo')
                    map{i} = turbo(bits);
                elseif strcmp(mapstr{i}, 'inferno')
                    map{i} = inferno(bits);
                elseif strcmp(mapstr{i}, 'fire')
                    map{i} = fire(bits);
                end
                    RGB0{i} = ind2rgb(I{i}, map{i});
                    RGB0sh{i} = RGB0{i};

              else
                    map{i} = gray(bits);
                    RGB0sh{i} = ind2rgb(I{i}, map{i});
                    RGB0{i} = RGB0sh{i}(:,:,1);
               end
            end


    Xlim = app.axes1.XAxis.Limits;
    Ylim = app.axes1.YAxis.Limits;
            
        if mode ~= 5
            
            if ~isempty(RGB0{1}) && ~isempty(RGB0{2}) && ~isempty(RGB0{3})
                RGB = RGB0{1} + RGB0{2} + RGB0{3};
                RGBsh = RGB0sh{1} + RGB0sh{2} + RGB0sh{3};
            elseif ~isempty(RGB0{1}) && ~isempty(RGB0{2})
                RGB = RGB0{1} + RGB0{2};
                RGBsh = RGB0sh{1} + RGB0sh{2};
            elseif ~isempty(RGB0{1}) && ~isempty(RGB0{3})
                RGB = RGB0{1} + RGB0{3};
                RGBsh = RGB0sh{1} + RGB0sh{3};
            elseif ~isempty(RGB0{2}) && ~isempty(RGB0{3})
                RGB = RGB0{2} + RGB0{3};
                RGBsh = RGB0sh{2} + RGB0sh{3};
            elseif ~isempty(RGB0{1})
                RGB = RGB0{1};
                RGBsh = RGB0sh{1};
            elseif ~isempty(RGB0{2})
                RGB = RGB0{2};
                RGBsh = RGB0sh{2};
            else
                RGB = zeros(900,900,3);
                RGBsh = zeros(900,900,3);
            end
%             RGB(RGB > (bits-1)) = (bits-1);
%             RGBsh(RGBsh > (bits-1)) = (bits-1);

            RGB(RGB > 1) = 1;
            RGBsh(RGBsh > 1) = 1;

            if bits == 256
                RGB = uint8(255 * RGB);
                RGBsh = uint8(255 * RGBsh);
            elseif bits == 65536
                RGB = uint16(65535 * RGB);
                RGBsh = uint16(65535 * RGBsh);
            end
            iptsetpref('ImshowAxesVisible','on');

axes(app.axes1);
            if show ~= 4 %if not overlay
                imshow(I{show}, map{show}, 'Parent', app.axes1, 'XData', [0, size(I{show},2) * p * 0.001], 'YData', [0, size(I{show},1) * p * 0.001],'InitialMagnification', 'fit','Border','tight','Interpolation','nearest');
            %app.im = imshow(I{show}, map{show}, 'Parent', app.axes1, 'XData', [0, size(I{show},2) * p * 0.001], 'YData', [0, size(I{show},1) * p * 0.001],'InitialMagnification', 'fit','Border','tight','Interpolation','nearest');
                
            else
            imshow(RGBsh, 'Parent', app.axes1, 'XData', [0, size(I{1},2) * p * 0.001], 'YData', [0, size(I{1},1) * p * 0.001],'InitialMagnification', 'fit','Border','tight','Interpolation','nearest');
            %app.im = imshow(app.axes1, 'Cdata', RGBsh, 'XData', [0, size(I{1},2) * p * 0.001], 'YData', [0, size(I{1},1) * p * 0.001]);

            end
            app.axes1.Layer = 'top';
            app.axes1.XTickLabel = [];
            app.axes1.XTick = [];
            app.axes1.FontSize = 10;
            app.axes1.PlotBoxAspectRatioMode = 'manual';
            app.axes1.PlotBoxAspectRatio = [1 1 1];
            app.axes1.DataAspectRatioMode = 'manual';
            app.axes1.DataAspectRatio = [1 1 1];
            %app.axes1.XLimitMethod = 'tight';


        else            % show tessellations
                if show ~= 4
                RGB = RGB0{show};
                RGBsh = RGB0sh{show};
            
            
            C = Voronoi{show,3};
            V = Voronoi{show,2};
            A = Voronoi{show,4};
            hold on
                for i = 1:length(C)
            %        if all(C{i} ~= 1)
                        patch(V(C{i},1), V(C{i},2), RGBsh(i,:,:), 'Parent', handles.axes1);
            %        end
                end
            points = get(handles.checkbox12, 'Value');
            if points
                plot(app.axes1, A(:,4), A(:,5), '.w', 'MarkerSize', 1);
            end
            hold off
            %xlim([0 18000]);
            %ylim([0 18000]);
            set(app.axes1, 'YDir', 'reverse');
                end
        end
                   
    app.axes1.XAxis.Limits = Xlim;
    app.axes1.YAxis.Limits = Ylim;
    app.axes1.InnerPosition = [0.05 0.05 0.95 0.95];
    app.axes1.OuterPosition = [0 0 1 1];
    app.axes1.PositionConstraint = 'outerposition';
        
            if list
                handles.RGB1{mode} = RGB;
                handles.multiplier1{mode} = mult;
            elseif ~list
                handles.RGB0{mode} = RGB;
                handles.multiplier0{mode} = mult;
            end
            end
            guidata(hObject, handles);
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end
        
        function popupmenu6_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function pushbutton6_Callback(app, hObject, eventdata, handles)
            % hObject    handle to pushbutton6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Reconstruct(app, hObject, handles);
        end
        
        function uipanel2_SelectionChangeFcn(app, hObject, eventdata, handles)
            % hObject    handle to the selected object in uipanel2
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            gaussmode = get(handles.radiobutton5, 'Value');
            histmode = get(handles.radiobutton4, 'Value');
            draworig = get(handles.radiobutton3, 'Value');
            drawcorr = get(handles.radiobutton2, 'Value');
            p0 = handles.pixsize0;
            p1 = handles.pixsize1;
            if gaussmode && draworig && p0(2) ~= 0
                app.edit5.Value = num2str(p0(2));
            elseif gaussmode && drawcorr && p1(2) ~= 0
                app.edit5.Value = num2str(p1(2));
            elseif histmode && draworig && p0(1) ~= 0
                app.edit5.Value = num2str(p0(1));
            elseif histmode && drawcorr && p1(1) ~= 0
                app.edit5.Value = num2str(p1(1));
            end
            guidata(hObject, handles);
            Reconstruct(app, hObject, handles);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function contrastenh_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
	allAxes = findall(app.figure, 'Type', 'axes');
	arrayfun(@(ax) disableDefaultInteractivity(ax), allAxes)      
	oncleanup = onCleanup(@() arrayfun(@(ax) enableDefaultInteractivity(ax), allAxes));

            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to contrastenh (see VARARGIN)
            
            % Choose default command line output for contrastenh
            handles.output = hObject;
            handles.AB = varargin{1};
            handles.ABcorr = varargin{2};
            handles.folder = varargin{3};
            handles.I1 = cell(3,5); % [redhist, redgauss, reddens; greenhist, greengauss, greendens; bluehist, bluegauss, bluedens]
            handles.I0 = cell(3,5); % [redhist, redgauss, reddens; greenhist, greengauss, greendens; bluehist, bluegauss, bluedens]
            handles.RGB1 = cell(5,1); % [hist, gauss, dens]
            handles.RGB0 = cell(5,1); % [hist, gauss, dens]
            handles.multiplier1 = cell(5,1); % [hist, gauss, dens]
            handles.multiplier0 = cell(5,1); % [hist, gauss, dens]
            handles.pixsize1 = zeros(5,1);
            handles.pixsize0 = zeros(5,1);
            handles.sigm = zeros(2,1);
            handles.Voronoi = cell(3,5);
            handles.Voronoi0 = cell(3,5);
            % This sets up the initial plot - only do when we are invisible
            % so window can get raised using contrastenh.
            % Update handles structure
            guidata(hObject, handles);
            Reconstruct(app, hObject, handles);
        end

        % Value changed function: checkbox11
        function checkbox11_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox11 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            UpdateImage(app, hObject, handles);
        end

        % Value changed function: checkbox12
        function checkbox12_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox12 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Reconstruct(app, hObject, handles);
        end

        % Value changed function: checkbox9
        function checkbox9_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            UpdateImage(app, hObject, handles);
        end

        % Callback function
        function edit3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            UpdateImage(app, hObject, handles);
        end

        % Callback function
        function edit4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            UpdateImage(app, hObject, handles);
        end

        % Value changed function: edit5
        function edit5_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Reconstruct(app, hObject, handles);
        end

        % Value changed function: edit6
        function edit6_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Reconstruct(app, hObject, handles);
        end

        % Value changed function: edit7
        function edit7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Reconstruct(app, hObject, handles);
        end

        % Close request function: figure
        function figure_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            % if isequal(get(hObject, 'waitstatus'), 'waiting')
            %     % The GUI is still in UIWAIT, use UIRESUME
            %     uiresume(hObject);
            % else
                % The GUI is no longer waiting, just close it
            % end
            RGB1 = handles.RGB1; % cell{hist, gauss}
            RGB0 = handles.RGB0; % cell{hist, gauss}
            multiplier1 = handles.multiplier1; % cell{hist, gauss}
            multiplier0 = handles.multiplier0; % cell{hist, gauss}
            saveorig = get(handles.checkbox3, 'Value'); %auto save original image
            savecorr = get(handles.checkbox2, 'Value'); %auto save corrected image
            pathname = handles.folder;
            
            % auto save images in the current folder
            if saveorig
                if ~isempty(RGB0{1}) %hist
                filename = ['0h_' num2str(multiplier0{1}(1)) '_' num2str(multiplier0{1}(2)) '.tif'];
                FPName = [pathname filename];
                imwrite(RGB0{1}, FPName, 'Compression', 'lzw');
                end
                if ~isempty(RGB0{2}) %gauss
                filename = ['0g_' num2str(multiplier0{2}(1)) '_' num2str(multiplier0{2}(2)) '.tif'];
                FPName = [pathname filename];
                imwrite(RGB0{2}, FPName, 'Compression', 'lzw');
                end
                if ~isempty(RGB0{3}) %dens
                filename = ['0v_' num2str(multiplier0{3}(1)) '_' num2str(multiplier0{3}(2)) '.tif'];
                FPName = [pathname filename];
                imwrite(RGB0{3}, FPName, 'Compression', 'lzw');
                end
                if ~isempty(RGB0{4}) %Qtree
                filename = ['0q_' num2str(multiplier0{4}(1)) '_' num2str(multiplier0{4}(2)) '.tif'];
                FPName = [pathname filename];
                imwrite(RGB0{4}, FPName, 'Compression', 'lzw');
                end
            end
            if savecorr
                if ~isempty(RGB1{1}) %hist
                filename = ['CorrH_' num2str(multiplier1{1}(1)) '_' num2str(multiplier1{1}(2)) '.tif'];
                FPName = [pathname filename];
                imwrite(RGB1{1}, FPName, 'Compression', 'lzw');
                end
                if ~isempty(RGB1{2}) %gauss
                filename = ['CorrG_' num2str(multiplier1{2}(1)) '_' num2str(multiplier1{2}(2)) '.tif'];
                FPName = [pathname filename];
                imwrite(RGB1{2}, FPName, 'Compression', 'lzw');
                end
                if ~isempty(RGB1{3}) %dens
                filename = ['CorrV_' num2str(multiplier1{3}(1)) '_' num2str(multiplier1{3}(2)) '.tif'];
                FPName = [pathname filename];
                imwrite(RGB1{3}, FPName, 'Compression', 'lzw');
                end
                if ~isempty(RGB1{4}) %Q-Tree
                filename = ['CorrQ_' num2str(multiplier1{4}(1)) '_' num2str(multiplier1{4}(2)) '.tif'];
                FPName = [pathname filename];
                imwrite(RGB1{4}, FPName, 'Compression', 'lzw');
                end
            end
            
            delete(hObject);
        end

        % Value changed function: popupmenu2
        function popupmenu2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            UpdateImage(app, hObject, handles);
        end

        % Value changed function: popupmenu3
        function popupmenu3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            UpdateImage(app, hObject, handles);
        end

        % Value changed function: popupmenu4
        function popupmenu4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            UpdateImage(app, hObject, handles);
        end

        % Value changed function: popupmenu5
        function popupmenu5_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            mode = get(handles.popupmenu5, 'Value');
            draworig = get(handles.radiobutton3, 'Value');
            drawcorr = get(handles.radiobutton2, 'Value');
            p0 = handles.pixsize0;
            p1 = handles.pixsize1;
            % if mode == 2
            %     set(handles.edit6, 'Enable', 'on');
            % else
            %     set(handles.edit6, 'Enable', 'off');
            % end
            if mode == 2 && draworig && p0(2) ~= 0 %gauss
                app.edit5.Value = num2str(p0(2));
            elseif mode == 2 && drawcorr && p1(2) ~= 0 %gauss
                app.edit5.Value = num2str(p1(2));
            elseif mode == 1 && draworig && p0(1) ~= 0 %hist
                app.edit5.Value = num2str(p0(1));
            elseif mode == 1 && drawcorr && p1(1) ~= 0 %hist
                app.edit5.Value = num2str(p1(1));
            elseif mode == 3 && draworig && p0(3) ~= 0 %dens
                app.edit5.Value = num2str(p0(3));
            elseif mode == 3 && drawcorr && p1(3) ~= 0 %dens
                app.edit5.Value = num2str(p1(3));
            elseif mode == 4 && draworig && p0(4) ~= 0 %quadtree (capacity)
                set(handles.edit7, 'String', num2str(p0(4)));
            elseif mode == 4 && drawcorr && p1(4) ~= 0 %quadtree (capacity)
                set(handles.edit7, 'String', num2str(p1(4)));
            end
            if mode == 5 %tessellations
                set(handles.checkbox12, 'Enable', 'on');
            else
                set(handles.checkbox12, 'Enable', 'off');
            end
            guidata(hObject, handles);
            Reconstruct(app, hObject, handles);
        end

        % Button pushed function: pushbutton7
        function pushbutton7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            drawcorr = get(handles.radiobutton2, 'Value');
            mode = get(handles.popupmenu5, 'Value');
            if drawcorr
                RGB = handles.RGB1{mode};
            elseif ~drawcorr
                RGB = handles.RGB0{mode};
            end
            if ~isempty(RGB)
            pathname = handles.folder;
            [FileName,PathName,indx] = uiputfile({'*.tif';'*.png';'*.*'}, 'Save current image as', pathname);
            if FileName ~= 0
            FPName=[PathName FileName];
            if indx == 1 %tif
            imwrite(RGB, FPName, 'Compression', 'lzw');
            elseif indx == 2 %png
            imwrite(RGB, FPName);
            elseif indx == 3 %other
            errordlg('Specify a supported file format','File Format Error');
            end
            end
            end
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Selection changed function: uipanel1
        function uipanel1_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in uipanel1
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            
            mode = get(handles.popupmenu5, 'Value');
            % gaussmode = get(handles.radiobutton5, 'Value');
            % histmode = get(handles.radiobutton4, 'Value');
            draworig = get(handles.radiobutton3, 'Value');
            drawcorr = get(handles.radiobutton2, 'Value');
            p0 = handles.pixsize0;
            p1 = handles.pixsize1;
            if draworig && p0(mode) ~= 0
                app.edit5.Value = num2str(p0(mode));
            elseif drawcorr && p1(mode) ~= 0
                app.edit5.Value = num2str(p1(mode));
            end
            guidata(hObject, handles);
            Reconstruct(app, hObject, handles);
        end

        % Value changed function: popupmenu3_2
        function popupmenu3_2ValueChanged(app, event)
        % Create GUIDE-style callback args
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            UpdateImage(app, hObject, handles);
        end

        % Callback function
        function edit4_2ValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            UpdateImage(app, hObject, handles);
        end

        % Value changed function: bitCheckBox
        function bitCheckBoxValueChanged(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            
            if ~app.checkbox9.Value
                if app.bitCheckBox.Value
                    app.EditField.Value = app.EditField.Value * 257;
                    app.EditField_2.Value = app.EditField_2.Value * 257;
                    app.EditField_3.Value = app.EditField_3.Value * 257;
                else
                    app.EditField.Value = app.EditField.Value / 257;
                    app.EditField_2.Value = app.EditField_2.Value / 257;
                    app.EditField_3.Value = app.EditField_3.Value / 257;
                end
            end
            
            UpdateImage(app, hObject, handles);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            UpdateImage(app, hObject, handles);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            UpdateImage(app, hObject, handles);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            UpdateImage(app, hObject, handles);
        end

        % Button pushed function: pushbutton7_2
        function pushbutton7_2ButtonPushed(app, event)
        try
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            AB = handles.ABcorr;
            fov = FOV(AB{1});
            c = 0;
            Image = {[]; []; []};
            for i = 1:3
                if ~isempty(AB{i})
                    Im = slices( AB{i}, str2double(app.edit5_2.Value), str2double(app.edit5.Value), fov);
                    for j = 1 : size(Im, 3)
                        Image{i} = cat(4, Image{i}, Im(:,:,j));
                    end
                    c = c + 1;
                else
                    emp = i;
                end
            end

            if c == 1
                RGB = uint8(Image{1});
            elseif c == 2
                RGB = cat(3, uint8(Image{1}), uint8(Image{5 - emp}));
            elseif c == 3
                RGB = cat(3, uint8(Image{1}), uint8(Image{2}), uint8(Image{3}));
            end
            % save 
            pathname = handles.folder;
            [FileName,PathName,indx] = uiputfile({'*.tif';'*.*'}, 'Save current image as', pathname);
            if FileName ~= 0
                FPName=[PathName FileName];
                if indx == 1 %tif
                    ImageJtiff(RGB, FPName);
                elseif indx == 2 %other
                    errordlg('Tiff format only!','File Format Error');
                end
            end
        catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure = uifigure('Visible', 'off');
            colormap(app.figure, 'parula');
            app.figure.Name = 'Super-resolution image reconstruction';
            app.figure.CloseRequestFcn = createCallbackFcn(app, @figure_CloseRequestFcn, true);
            app.figure.Tag = 'figure';
            app.GridLayout = uigridlayout(app.figure);
            app.axes1 = uiaxes(app.GridLayout);
            app.axes1.XLim = [0 25];
            app.axes1.YLim = [0 18];
            app.axes1.YDir = 'reverse';
            app.axes1.XTick = [];
            app.axes1.XTickLabel = '';
            app.axes1.YTick = [];
            app.axes1.Tag = 'axes1';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.uipanel3 = uipanel(app.GridLayout2);
            app.uipanel3.Title = 'Auto save in current folder';
            app.uipanel3.Tag = 'uipanel3';
            app.checkbox2 = uicheckbox(app.uipanel3);
            app.checkbox2.Tag = 'checkbox2';
            app.checkbox2.Text = 'Corrected';
            app.checkbox3 = uicheckbox(app.uipanel3);
            app.checkbox3.Tag = 'checkbox3';
            app.checkbox3.Text = 'Original images';
            app.pushbutton7 = uibutton(app.GridLayout2, 'push');
            app.pushbutton7.ButtonPushedFcn = createCallbackFcn(app, @pushbutton7_Callback, true);
            app.pushbutton7.Tag = 'pushbutton7';
            app.pushbutton7.Text = 'Save image';
            app.popupmenu3 = uidropdown(app.GridLayout2);
            app.popupmenu3.Items = {'green', 'green-yellow-white', 'fire', 'red-yellow-white', 'blue-cyan-white', 'magenta-white', 'yellow-white', 'cyan-white', 'cyan', 'gray', 'magenta', 'red', 'blue', 'hsv', 'jet', 'cool', 'spring', 'summer', 'autumn', 'winter', 'bone', 'copper', 'pink', 'turbo', 'parula', 'inferno'};
            app.popupmenu3.ValueChangedFcn = createCallbackFcn(app, @popupmenu3_Callback, true);
            app.popupmenu3.Tag = 'popupmenu3';
            app.popupmenu3.Value = 'green';
            app.text4 = uilabel(app.GridLayout2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'colormap';
            app.popupmenu2 = uidropdown(app.GridLayout2);
            app.popupmenu2.Items = {'red', 'red-yellow-white', 'fire', 'green-yellow-white', 'blue-cyan-white', 'magenta-white', 'yellow-white', 'cyan-white', 'magenta', 'gray', 'blue', 'green', 'hsv', 'jet', 'cool', 'spring', 'summer', 'autumn', 'winter', 'bone', 'copper', 'cyan', 'pink', 'turbo', 'parula', 'inferno'};
            app.popupmenu2.ValueChangedFcn = createCallbackFcn(app, @popupmenu2_Callback, true);
            app.popupmenu2.Tag = 'popupmenu2';
            app.popupmenu2.Value = 'red';
            app.text6 = uilabel(app.GridLayout2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Green';
            app.text3 = uilabel(app.GridLayout2);
            app.text3.Tag = 'text3';
            app.text3.Text = 'brightness';
            app.text5 = uilabel(app.GridLayout2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Red';
            app.checkbox11 = uicheckbox(app.GridLayout2);
            app.checkbox11.ValueChangedFcn = createCallbackFcn(app, @checkbox11_Callback, true);
            app.checkbox11.Tag = 'checkbox11';
            app.checkbox11.Text = 'Monochrome';
            app.checkbox9 = uicheckbox(app.GridLayout2);
            app.checkbox9.ValueChangedFcn = createCallbackFcn(app, @checkbox9_Callback, true);
            app.checkbox9.Tag = 'checkbox9';
            app.checkbox9.Text = 'Normalize on maximum';
            app.popupmenu4 = uidropdown(app.GridLayout2);
            app.popupmenu4.Items = {'red', 'green', 'blue', 'overlay'};
            app.popupmenu4.ValueChangedFcn = createCallbackFcn(app, @popupmenu4_Callback, true);
            app.popupmenu4.Tag = 'popupmenu4';
            app.popupmenu4.Value = 'overlay';
            app.text7 = uilabel(app.GridLayout2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Channel:';
            app.uipanel4 = uipanel(app.GridLayout2);
            app.uipanel4.Title = 'Representation mode';
            app.uipanel4.Tag = 'uipanel4';
            app.popupmenu5 = uidropdown(app.uipanel4);
            app.popupmenu5.Items = {'Histogram', 'Gaussian', 'Voronoi smooth', 'Quad-tree histogram', 'Voronoi tessellation'};
            app.popupmenu5.ValueChangedFcn = createCallbackFcn(app, @popupmenu5_Callback, true);
            app.popupmenu5.Tag = 'popupmenu5';
            app.popupmenu5.Value = 'Histogram';
            app.text10 = uilabel(app.uipanel4);
            app.text10.Tag = 'text10';
            app.text10.Text = 's =';
            app.edit6 = uieditfield(app.uipanel4, 'text');
            app.edit6.ValueChangedFcn = createCallbackFcn(app, @edit6_Callback, true);
            app.edit6.Tag = 'edit6';
            app.edit6.Value = '240';
            app.text11 = uilabel(app.uipanel4);
            app.text11.Tag = 'text11';
            app.text11.Text = '/√Nph, nm';
            app.text12 = uilabel(app.uipanel4);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Gaussian';
            app.text8 = uilabel(app.uipanel4);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Pixel size X, Y:';
            app.text9 = uilabel(app.uipanel4);
            app.text9.Tag = 'text9';
            app.text9.Text = 'nm';
            app.edit5 = uieditfield(app.uipanel4, 'text');
            app.edit5.ValueChangedFcn = createCallbackFcn(app, @edit5_Callback, true);
            app.edit5.Tag = 'edit5';
            app.edit5.Value = '20';
            app.text13 = uilabel(app.uipanel4);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Quad-tree leaf capacity:';
            app.edit7 = uieditfield(app.uipanel4, 'text');
            app.edit7.ValueChangedFcn = createCallbackFcn(app, @edit7_Callback, true);
            app.edit7.Tag = 'edit7';
            app.edit7.Value = '5';
            app.checkbox12 = uicheckbox(app.uipanel4);
            app.checkbox12.ValueChangedFcn = createCallbackFcn(app, @checkbox12_Callback, true);
            app.checkbox12.Tag = 'checkbox12';
            app.checkbox12.Enable = 'off';
            app.checkbox12.Text = 'Points';
            app.checkbox12.Value = true;
            app.uipanel1 = uibuttongroup(app.GridLayout2);
            app.uipanel1.SelectionChangedFcn = createCallbackFcn(app, @uipanel1_SelectionChangeFcn, true);
            app.uipanel1.Title = 'Eventlist';
            app.uipanel1.Tag = 'uipanel1';
            app.radiobutton2 = uiradiobutton(app.uipanel1);
            app.radiobutton2.Tag = 'radiobutton2';
            app.radiobutton2.Text = 'Corrected';
            app.radiobutton2.Value = true;
            app.radiobutton3 = uiradiobutton(app.uipanel1);
            app.radiobutton3.Tag = 'radiobutton3';
            app.radiobutton3.Text = 'Original';
            app.text6_2 = uilabel(app.GridLayout2);
            app.text6_2.Tag = 'text6';
            app.text6_2.Text = 'Blue';
            app.popupmenu3_2 = uidropdown(app.GridLayout2);
            app.popupmenu3_2.Items = {'blue', 'blue-cyan-white', 'fire', 'red-yellow-white', 'green-yellow-white', 'magenta-white', 'yellow-white', 'cyan-white', 'cyan', 'gray', 'magenta', 'red', 'green', 'hsv', 'jet', 'cool', 'spring', 'summer', 'autumn', 'winter', 'bone', 'copper', 'pink', 'turbo', 'parula', 'inferno'};
            app.popupmenu3_2.ValueChangedFcn = createCallbackFcn(app, @popupmenu3_2ValueChanged, true);
            app.popupmenu3_2.Tag = 'popupmenu3_2';
            app.popupmenu3_2.Value = 'blue';
            app.bitCheckBox = uicheckbox(app.GridLayout2);
            app.bitCheckBox.ValueChangedFcn = createCallbackFcn(app, @bitCheckBoxValueChanged, true);
            app.bitCheckBox.Text = '16 bit';
            app.EditField = uieditfield(app.GridLayout2, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 30;
            app.EditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 30;
            app.EditField_3 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 30;
            app.DstackPanel = uipanel(app.GridLayout2);
            app.DstackPanel.Title = '3D stack';
            app.pushbutton7_2 = uibutton(app.DstackPanel, 'push');
            app.pushbutton7_2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton7_2ButtonPushed, true);
            app.pushbutton7_2.Tag = 'pushbutton7';
            app.pushbutton7_2.Text = 'Save stack...';
            app.edit5_2 = uieditfield(app.DstackPanel, 'text');
            app.edit5_2.Tag = 'edit5';
            app.edit5_2.Value = '30';
            app.text8_2 = uilabel(app.DstackPanel);
            app.text8_2.Tag = 'text8';
            app.text8_2.Text = 'Z step, nm';
            % Show the figure after all components are created
            app.figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = contrastenh_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure)

                % Execute the startup function
                runStartupFcn(app, @(app)contrastenh_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure)
        end
    end
end