classdef filtration_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        checkbox3               matlab.ui.control.CheckBox
        EditField_9             matlab.ui.control.NumericEditField
        EditField_8             matlab.ui.control.NumericEditField
        EditField_7             matlab.ui.control.NumericEditField
        text50_2                matlab.ui.control.Label
        text1_2                 matlab.ui.control.Label
        text50                  matlab.ui.control.Label
        text49                  matlab.ui.control.Label
        uipanel3                matlab.ui.container.Panel
        EditField_6             matlab.ui.control.NumericEditField
        EditField_5             matlab.ui.control.NumericEditField
        EditField_4             matlab.ui.control.NumericEditField
        EditField_3             matlab.ui.control.NumericEditField
        EditField_2             matlab.ui.control.NumericEditField
        EditField               matlab.ui.control.NumericEditField
        text20_2                matlab.ui.control.Label
        edit12_2                matlab.ui.control.EditField
        text19_2                matlab.ui.control.Label
        edit11_2                matlab.ui.control.EditField
        text18_2                matlab.ui.control.Label
        text21_4                matlab.ui.control.Label
        text44_2                matlab.ui.control.Label
        text43_2                matlab.ui.control.Label
        edit14_2                matlab.ui.control.EditField
        text21_3                matlab.ui.control.Label
        text44                  matlab.ui.control.Label
        text43                  matlab.ui.control.Label
        text23                  matlab.ui.control.Label
        text22                  matlab.ui.control.Label
        text21                  matlab.ui.control.Label
        edit14                  matlab.ui.control.EditField
        edit13                  matlab.ui.control.EditField
        pushbutton5             matlab.ui.control.Button
        text20                  matlab.ui.control.Label
        edit12                  matlab.ui.control.EditField
        text19                  matlab.ui.control.Label
        edit11                  matlab.ui.control.EditField
        text18                  matlab.ui.control.Label
        text17                  matlab.ui.control.Label
        edit10                  matlab.ui.control.EditField
        text16                  matlab.ui.control.Label
        edit9                   matlab.ui.control.EditField
        text15                  matlab.ui.control.Label
        axes6_3                 matlab.ui.control.UIAxes
        axes2                   matlab.ui.control.UIAxes
        axes1                   matlab.ui.control.UIAxes
        text2                   matlab.ui.control.Label
        text1                   matlab.ui.control.Label
        uipanel4                matlab.ui.container.Panel
        pushbutton4             matlab.ui.control.Button
        text10_2                matlab.ui.control.Label
        SmoothEditField_3       matlab.ui.control.NumericEditField
        SmoothEditField_3Label  matlab.ui.control.Label
        edit6_2                 matlab.ui.control.EditField
        edit5_2                 matlab.ui.control.EditField
        text9_2                 matlab.ui.control.Label
        SmoothEditField_2       matlab.ui.control.NumericEditField
        SmoothEditField_2Label  matlab.ui.control.Label
        SmoothEditField         matlab.ui.control.NumericEditField
        SmoothEditFieldLabel    matlab.ui.control.Label
        edit8                   matlab.ui.control.EditField
        text13                  matlab.ui.control.Label
        edit7                   matlab.ui.control.EditField
        text12                  matlab.ui.control.Label
        edit6                   matlab.ui.control.EditField
        text10                  matlab.ui.control.Label
        edit5                   matlab.ui.control.EditField
        text9                   matlab.ui.control.Label
        axes6_2                 matlab.ui.control.UIAxes
        axes5                   matlab.ui.control.UIAxes
        axes6                   matlab.ui.control.UIAxes
        uipanel1                matlab.ui.container.Panel
        text4_6                 matlab.ui.control.Label
        edit3_4                 matlab.ui.control.EditField
        text4_5                 matlab.ui.control.Label
        edit3_3                 matlab.ui.control.EditField
        edit1_2                 matlab.ui.control.EditField
        text4_4                 matlab.ui.control.Label
        methodDropDown_3        matlab.ui.control.DropDown
        methodDropDown_3Label   matlab.ui.control.Label
        text5_3                 matlab.ui.control.Label
        text4_3                 matlab.ui.control.Label
        edit4_2                 matlab.ui.control.EditField
        checkbox2_2             matlab.ui.control.CheckBox
        edit3_2                 matlab.ui.control.EditField
        methodDropDown_2        matlab.ui.control.DropDown
        methodDropDown_2Label   matlab.ui.control.Label
        methodDropDown          matlab.ui.control.DropDown
        methodDropDownLabel     matlab.ui.control.Label
        text5_2                 matlab.ui.control.Label
        text4_2                 matlab.ui.control.Label
        pushbutton1             matlab.ui.control.Button
        edit4                   matlab.ui.control.EditField
        checkbox2               matlab.ui.control.CheckBox
        edit3                   matlab.ui.control.EditField
        edit2                   matlab.ui.control.EditField
        text5                   matlab.ui.control.Label
        checkbox1               matlab.ui.control.CheckBox
        edit1                   matlab.ui.control.EditField
        text4                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        reduction % Description
        ABout % Description
    end
    
    properties (Access = private)
        CallingApp % Main app object
    end
    
    methods (Access = private)
        function A = keepframes(app, A, beg, fin)
            % keeps only events from frames from beg to fin
            try
            A(((A(:,2) < beg) | (A(:,2) > fin)),:) = [];
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end
        
        function A = keepphotons(app, A, beg, fin)
            % keeps only events wigh photon count from beg to fin
            try
            A(((A(:,7) < beg) | (A(:,7) > fin)),:) = [];
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end
        
        function plotevperfr(app, A, smooth, h)
            %plots function events per frame
            % A eventlist, h handle to axes
            evperfr = eventperframe(A, smooth);
            plot(h, evperfr);
            set(h, 'XMinorTick', 'on', 'FontSize', 9);
            title(h, 'Events per frame', 'FontSize', 9);
            xlabel(h, 'Frame number', 'FontSize', 9);
            ylabel(h, 'Number of events', 'FontSize', 9);
            xlim(h, [0,max(A(:,2))+1]);
        end
        
        function plotphperev(app, A, bins, h, hmean, hmedian)
            %plots histogram of the distibution number of photones in a frame
            % A eventlist, h handle to axes, b number of bins of hisogram
            histogram(h, A(:,7), 'BinWidth', bins);
            set(h, 'XMinorTick', 'on', 'FontSize', 9);
            xlabel(h, 'Number of photons', 'FontSize', 9);
            ylabel(h, 'Number of events', 'FontSize', 9);
            xlim(h, [0,Inf]);
            
            if exist ('hmean', 'var')
            set(hmean, 'Value', mean(A(:,7)));
            set(hmedian, 'Value', median(A(:,7)));
            %set(hmode, 'Value', mode(A(:,7)));
            end
        end
        
        function updatefigure(app, handles, ABcorr)
            % ABcorr = cell
            try
            if ~isempty(ABcorr{1})
                smooth = app.SmoothEditField.Value;
                plotevperfr(app, ABcorr{1}, smooth, handles.axes5);
                bins = str2double(get(handles.edit13, 'String')); % nb of bins for red histogram
                plotphperev(app, ABcorr{1}, bins, app.axes1, app.EditField_3, app.EditField_4)
                set(app.EditField_7, 'Value', size(ABcorr{1}, 1));
            end

            if ~isempty(ABcorr{2})
                smooth = app.SmoothEditField_2.Value;
                plotevperfr(app, ABcorr{2}, smooth, handles.axes6);
                bins = str2double(get(handles.edit14, 'String')); % nb of bins for green histogram
                plotphperev(app, ABcorr{2}, bins, app.axes2, app.EditField_5, app.EditField_6)
                set(app.EditField_8, 'Value', size(ABcorr{2}, 1));
            end

            if ~isempty(ABcorr{3})
                smooth = app.SmoothEditField_3.Value;
                plotevperfr(app, ABcorr{3}, smooth, app.axes6_2);
                bins = str2double(get(app.edit14_2, 'Value')); % nb of bins for blue histogram
                plotphperev(app, ABcorr{3}, bins, app.axes6_3, app.EditField, app.EditField_2)
                set(app.EditField_9, 'Value', size(ABcorr{3}, 1));
            end
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function filtration_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to filtration (see VARARGIN)
            
            % Choose default command line output for filtration
            app.CallingApp = varargin{1}; % add mainapp to the input arguments

            handles.output = hObject;
            AB = varargin{2};
            ABcorr = AB;
            handles.AB = AB;
            handles.ABcorr = ABcorr;
            
            % evaluate initial eventlist
            updatefigure(app, handles, ABcorr);
            
            % Update handles structure
            guidata(hObject, handles);
            
            % This sets up the initial plot - only do when we are invisible
            % so window can get raised using filtration.
            
            % UIWAIT makes filtration wait for user response (see UIRESUME)
            uiwait(hObject);
        end

        % Value changed function: edit10
        function edit10_Callback(app, event)
            try
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of edit10 as text
            %        str2double(get(hObject,'String')) returns contents of edit10 as a double
            AB = handles.ABcorr{1};
            
            beg = str2double(get(handles.edit9, 'String')); % lowest photons
            fin = str2double(get(handles.edit10, 'String')); % highest photons
            
            if ~isempty(AB)
              AB = keepphotons(app, AB, beg, fin);
            end
            ABcell = cell(1,3);
            ABcell{1} = AB;
            updatefigure(app, handles, ABcell);

            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: edit11
        function edit11_Callback(app, event)
            try
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit11 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of edit11 as text
            %        str2double(get(hObject,'String')) returns contents of edit11 as a double
            AB = handles.ABcorr{2};
            
            beg = str2double(get(handles.edit11, 'String')); % lowest photons
            fin = str2double(get(handles.edit12, 'String')); % highest photons
            
            if ~isempty(AB)
              AB = keepphotons(app, AB, beg, fin);
            end
            ABcell = cell(1,3);
            ABcell{2} = AB;
            updatefigure(app, handles, ABcell);

            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: edit12
        function edit12_Callback(app, event)
            try 
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit12 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of edit12 as text
            %        str2double(get(hObject,'String')) returns contents of edit12 as a double
            AB = handles.ABcorr{2};
            
            beg = str2double(get(handles.edit11, 'String')); % lowest photons
            fin = str2double(get(handles.edit12, 'String')); % highest photons
            
            if ~isempty(AB)
              AB = keepphotons(app, AB, beg, fin);
            end
            ABcell = cell(1,3);
            ABcell{2} = AB;
            updatefigure(app, handles, ABcell);

            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: edit13
        function edit13_Callback(app, event)
            try
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit13 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of edit13 as text
            %        str2double(get(hObject,'String')) returns contents of edit13 as a double
            AB = handles.ABcorr;
            if ~isempty(AB{1})
                bins = str2double(get(hObject, 'String')); % nb of bins for red histogram
                plotphperev(app, AB{1}, bins, handles.axes1)
            end


            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: edit14
        function edit14_Callback(app, event)
            try
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit14 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of edit14 as text
            %        str2double(get(hObject,'String')) returns contents of edit14 as a double
            AB = handles.ABcorr;
            if ~isempty(AB{2})
                bins = str2double(get(hObject, 'String')); % nb of bins for red histogram
                plotphperev(app, AB{2}, bins, handles.axes2)
            end


            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: edit5
        function edit5_Callback(app, event)
            try
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of edit5 as text
            %        str2double(get(hObject,'String')) returns contents of edit5 as a double
            AB = handles.ABcorr{1};
            
            beg = str2double(get(handles.edit5, 'string')); % first frame
            fin = str2double(get(handles.edit6, 'string')); % last frame
            
            if ~isempty(AB)
              AB = keepframes(app, AB, beg, fin);
            end
            ABcell = cell(1,3);
            ABcell{1} = AB;
            updatefigure(app, handles, ABcell);

            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: edit6
        function edit6_Callback(app, event)
            try
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of edit6 as text
            %        str2double(get(hObject,'String')) returns contents of edit6 as a double
            AB = handles.ABcorr{1};
            
            beg = str2double(get(handles.edit5, 'string')); % first frame
            fin = str2double(get(handles.edit6, 'string')); % last frame
            
            if ~isempty(AB)
              AB = keepframes(app, AB, beg, fin);
            end
            ABcell = cell(1,3);
            ABcell{1} = AB;
            updatefigure(app, handles, ABcell);


            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: edit7
        function edit7_Callback(app, event)
            try
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of edit7 as text
            %        str2double(get(hObject,'String')) returns contents of edit7 as a double
            AB = handles.ABcorr{2};
            
            beg = str2double(get(handles.edit7, 'string')); % first frame
            fin = str2double(get(handles.edit8, 'string')); % last frame
            
            if ~isempty(AB)
              AB = keepframes(app, AB, beg, fin);
            end
            ABcell = cell(1,3);
            ABcell{2} = AB;
            updatefigure(app, handles, ABcell);

            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: edit8
        function edit8_Callback(app, event)
            try
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of edit8 as text
            %        str2double(get(hObject,'String')) returns contents of edit8 as a double
            AB = handles.ABcorr{2};
            
            beg = str2double(get(handles.edit7, 'string')); % first frame
            fin = str2double(get(handles.edit8, 'string')); % last frame
            
            if ~isempty(AB)
              AB = keepframes(app, AB, beg, fin);
            end
            ABcell = cell(1,3);
            ABcell{2} = AB;
            updatefigure(app, handles, ABcell);

            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: edit9
        function edit9_Callback(app, event)
            try
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of edit9 as text
            %        str2double(get(hObject,'String')) returns contents of edit9 as a double
            AB = handles.ABcorr{1};
            
            beg = str2double(get(handles.edit9, 'String')); % lowest photons
            fin = str2double(get(handles.edit10, 'String')); % highest photons
            
            if ~isempty(AB)
              AB = keepphotons(app, AB, beg, fin);
            end
            ABcell = cell(1,3);
            ABcell{1} = AB;
            updatefigure(app, handles, ABcell);


            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
             %if isequal(get(hObject, 'waitstatus'), 'waiting')
                % The GUI is still in UIWAIT, use UIRESUME
                %updateABcorr(app.CallingApp, handles.ABcorr);
                uiresume(hObject);
             %else
                % The GUI is no longer waiting, just close it
                delete(hObject);
             %end
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
                if ~get(handles.checkbox3, 'Value')
                    AB = handles.AB; % correct exclusively initial eventlists
                else
                    AB = handles.ABcorr; % correct already filtered eventlists
                end

                rad(1) = str2double(get(handles.edit1, 'string')); % radius A
                gap(1) = str2double(get(handles.edit2, 'string')); % nb of empty frames
                anis(1) = get(handles.checkbox1, 'Value'); % anisotropically
                radZ(1) = str2double(app.edit1_2.Value); % Z-radius A

                if strcmp(app.methodDropDown.Value, 'reduction')
                    app.reduction(1) = true;
                else
                    app.reduction(1) = false;
                end

                rad(2) = str2double(get(handles.edit3, 'string')); % radius B
                gap(2) = str2double(get(handles.edit4, 'string')); % nb of empty frames
                anis(2) = get(handles.checkbox2, 'Value'); % anisotropically
                radZ(2) = str2double(app.edit3_3.Value); % Z-radius B

                rad(3) = str2double(get(app.edit3_2, 'Value')); % radius B
                gap(3) = str2double(get(app.edit4_2, 'Value')); % nb of empty frames
                anis(3) = get(app.checkbox2_2, 'Value'); % anisotropically
                radZ(3) = str2double(app.edit3_4.Value); % Z-radius C

                if strcmp(app.methodDropDown_2.Value, 'reduction')
                    app.reduction(2) = true;
                else
                    app.reduction(2) = false;
                end

                    if strcmp(app.methodDropDown_3.Value, 'reduction')
                        app.reduction(3) = true;
                    else
                        app.reduction(3) = false;
                    end

                            ABcorr = handles.ABcorr;
                            for i = 1:3
                                if ~isempty(AB{i}) && rad(i) ~= 0
                                    ABcorr{i} = filtercons3D(AB{i}, [rad(i), rad(i), radZ(i)], gap(i), anis(i), app.reduction(i));
                                else
                                    ABcorr{i} = AB{i};
                                end
                            end
                            updatefigure(app, handles, ABcorr);
                            updateABcorr(app.CallingApp, ABcorr);

                            handles.ABcorr = ABcorr;
                            guidata(hObject,handles);

            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            try
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            AB = handles.ABcorr;
            
            beg(1) = str2double(get(handles.edit5, 'string')); % first frame A
            fin(1) = str2double(get(handles.edit6, 'string')); % last frame B
            
            beg(2) = str2double(get(handles.edit7, 'string')); % first frame B
            fin(2) = str2double(get(handles.edit8, 'string')); % last frame B
            
            beg(3) = str2double(get(app.edit5_2, 'Value')); % first frame B
            fin(3) = str2double(get(app.edit6_2, 'Value')); % last frame B
            
            for i = 1:3
            if ~isempty(AB{i})
              AB{i} = keepframes(app, AB{i}, beg(i), fin(i));
            end
            end
            updatefigure(app, handles, AB);
            updateABcorr(app.CallingApp, AB);
            
            handles.ABcorr = AB;
            guidata(hObject,handles); 


            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton5
        function pushbutton5_Callback(app, event)
            try
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            AB = handles.ABcorr;
            
            beg(1) = str2double(get(handles.edit9, 'String')); % lowest photons A
            fin(1) = str2double(get(handles.edit10, 'String')); % highest photons B
            
            beg(2) = str2double(get(handles.edit11, 'String')); % lowest photons B
            fin(2) = str2double(get(handles.edit12, 'String')); % highest photons B
            
            beg(3) = str2double(get(app.edit11_2, 'Value')); % lowest photons C
            fin(3) = str2double(get(app.edit12_2, 'Value')); % highest photons C
            
            for i = 1:3
            if ~isempty(AB{i})
              AB{i} = keepphotons(app, AB{i}, beg(i), fin(i));
            end
            end
            updatefigure(app, handles, AB);   

            updateABcorr(app.CallingApp, AB);
            
            handles.ABcorr = AB;
            guidata(hObject,handles);           


            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: SmoothEditField_2
        function SmoothEditField_2ValueChanged(app, event)
            try
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            plotevperfr(app,handles.ABcorr{2},app.SmoothEditField_2.Value,app.axes6);
                        
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: SmoothEditField
        function SmoothEditFieldValueChanged(app, event)
            try

            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            plotevperfr(app,handles.ABcorr{1},app.SmoothEditField.Value,app.axes5);
            

            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: SmoothEditField_3
        function SmoothEditField_3ValueChanged(app, event)
            try
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            plotevperfr(app,handles.ABcorr{3},app.SmoothEditField_3.Value,app.axes6_2);
                        
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: edit14_2
        function edit14_2ValueChanged(app, event)
            try
                
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit14 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of edit14 as text
            %        str2double(get(hObject,'String')) returns contents of edit14 as a double
            AB = handles.ABcorr;
            if ~isempty(AB{3})
                bins = str2double(get(app.edit14_2, 'Value')); % nb of bins for red histogram
                plotphperev(app, AB{3}, bins, app.axes6_3)
            end


            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: edit5_2
        function edit5_2ValueChanged(app, event)
            try
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of edit7 as text
            %        str2double(get(hObject,'String')) returns contents of edit7 as a double
            AB = handles.ABcorr{3};
            
            beg = str2double(get(app.edit5_2, 'Value')); % first frame
            fin = str2double(get(app.edit6_2, 'Value')); % last frame
            
            if ~isempty(AB)
              AB = keepframes(app, AB, beg, fin);
            end
            ABcell = cell(1,3);
            ABcell{3} = AB;
            updatefigure(app, handles, ABcell);

            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changing function: edit5_2
        function edit5_2ValueChanging(app, event)

        end

        % Value changed function: edit11_2
        function edit11_2ValueChanged(app, event)
            try
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit12 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of edit12 as text
            %        str2double(get(hObject,'String')) returns contents of edit12 as a double
            AB = handles.ABcorr{3};
            
            beg = str2double(get(app.edit11_2, 'Value')); % lowest photons
            fin = str2double(get(app.edit12_2, 'Value')); % highest photons
            
            if ~isempty(AB)
              AB = keepphotons(app, AB, beg, fin);
            end
            ABcell = cell(1,3);
            ABcell{3} = AB;
            updatefigure(app, handles, ABcell);

            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: edit12_2
        function edit12_2ValueChanged(app, event)
            try
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit12 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of edit12 as text
            %        str2double(get(hObject,'String')) returns contents of edit12 as a double
            AB = handles.ABcorr{3};
            
            beg = str2double(get(app.edit11_2, 'Value')); % lowest photons
            fin = str2double(get(app.edit12_2, 'Value')); % highest photons
            
            if ~isempty(AB)
              AB = keepphotons(app, AB, beg, fin);
            end
            ABcell = cell(1,3);
            ABcell{3} = AB;
            updatefigure(app, handles, ABcell);

            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: edit6_2
        function edit6_2ValueChanged(app, event)
            try
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of edit7 as text
            %        str2double(get(hObject,'String')) returns contents of edit7 as a double
            AB = handles.ABcorr{3};
            
            beg = str2double(get(app.edit5_2, 'Value')); % first frame
            fin = str2double(get(app.edit6_2, 'Value')); % last frame
            
            if ~isempty(AB)
              AB = keepframes(app, AB, beg, fin);
            end
            ABcell = cell(1,3);
            ABcell{3} = AB;
            updatefigure(app, handles, ABcell);

            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'filtration';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Temporal grouping';
            app.uipanel1.Tag = 'uipanel1';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'radius XY, nm';
            app.edit1 = uieditfield(app.uipanel1, 'text');
            app.edit1.Tag = 'edit1';
            app.edit1.Value = '50';
            app.checkbox1 = uicheckbox(app.uipanel1);
            app.checkbox1.Tag = 'checkbox1';
            app.checkbox1.Text = 'anisotropy';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'max. number of empty frames:';
            app.edit2 = uieditfield(app.uipanel1, 'text');
            app.edit2.Tag = 'edit2';
            app.edit2.Value = '0';
            app.edit3 = uieditfield(app.uipanel1, 'text');
            app.edit3.Tag = 'edit3';
            app.edit3.Value = '50';
            app.checkbox2 = uicheckbox(app.uipanel1);
            app.checkbox2.Tag = 'checkbox2';
            app.checkbox2.Text = 'anisotropy';
            app.edit4 = uieditfield(app.uipanel1, 'text');
            app.edit4.Tag = 'edit4';
            app.edit4.Value = '0';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Apply';
            app.text4_2 = uilabel(app.uipanel1);
            app.text4_2.Tag = 'text4';
            app.text4_2.Text = 'radius XY, nm';
            app.text5_2 = uilabel(app.uipanel1);
            app.text5_2.Tag = 'text5';
            app.text5_2.Text = 'max. number of empty frames:';
            app.methodDropDownLabel = uilabel(app.uipanel1);
            app.methodDropDownLabel.Text = 'method';
            app.methodDropDown = uidropdown(app.uipanel1);
            app.methodDropDown.Items = {'refinement', 'reduction'};
            app.methodDropDown.Value = 'refinement';
            app.methodDropDown_2Label = uilabel(app.uipanel1);
            app.methodDropDown_2Label.Text = 'method';
            app.methodDropDown_2 = uidropdown(app.uipanel1);
            app.methodDropDown_2.Items = {'refinement', 'reduction'};
            app.methodDropDown_2.Value = 'refinement';
            app.edit3_2 = uieditfield(app.uipanel1, 'text');
            app.edit3_2.Tag = 'edit3';
            app.edit3_2.Value = '50';
            app.checkbox2_2 = uicheckbox(app.uipanel1);
            app.checkbox2_2.Tag = 'checkbox2';
            app.checkbox2_2.Text = 'anisotropy';
            app.edit4_2 = uieditfield(app.uipanel1, 'text');
            app.edit4_2.Tag = 'edit4';
            app.edit4_2.Value = '0';
            app.text4_3 = uilabel(app.uipanel1);
            app.text4_3.Tag = 'text4';
            app.text4_3.Text = 'radius XY, nm';
            app.text5_3 = uilabel(app.uipanel1);
            app.text5_3.Tag = 'text5';
            app.text5_3.Text = 'max. number of empty frames:';
            app.methodDropDown_3Label = uilabel(app.uipanel1);
            app.methodDropDown_3Label.Text = 'method';
            app.methodDropDown_3 = uidropdown(app.uipanel1);
            app.methodDropDown_3.Items = {'refinement', 'reduction'};
            app.methodDropDown_3.Value = 'refinement';
            app.text4_4 = uilabel(app.uipanel1);
            app.text4_4.Tag = 'text4';
            app.text4_4.Text = 'radius Z, nm';
            app.edit1_2 = uieditfield(app.uipanel1, 'text');
            app.edit1_2.Tag = 'edit1';
            app.edit1_2.Value = '200';
            app.edit3_3 = uieditfield(app.uipanel1, 'text');
            app.edit3_3.Tag = 'edit3';
            app.edit3_3.Value = '200';
            app.text4_5 = uilabel(app.uipanel1);
            app.text4_5.Tag = 'text4';
            app.text4_5.Text = 'radius Z, nm';
            app.edit3_4 = uieditfield(app.uipanel1, 'text');
            app.edit3_4.Tag = 'edit3';
            app.edit3_4.Value = '200';
            app.text4_6 = uilabel(app.uipanel1);
            app.text4_6.Tag = 'text4';
            app.text4_6.Text = 'radius Z, nm';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Temporal filter';
            app.uipanel4.Tag = 'uipanel4';
            app.axes6 = uiaxes(app.uipanel4);
            app.axes6.XLim = [0 1];
            app.axes6.YLim = [0 1];
            app.axes6.ZLim = [0 1];
            app.axes6.CLim = [0 1];
            app.axes6.ALim = [0 1];
            app.axes6.XTick = [];
            app.axes6.XTickLabel = '';
            app.axes6.YTick = [];
            app.axes6.YTickLabel = '';
            app.axes6.ZTick = [0 0.5 1];
            app.axes6.ZTickLabel = '';
            app.axes6.Tag = 'axes6';
            app.axes5 = uiaxes(app.uipanel4);
            app.axes5.XLim = [0 1];
            app.axes5.YLim = [0 1];
            app.axes5.ZLim = [0 1];
            app.axes5.CLim = [0 1];
            app.axes5.ALim = [0 1];
            app.axes5.XTick = [];
            app.axes5.XTickLabel = '';
            app.axes5.YTick = [];
            app.axes5.YTickLabel = '';
            app.axes5.ZTick = [0 0.5 1];
            app.axes5.ZTickLabel = '';
            app.axes5.Tag = 'axes5';
            app.axes6_2 = uiaxes(app.uipanel4);
            app.axes6_2.XLim = [0 1];
            app.axes6_2.YLim = [0 1];
            app.axes6_2.ZLim = [0 1];
            app.axes6_2.CLim = [0 1];
            app.axes6_2.ALim = [0 1];
            app.axes6_2.XTick = [];
            app.axes6_2.XTickLabel = '';
            app.axes6_2.YTick = [];
            app.axes6_2.YTickLabel = '';
            app.axes6_2.ZTick = [0 0.5 1];
            app.axes6_2.ZTickLabel = '';
            app.axes6_2.Tag = 'axes6';
            app.text9 = uilabel(app.uipanel4);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Keep frames:';
            app.edit5 = uieditfield(app.uipanel4, 'text');
            app.edit5.ValueChangedFcn = createCallbackFcn(app, @edit5_Callback, true);
            app.edit5.Tag = 'edit5';
            app.edit5.Value = '0';
            app.text10 = uilabel(app.uipanel4);
            app.text10.Tag = 'text10';
            app.text10.Text = 'to';
            app.edit6 = uieditfield(app.uipanel4, 'text');
            app.edit6.ValueChangedFcn = createCallbackFcn(app, @edit6_Callback, true);
            app.edit6.Tag = 'edit6';
            app.edit6.Value = '200000';
            app.text12 = uilabel(app.uipanel4);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Keep frames:';
            app.edit7 = uieditfield(app.uipanel4, 'text');
            app.edit7.ValueChangedFcn = createCallbackFcn(app, @edit7_Callback, true);
            app.edit7.Tag = 'edit7';
            app.edit7.Value = '0';
            app.text13 = uilabel(app.uipanel4);
            app.text13.Tag = 'text13';
            app.text13.Text = 'to';
            app.edit8 = uieditfield(app.uipanel4, 'text');
            app.edit8.ValueChangedFcn = createCallbackFcn(app, @edit8_Callback, true);
            app.edit8.Tag = 'edit8';
            app.edit8.Value = '200000';
            app.SmoothEditFieldLabel = uilabel(app.uipanel4);
            app.SmoothEditFieldLabel.Text = 'Smooth';
            app.SmoothEditField = uieditfield(app.uipanel4, 'numeric');
            app.SmoothEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothEditFieldValueChanged, true);
            app.SmoothEditField.Value = 100;
            app.SmoothEditField_2Label = uilabel(app.uipanel4);
            app.SmoothEditField_2Label.Text = 'Smooth';
            app.SmoothEditField_2 = uieditfield(app.uipanel4, 'numeric');
            app.SmoothEditField_2.ValueChangedFcn = createCallbackFcn(app, @SmoothEditField_2ValueChanged, true);
            app.SmoothEditField_2.Value = 100;
            app.text9_2 = uilabel(app.uipanel4);
            app.text9_2.Tag = 'text9';
            app.text9_2.Text = 'Keep frames:';
            app.edit5_2 = uieditfield(app.uipanel4, 'text');
            app.edit5_2.ValueChangedFcn = createCallbackFcn(app, @edit5_2ValueChanged, true);
            app.edit5_2.ValueChangingFcn = createCallbackFcn(app, @edit5_2ValueChanging, true);
            app.edit5_2.Tag = 'edit5';
            app.edit5_2.Value = '0';
            app.edit6_2 = uieditfield(app.uipanel4, 'text');
            app.edit6_2.ValueChangedFcn = createCallbackFcn(app, @edit6_2ValueChanged, true);
            app.edit6_2.Tag = 'edit6';
            app.edit6_2.Value = '200000';
            app.SmoothEditField_3Label = uilabel(app.uipanel4);
            app.SmoothEditField_3Label.Text = 'Smooth';
            app.SmoothEditField_3 = uieditfield(app.uipanel4, 'numeric');
            app.SmoothEditField_3.ValueChangedFcn = createCallbackFcn(app, @SmoothEditField_3ValueChanged, true);
            app.SmoothEditField_3.Value = 100;
            app.text10_2 = uilabel(app.uipanel4);
            app.text10_2.Tag = 'text10';
            app.text10_2.Text = 'to';
            app.pushbutton4 = uibutton(app.uipanel4, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Text = 'Apply';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Green';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Red';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Brightness filter';
            app.uipanel3.Tag = 'uipanel3';
            app.axes1 = uiaxes(app.uipanel3);
            app.axes1.XLim = [0 1];
            app.axes1.YLim = [0 1];
            app.axes1.ZLim = [0 1];
            app.axes1.CLim = [0 1];
            app.axes1.ALim = [0 1];
            app.axes1.XTick = [];
            app.axes1.XTickLabel = '';
            app.axes1.YTick = [];
            app.axes1.YTickLabel = '';
            app.axes1.ZTick = [0 0.5 1];
            app.axes1.ZTickLabel = '';
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.uipanel3);
            app.axes2.XLim = [0 1];
            app.axes2.YLim = [0 1];
            app.axes2.ZLim = [0 1];
            app.axes2.CLim = [0 1];
            app.axes2.ALim = [0 1];
            app.axes2.XTick = [];
            app.axes2.XTickLabel = '';
            app.axes2.YTick = [];
            app.axes2.YTickLabel = '';
            app.axes2.ZTick = [0 0.5 1];
            app.axes2.ZTickLabel = '';
            app.axes2.Tag = 'axes2';
            app.axes6_3 = uiaxes(app.uipanel3);
            app.axes6_3.XLim = [0 1];
            app.axes6_3.YLim = [0 1];
            app.axes6_3.ZLim = [0 1];
            app.axes6_3.CLim = [0 1];
            app.axes6_3.ALim = [0 1];
            app.axes6_3.XTick = [];
            app.axes6_3.XTickLabel = '';
            app.axes6_3.YTick = [];
            app.axes6_3.YTickLabel = '';
            app.axes6_3.ZTick = [0 0.5 1];
            app.axes6_3.ZTickLabel = '';
            app.axes6_3.Tag = 'axes6';
            app.text15 = uilabel(app.uipanel3);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Keep events with';
            app.edit9 = uieditfield(app.uipanel3, 'text');
            app.edit9.ValueChangedFcn = createCallbackFcn(app, @edit9_Callback, true);
            app.edit9.Tag = 'edit9';
            app.edit9.Value = '1';
            app.text16 = uilabel(app.uipanel3);
            app.text16.Tag = 'text16';
            app.text16.Text = 'to';
            app.edit10 = uieditfield(app.uipanel3, 'text');
            app.edit10.ValueChangedFcn = createCallbackFcn(app, @edit10_Callback, true);
            app.edit10.Tag = 'edit10';
            app.edit10.Value = '100000';
            app.text17 = uilabel(app.uipanel3);
            app.text17.Tag = 'text17';
            app.text17.Text = 'photons';
            app.text18 = uilabel(app.uipanel3);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Keep events with';
            app.edit11 = uieditfield(app.uipanel3, 'text');
            app.edit11.ValueChangedFcn = createCallbackFcn(app, @edit11_Callback, true);
            app.edit11.Tag = 'edit11';
            app.edit11.Value = '1';
            app.text19 = uilabel(app.uipanel3);
            app.text19.Tag = 'text19';
            app.text19.Text = 'to';
            app.edit12 = uieditfield(app.uipanel3, 'text');
            app.edit12.ValueChangedFcn = createCallbackFcn(app, @edit12_Callback, true);
            app.edit12.Tag = 'edit12';
            app.edit12.Value = '100000';
            app.text20 = uilabel(app.uipanel3);
            app.text20.Tag = 'text20';
            app.text20.Text = 'photons';
            app.pushbutton5 = uibutton(app.uipanel3, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton5_Callback, true);
            app.pushbutton5.Tag = 'pushbutton5';
            app.pushbutton5.Text = 'Apply';
            app.edit13 = uieditfield(app.uipanel3, 'text');
            app.edit13.ValueChangedFcn = createCallbackFcn(app, @edit13_Callback, true);
            app.edit13.Tag = 'edit13';
            app.edit13.Value = '100';
            app.edit14 = uieditfield(app.uipanel3, 'text');
            app.edit14.ValueChangedFcn = createCallbackFcn(app, @edit14_Callback, true);
            app.edit14.Tag = 'edit14';
            app.edit14.Value = '100';
            app.text21 = uilabel(app.uipanel3);
            app.text21.Tag = 'text21';
            app.text21.Text = 'bin width';
            app.text22 = uilabel(app.uipanel3);
            app.text22.Tag = 'text22';
            app.text22.Text = 'mean:';
            app.text23 = uilabel(app.uipanel3);
            app.text23.Tag = 'text23';
            app.text23.Text = 'median:';
            app.text43 = uilabel(app.uipanel3);
            app.text43.Tag = 'text43';
            app.text43.Text = 'mean:';
            app.text44 = uilabel(app.uipanel3);
            app.text44.Tag = 'text44';
            app.text44.Text = 'median:';
            app.text21_3 = uilabel(app.uipanel3);
            app.text21_3.Tag = 'text21';
            app.text21_3.Text = 'bin width';
            app.edit14_2 = uieditfield(app.uipanel3, 'text');
            app.edit14_2.ValueChangedFcn = createCallbackFcn(app, @edit14_2ValueChanged, true);
            app.edit14_2.Tag = 'edit14';
            app.edit14_2.Value = '100';
            app.text43_2 = uilabel(app.uipanel3);
            app.text43_2.Tag = 'text43';
            app.text43_2.Text = 'mean:';
            app.text44_2 = uilabel(app.uipanel3);
            app.text44_2.Tag = 'text44';
            app.text44_2.Text = 'median:';
            app.text21_4 = uilabel(app.uipanel3);
            app.text21_4.Tag = 'text21';
            app.text21_4.Text = 'bin width';
            app.text18_2 = uilabel(app.uipanel3);
            app.text18_2.Tag = 'text18';
            app.text18_2.Text = 'Keep events with';
            app.edit11_2 = uieditfield(app.uipanel3, 'text');
            app.edit11_2.ValueChangedFcn = createCallbackFcn(app, @edit11_2ValueChanged, true);
            app.edit11_2.Tag = 'edit11';
            app.edit11_2.Value = '1';
            app.text19_2 = uilabel(app.uipanel3);
            app.text19_2.Tag = 'text19';
            app.text19_2.Text = 'to';
            app.edit12_2 = uieditfield(app.uipanel3, 'text');
            app.edit12_2.ValueChangedFcn = createCallbackFcn(app, @edit12_2ValueChanged, true);
            app.edit12_2.Tag = 'edit12';
            app.edit12_2.Value = '100000';
            app.text20_2 = uilabel(app.uipanel3);
            app.text20_2.Tag = 'text20';
            app.text20_2.Text = 'photons';
            app.EditField = uieditfield(app.uipanel3, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.uipanel3, 'numeric');
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.uipanel3, 'numeric');
            app.EditField_3.Editable = 'off';
            app.EditField_4 = uieditfield(app.uipanel3, 'numeric');
            app.EditField_4.Editable = 'off';
            app.EditField_5 = uieditfield(app.uipanel3, 'numeric');
            app.EditField_5.Editable = 'off';
            app.EditField_6 = uieditfield(app.uipanel3, 'numeric');
            app.EditField_6.Editable = 'off';
            app.text49 = uilabel(app.figure1);
            app.text49.Tag = 'text49';
            app.text49.Text = 'number of events:';
            app.text50 = uilabel(app.figure1);
            app.text50.Tag = 'text50';
            app.text50.Text = 'number of events:';
            app.text1_2 = uilabel(app.figure1);
            app.text1_2.Tag = 'text1';
            app.text1_2.Text = 'Blue';
            app.text50_2 = uilabel(app.figure1);
            app.text50_2.Tag = 'text50';
            app.text50_2.Text = 'number of events:';
            app.EditField_7 = uieditfield(app.figure1, 'numeric');
            app.EditField_7.Editable = 'off';
            app.EditField_8 = uieditfield(app.figure1, 'numeric');
            app.EditField_8.Editable = 'off';
            app.EditField_9 = uieditfield(app.figure1, 'numeric');
            app.EditField_9.Editable = 'off';
            app.checkbox3 = uicheckbox(app.figure1);
            app.checkbox3.Tag = 'checkbox3';
            app.checkbox3.Text = 'Filtered eventlists';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filtration_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)filtration_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end